classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainApp                         matlab.ui.Figure
        Grid1                           matlab.ui.container.GridLayout
        ToolBar                         matlab.ui.container.GridLayout
        Button_33                       matlab.ui.control.Button
        Button_34                       matlab.ui.control.Button
        Button_35                       matlab.ui.control.Button
        Button_32                       matlab.ui.control.Button
        Button_31                       matlab.ui.control.Button
        MainMenu                        matlab.ui.container.GridLayout
        GridLayout27                    matlab.ui.container.GridLayout
        UserPath                        matlab.ui.control.DropDown
        SearchPath                      matlab.ui.control.Image
        GridLayout28                    matlab.ui.container.GridLayout
        Button_11                       matlab.ui.control.Button
        Button_Info                     matlab.ui.control.StateButton
        Image2                          matlab.ui.control.Image
        Button_Playback                 matlab.ui.control.StateButton
        Button_Report                   matlab.ui.control.StateButton
        Report_recButton                matlab.ui.control.StateButton
        Report_FiscalizaButton          matlab.ui.control.Button
        Grid_2                          matlab.ui.container.GridLayout
        Warning_Image                   matlab.ui.control.Image
        Tree2                           matlab.ui.container.Tree
        Tree2_Label                     matlab.ui.control.Label
        Warning_Message                 matlab.ui.control.Label
        Label_31                        matlab.ui.control.Label
        metaData_16                     matlab.ui.control.TextArea
        ImageTree                       matlab.ui.control.Image
        Grid_1                          matlab.ui.container.GridLayout
        Tree1                           matlab.ui.container.Tree
        LeituraespectroButton           matlab.ui.control.Button
        Label_13                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Button_12                       matlab.ui.control.Image
        metaData_15                     matlab.ui.control.TextArea
        Logo                            matlab.ui.control.Image
        Grid3_Playback                  matlab.ui.container.GridLayout
        Report_Label1_4                 matlab.ui.control.Label
        Playback_Play                   matlab.ui.control.Button
        Playback_FullScreen             matlab.ui.control.Button
        Playback_Hand                   matlab.ui.control.StateButton
        Playback_Zoom                   matlab.ui.control.StateButton
        Playback_Menu                   matlab.ui.control.StateButton
        Playback_Reload                 matlab.ui.control.Button
        Panel_21                        matlab.ui.container.Panel
        GridLayout24                    matlab.ui.container.GridLayout
        Report_Grid1_2                  matlab.ui.container.GridLayout
        Playback_Label1                 matlab.ui.control.Label
        RadioGroup1                     matlab.ui.container.ButtonGroup
        Playback_Radio2                 matlab.ui.control.RadioButton
        Playback_Radio1                 matlab.ui.control.RadioButton
        Panel1                          matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        Playback_CheckBox3              matlab.ui.control.CheckBox
        Playback_CheckBox2              matlab.ui.control.CheckBox
        Playback_Label2                 matlab.ui.control.Label
        RadioGroup2                     matlab.ui.container.ButtonGroup
        Playback_Radio5                 matlab.ui.control.RadioButton
        Playback_Radio4                 matlab.ui.control.RadioButton
        Playback_CheckBox1              matlab.ui.control.CheckBox
        FatordeintegraoLabel            matlab.ui.control.Label
        Playback_Value1                 matlab.ui.control.Spinner
        Playback_Label3                 matlab.ui.control.Label
        RadioGroup3                     matlab.ui.container.ButtonGroup
        Playback_Radio7                 matlab.ui.control.RadioButton
        Playback_Radio6                 matlab.ui.control.RadioButton
        Panel3                          matlab.ui.container.Panel
        Grid7                           matlab.ui.container.GridLayout
        LarguramnimaocupadaLabel        matlab.ui.control.Label
        Marker_Label3                   matlab.ui.control.Label
        Marker_Label2                   matlab.ui.control.Label
        Marker_Label1                   matlab.ui.control.Label
        Playback_Unit6                  matlab.ui.control.Label
        Playback_Value13                matlab.ui.control.Spinner
        Playback_Unit5                  matlab.ui.control.Label
        Playback_Value12                matlab.ui.control.Spinner
        Playback_Unit3                  matlab.ui.control.Label
        Playback_Unit4                  matlab.ui.control.Label
        Playback_Value11                matlab.ui.control.Spinner
        Playback_Value10                matlab.ui.control.Spinner
        Playback_Value9                 matlab.ui.control.Spinner
        NmerodepicosLabel               matlab.ui.control.Label
        TraoDropDownLabel               matlab.ui.control.Label
        Playback_TraceType              matlab.ui.control.DropDown
        Button_45                       matlab.ui.control.Image
        Button_46                       matlab.ui.control.Image
        Tree4                           matlab.ui.container.Tree
        Marker_CF                       matlab.ui.control.NumericEditField
        FrequnciacentraldomarcadorLabel  matlab.ui.control.Label
        Marker_CFUnit                   matlab.ui.control.Label
        GridLayout25                    matlab.ui.container.GridLayout
        Playback_CheckBox4              matlab.ui.control.CheckBox
        Playback_Value2                 matlab.ui.control.DropDown
        ThresholdDropDownLabel          matlab.ui.control.Label
        Playback_Value3                 matlab.ui.control.Spinner
        ValorLabel                      matlab.ui.control.Label
        Playback_Unit1                  matlab.ui.control.Label
        Playback_Value6                 matlab.ui.control.Spinner
        OffSetLabel                     matlab.ui.control.Label
        Playback_Value4                 matlab.ui.control.Spinner
        FatordeintegraoLabel_2          matlab.ui.control.Label
        NveldeconfianaLabel             matlab.ui.control.Label
        Playback_Value8                 matlab.ui.control.DropDown
        Playback_Label001               matlab.ui.control.Label
        Panel2                          matlab.ui.container.Panel
        Grid4                           matlab.ui.container.GridLayout
        Playback_Value7                 matlab.ui.control.Spinner
        Playback_Value5                 matlab.ui.control.DropDown
        AmostrasteisLabel_2             matlab.ui.control.Label
        FunodetendnciacentralLabel_3    matlab.ui.control.Label
        Playback_CheckBox5              matlab.ui.control.CheckBox
        GridLayout26                    matlab.ui.container.GridLayout
        Playback_Slider                 matlab.ui.control.Slider
        Grid3_Info                      matlab.ui.container.GridLayout
        Info_Panel2                     matlab.ui.container.Panel
        Info_Grid3                      matlab.ui.container.GridLayout
        Info_Label1                     matlab.ui.control.Label
        Info_Label2                     matlab.ui.control.Label
        Info_Panel1                     matlab.ui.container.Panel
        Info_Grid2                      matlab.ui.container.GridLayout
        LongitudeEditFieldLabel         matlab.ui.control.Label
        Info_MetaData6                  matlab.ui.control.NumericEditField
        Info_MetaData5                  matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel          matlab.ui.control.Label
        Info_MetaData4                  matlab.ui.control.EditField
        LocalidadeLabel                 matlab.ui.control.Label
        Info_MetaData3                  matlab.ui.control.EditField
        UnidaderesponsvelLabel_2        matlab.ui.control.Label
        Info_Panel1_2                   matlab.ui.container.Panel
        Info_Grid2_2                    matlab.ui.container.GridLayout
        LatitudeEditField_2Label        matlab.ui.control.Label
        Info_Latitude                   matlab.ui.control.NumericEditField
        Info_Longitude                  matlab.ui.control.NumericEditField
        LongitudeEditField_2Label       matlab.ui.control.Label
        Info_CheckBox                   matlab.ui.control.CheckBox
        Info_OCC                        matlab.ui.control.DropDown
        OcupaoDropDownLabel             matlab.ui.control.Label
        Info_Button                     matlab.ui.control.Button
        Info_Label1_2                   matlab.ui.control.Label
        Grid3_Report                    matlab.ui.container.GridLayout
        Report_playButton               matlab.ui.control.Button
        Report_Label1_3                 matlab.ui.control.Label
        Panel_22                        matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        Report_Grid1                    matlab.ui.container.GridLayout
        Tree3_Label                     matlab.ui.control.Label
        Tree3                           matlab.ui.container.Tree
        Report_Label3                   matlab.ui.control.Label
        Report_Grid4                    matlab.ui.container.GridLayout
        Report_DatePicker1              matlab.ui.control.DatePicker
        Report_Spinner1                 matlab.ui.control.Spinner
        Report_Spinner2                 matlab.ui.control.Spinner
        Report_DatePicker2              matlab.ui.control.DatePicker
        Report_Spinner3                 matlab.ui.control.Spinner
        Report_Spinner4                 matlab.ui.control.Spinner
        Report_Label1                   matlab.ui.control.Label
        GridLayout19                    matlab.ui.container.GridLayout
        Report_Value2                   matlab.ui.control.NumericEditField
        InspeoFiscalizaLabel            matlab.ui.control.Label
        Report_Value1_3                 matlab.ui.control.DropDown
        VersoLabel                      matlab.ui.control.Label
        Report_CheckBox2                matlab.ui.control.CheckBox
        Report_CheckBox3                matlab.ui.control.CheckBox
        Report_CheckBox4                matlab.ui.control.CheckBox
        Report_TextArea                 matlab.ui.control.TextArea
        Report_Label2                   matlab.ui.control.Label
        Report_addButton                matlab.ui.control.Image
        Report_deleteButton             matlab.ui.control.Image
        Report_Grid1_3                  matlab.ui.container.GridLayout
        Report_Label4                   matlab.ui.control.Label
        Report_Label7                   matlab.ui.control.Label
        GridLayout21                    matlab.ui.container.GridLayout
        ParmetrosrelacionadosestimativadopisoderudoLabel  matlab.ui.control.Label
        Report_Panel2_2                 matlab.ui.container.Panel
        Report_Grid5_2                  matlab.ui.container.GridLayout
        Report_Value6                   matlab.ui.control.DropDown
        FunodetendnciacentralLabel      matlab.ui.control.Label
        AmostrasdescartadasLabel        matlab.ui.control.Label
        Report_Value7                   matlab.ui.control.Spinner
        AmostrasteisLabel               matlab.ui.control.Label
        Report_Value8                   matlab.ui.control.Spinner
        ThresholdDropDownLabel_2        matlab.ui.control.Label
        Playback_Value2_2               matlab.ui.control.DropDown
        Report_Value4                   matlab.ui.control.Spinner
        TempodeintegraoLabel            matlab.ui.control.Label
        ValorLabel_2                    matlab.ui.control.Label
        Playback_Value3_2               matlab.ui.control.Spinner
        Playback_Unit1_2                matlab.ui.control.Label
        Playback_Value8_2               matlab.ui.control.DropDown
        NveldeconfianaLabel_2           matlab.ui.control.Label
        OffSetLabel_2                   matlab.ui.control.Label
        Report_Value5                   matlab.ui.control.Spinner
        Playback_Unit1_3                matlab.ui.control.Label
        GridLayout15                    matlab.ui.container.GridLayout
        LarguramnimaocupadaLabel_2      matlab.ui.control.Label
        Report_Value11                  matlab.ui.control.Spinner
        Report_Unit11                   matlab.ui.control.Label
        DistnciamnimaentrepicosLabel_2  matlab.ui.control.Label
        Report_Value12                  matlab.ui.control.Spinner
        Report_Unit12                   matlab.ui.control.Label
        ClassificaodafaixaLabel         matlab.ui.control.Label
        Report_Value6_2                 matlab.ui.control.DropDown
        EmrelaocurvadetendnciacentralLabel  matlab.ui.control.Label
        Panel_23                        matlab.ui.container.Panel
        GridLayout22                    matlab.ui.container.GridLayout
        Report_Value9                   matlab.ui.control.DropDown
        FunodetendnciacentralLabel_2    matlab.ui.control.Label
        Report_Value10                  matlab.ui.control.Spinner
        ProeminnciamnimaLabel_2         matlab.ui.control.Label
        Report_Unit10                   matlab.ui.control.Label
        Panel_24                        matlab.ui.container.Panel
        GridLayout23                    matlab.ui.container.GridLayout
        Report_Value13                  matlab.ui.control.Spinner
        ProeminnciamnimaLabel_3         matlab.ui.control.Label
        Report_Unit13                   matlab.ui.control.Label
        Report_Value16                  matlab.ui.control.Spinner
        MnimaocupaomximaLabel           matlab.ui.control.Label
        EmrelaocurvadeMaxHoldLabel      matlab.ui.control.Label
        Label_32                        matlab.ui.control.Label
        Report_playButton_2             matlab.ui.control.Button
        Report_recButton_2              matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        
        RootFolder    = [];
        SelectedFiles = {};
        FilesPath     = {};
        
        Samples  = [];
        metaData = struct('File', [], 'Data', [], 'Samples', [])
        specData = [];
        
        filtData = [];
        
        % pyRedmine
        mod            = [];
        redmineObj     = [];
        ReportFilename = '';
        
        channelData = []; % 2 colunas. A primeira define a linha do fluxo. A segunda define o índice do canal.
        
        dialogWin = [];
        
        Tree2_WarnCount = 0;
        
        nodesTable = [];
        
        htmlSet = [];
        
        fig     = [];
        axes1   = [];
        axes2   = [];
        axes3   = [];
        cBar1   = [];
        line1   = [];                                                       % WaterFall TimeStamp
        line2   = [];                                                       % OCC Threshold
        label1  = [];                                                       % OCC Threshold Label
        line3   = [];                                                       % Marker Frequency
        
        timeIndex = 0;
        
        dbFlag     = 0;
        occFlag    = 0;
        plotFlag   = 0;
        axesFlag   = 0;
        reloadFlag = 1;
        
        traceInfo = struct('SelectedNode', 1, 'Mode', [0, 0, 0], 'plotLayout', 0);
        
        cTraces = [0.07, 0.07, 0.75; ...                                    % color1 (MinHold)
                   0.39, 0.83, 0.07; ...                                    % color2 (Median or Mean)
                   1.00, 0.07, 0.65; ...                                    % color3 (MaxHold)
                   0.93, 0.69, 0.13];                                       % color4 (ClearWrite)
        
        x;
        Span;
        aCoef;
        bCoef;
        Unit;
        
        occTHR;                                                             % "OffSet Statistical OCC"
        locs = [];                                                          % Índices dos marcadores.
        
    end
    
    
    methods (Access = private)
        
        function Fcn_MetaDataReader(app)
            
            if isempty(app.metaData(1).File)
                auxIndex = 1;
            else
                auxIndex = length(app.metaData)+1;
            end
        
            for ii = 1:length(app.SelectedFiles)
                app.metaData(ii).File = [app.FilesPath{ii} app.SelectedFiles{ii}];
            end
            
            % Progress window
            d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on');
            
            for ii = auxIndex:length(app.metaData)
                if length(app.SelectedFiles{ii}) <= 50
                    d.Message = sprintf('Em andamento a leitura de metadados do arquivo:\n• %s\n\n%d de %d', app.SelectedFiles{ii}, ii, length(app.metaData));
                else
                    d.Message = sprintf('Em andamento a leitura de metadados do arquivo:\n• %s\n\n%d de %d', [app.SelectedFiles{ii}(1:48) '...'], ii, length(app.metaData));
                end
                
                auxFile = strsplit(app.metaData(ii).File, '.');
                if strcmpi(auxFile{end}, 'bin')
                    app.metaData(ii).Data = Fcn_BinFileReaderV4(app.metaData(ii).File, 'MetaData', []);
                elseif strcmpi(auxFile{end}, 'sm1809')
                    app.metaData(ii).Data = Fcn_SM1809FileReader(app.metaData(ii).File, 'MetaData', []);
                end
                
                app.metaData(ii).Samples = [app.metaData(ii).Data.Samples]';
            end
            
        end
        
        
        function Fcn_SpecDataReader(app)
            
            % Organização dos fluxos de dados e pré-alocação
            Fcn_SpecDataReader_Aux1(app)
            
            % Leitura dos dados de espectro
            auxValue1 = length(app.metaData);
            for ii = 1:auxValue1
                if length(app.SelectedFiles{ii}) <= 50
                    app.dialogWin.Message = sprintf('Em andamento a leitura de dados do espectro do arquivo:\n• %s\n\n%d de %d', app.SelectedFiles{ii}, ii, auxValue1);
                else
                    app.dialogWin.Message = sprintf('Em andamento a leitura de dados do espectro do arquivo:\n• %s\n\n%d de %d', [app.SelectedFiles{ii}(1:48) '...'], ii, auxValue1);
                end
                
                auxFile = strsplit(app.metaData(ii).File, '.');
                if strcmpi(auxFile{end}, 'bin')
                    SpecInfo = Fcn_BinFileReaderV4(app.metaData(ii).File, 'SpecData', app.metaData(ii));
                elseif strcmpi(auxFile{end}, 'sm1809')
                    SpecInfo = Fcn_SM1809FileReader(app.metaData(ii).File, 'SpecData', app.metaData(ii));
                end                
                
                auxSpecInfo = rmfield(SpecInfo,     {'Samples', 'Data', 'gps', 'gpsString', 'gpsFlag', 'Blocks'});
                auxSpecData = rmfield(app.specData, {'Samples', 'Data', 'gps', 'gpsString', 'gpsFlag', 'Blocks'});
                
                for jj = 1:length(SpecInfo)
                    for kk = 1:length(app.specData)
                        if isequal(auxSpecData(kk), auxSpecInfo(jj))
                            Index1 = kk;
                            break
                        end
                    end
                                        
                    auxValue2 = sum(app.Samples(Index1, 1:ii));
                    if ii == 1
                        Index2 = 1;
                    else
                        Index2 = sum(app.Samples(Index1, 1:(ii-1)))+1;
                    end
                    
                    app.specData(Index1).Data{1}(1,Index2:auxValue2) = SpecInfo(jj).Data{1};
                    app.specData(Index1).Data{2}(:,Index2:auxValue2) = SpecInfo(jj).Data{2};
                end
            end
            app.specData = rmfield(app.specData, 'Blocks');            
            
            % Manipulações acessórias
            app.dialogWin.Message = sprintf('Em andamento manipulações acessórias, como aferição de parâmetros estatísticos e identificação de fluxo(s) de ocupação relacionado(s).');
            Fcn_SpecDataReader_Aux2(app)
            
        end
        
        
        function Fcn_SpecDataReader_Aux1(app)
            
            % Organização dos fluxos de dados
            for ii = 1:length(app.metaData)
                if ii == 1
                    app.specData = app.metaData(1).Data;
                    app.Samples  = app.metaData(1).Samples;
                else
                       SpecInfo = app.metaData(ii).Data;
                    auxSpecInfo = rmfield(SpecInfo, {'Samples', 'gps', 'gpsString', 'gpsFlag', 'Blocks'});
                    auxSamples  = app.metaData(ii).Samples;
                    
                    for kk = 1:length(SpecInfo)
                        auxIndex2 = find([app.specData.ThreadID] == SpecInfo(kk).ThreadID);
                        
                        if isempty(auxIndex2)
                            app.specData(end+1)    = SpecInfo(kk);
                            app.Samples(end+1, ii) = auxSamples(kk);
                        else
                            for ll = auxIndex2
                                auxSpecData = rmfield(app.specData(ll), {'Samples', 'gps', 'gpsString', 'gpsFlag', 'Blocks'});
                                
                                if isequal(auxSpecData, auxSpecInfo(kk))
                                    app.Samples(ll, ii) = auxSamples(kk);
                                    break
                                end
                                
                                if ll == auxIndex2(end)
                                    app.specData(end+1)    = SpecInfo(kk);
                                    app.Samples(end+1, ii) = auxSamples(kk);
                                end
                            end
                        end
                    end
                end
            end
            
            % Pré-alocação
            totalSamples = sum(app.Samples, 2);
            
            for jj = 1:length(app.specData)
                app.specData(jj).Samples = totalSamples(jj);
                app.specData(jj).Data    = {repmat(datetime([0 0 0 0 0 0], 'Format', 'dd/MM/yyyy HH:mm:ss'), 1, totalSamples(jj)), ...
                                            zeros(app.specData(jj).MetaData.NDATA,                              totalSamples(jj), 'single')};
            end
            
        end
        
        
        function Fcn_SpecDataReader_Aux2(app)
            
            FindPeaksSettings = struct('Description', {'TV_VHF1',           'FM',               'Aircraft',        'TV_VHF2',           'TV_UHF'},         ...
                                       'Band',        {[  54e+6  88e+6],    [87.8e+6 108e+6],    [108e+6 137e+6],   [ 174e+6 216e+6],    [ 470e+6 698e+6]}, ...
                                       'Settings',    {[4.45e+6 150e+3 67], [200e+3  15e+3 67], [ 25e+3  10e+3 1], [4.45e+6 150e+3 67], [4.45e+6 150e+3 67]});
            
            
            % Fluxos de dados ordenados por ThreadID
            if ~issorted([app.specData.ThreadID])
                [~, ind1]     = sort([app.specData.ThreadID]);
                app.specData  = app.specData(ind1);
            end
            
            
            % Fluxos de dados ordenados temporalmente
            for ii = 1:length(app.specData)
                if ~issorted(app.specData(ii).Data{1})
                    [app.specData(ii).Data{1}, ind2] = sort(app.specData(ii).Data{1});
                    app.specData(ii).Data{2}         = app.specData(ii).Data{2}(:,ind2);
                end
            end
            
            
            % Estatística e mapeamento dos fluxos de ocupação
            for ii = 1:length(app.specData)
                if ismember(app.specData(ii).MetaData.DataType, [60, 61, 63, 64, 67, 68, 1809])
                    % Estatística.
                    app.specData(ii).statsData = [min(app.specData(ii).Data{2}, [], 2), ...
                                                  median(app.specData(ii).Data{2}, 2),  ...
                                                  mean(app.specData(ii).Data{2}, 2),    ...
                                                  max(app.specData(ii).Data{2}, [], 2)];
                    
                    
                    % Caracterização do fluxo (e dos parâmetros automáticos
                    % p/ geração do relatório).
                    app.specData(ii).reportInfo = struct('Band', '',              ...
                                                         'statsFcn', '',          ...
                                                         'minProeminence_C1', [], ...
                                                         'minProeminence_C2', [], ...
                                                         'minBW', [],             ...
                                                         'minDistance', [],       ...
                                                         'minOCC', []);
                    
                    Band = 'none';
                    FrequencyBand = (app.specData(ii).MetaData.F0 + app.specData(ii).MetaData.F1)/2;
                    SpanBand      =  app.specData(ii).MetaData.F1 - app.specData(ii).MetaData.F0;
                    
                    for ll = 1:length(FindPeaksSettings)
                        if (FrequencyBand > FindPeaksSettings(ll).Band(1)) && (FrequencyBand < FindPeaksSettings(ll).Band(2)) && (SpanBand >= 10e+6)
                            Band = FindPeaksSettings(ll).Description;
                            ind3 = find(ismember({FindPeaksSettings.Description}, {Band}));
                            break
                        end
                    end
                    app.specData(ii).reportInfo.Band              =  Band;
                    app.specData(ii).reportInfo.statsFcn          = 'Mediana';
                    app.specData(ii).reportInfo.minProeminence_C1 = 10;
                    app.specData(ii).reportInfo.minProeminence_C2 = 30;
                        
                    if strcmp(Band, 'none')
                        app.specData(ii).reportInfo.minDistance = 25e+3;
                        app.specData(ii).reportInfo.minBW       = 10e+3;
                        app.specData(ii).reportInfo.minOCC      = 10;
                    else
                        app.specData(ii).reportInfo.minDistance = FindPeaksSettings(ind3).Settings(1);
                        app.specData(ii).reportInfo.minBW       = FindPeaksSettings(ind3).Settings(2);
                        app.specData(ii).reportInfo.minOCC      = FindPeaksSettings(ind3).Settings(3);
                    end           


                    % Fluxos de ocupação relacionados.
                    auxValue = [];
                    switch app.specData(ii).MetaData.DataType
                        case {60, 61}; auxValue = 62;
                        case {63, 64}; auxValue = 65;
                        case {67, 68}; auxValue = 69;
                    end
                    
                    ind4 = [];
                    for jj = 1:length(app.specData)
                        if app.specData(jj).MetaData.DataType == auxValue
                            ind4 = [ind4, jj];
                        end
                    end

                    if isempty(ind4)
                        continue
                    end
                    
                    app.specData(ii).occRelated = [];
                    app.specData(ii).occFlag = [];
                    for kk = ind4
                        logEvaluation = strcmp(app.specData(ii).Node, app.specData(kk).Node)         & ...
                                        app.specData(ii).MetaData.F0 == app.specData(kk).MetaData.F0 & ...
                                        app.specData(ii).MetaData.F1 == app.specData(kk).MetaData.F1 & ...
                                        app.specData(ii).MetaData.NDATA == app.specData(kk).MetaData.NDATA & ...
                                        strcmp(app.specData(ii).ScriptName, app.specData(kk).ScriptName);
            
                        if logEvaluation
                            app.specData(ii).occRelated = [app.specData(ii).occRelated, kk];
                        end
                    end
                    
                    if ~isempty(app.specData(ii).occRelated)
                        app.specData(ii).occFlag = app.specData(ii).occRelated(1);
                    end
                    
                elseif ismember(app.specData(ii).MetaData.DataType, [62, 65, 69])
                        app.specData(ii).statsData = [min(app.specData(ii).Data{2}, [], 2), ...
                                                      mean(app.specData(ii).Data{2}, 2),    ...
                                                      max(app.specData(ii).Data{2}, [], 2)];
                end
            end
            
        end
   
        
        function Fcn_Tree1(app)

            delete(app.Tree1.Children);
            
            app.Button_12.Enable = 1;
            app.LeituraespectroButton.Enable = 1;
            
            if app.Tree2.Enable
                app.Warning_Image.Visible = 1;
            else
                app.Warning_Image.Visible = 0;
            end
            
            value = length(app.SelectedFiles);
            if value
                for ii = 1:value
                    if contains(app.metaData(ii).Data(1).Node, 'RFeye', 'IgnoreCase', true)
                        imageIcon = 'CRFS.png';
                    elseif contains(app.metaData(ii).Data(1).Node, 'FSL', 'IgnoreCase', true)
                        imageIcon = 'R&S Icon.png';
                    else
                        imageIcon = 'R&S Icon.png';
                    end
                    
                    uitreenode(app.Tree1, 'Text', app.SelectedFiles{ii}, 'NodeData', ii, 'Icon', imageIcon);
                    
                end
                
            else
                app.metaData = struct('File', [], 'Data', [], 'Samples', []);
                
                app.Button_12.Enable = 0;
                app.LeituraespectroButton.Enable = 0;
            end
            app.Label_12.Text = sprintf('%d arquivo(s) selecionado(s)', value);
            
            Layout_Panel12(app)
            
        end
        
        
        function Fcn_Tree2(app)
            
            delete(app.Tree2.Children);
            app.Tree2_WarnCount = 0;
            
            Layout_Panel2_Aux(app)
            
            for ii = 1:length(app.specData)
                allNodes{ii,1} = app.specData(ii).Node;
            end
            [uniqueNodes, ~, ic] = unique(allNodes);
            a_counts = accumarray(ic, 1);
            
            for ii = 1:length(uniqueNodes)
                if contains(uniqueNodes{ii}, 'RFeye', 'IgnoreCase', true)
                    imageIcon = 'CRFS.png';
                elseif contains(uniqueNodes{ii}, 'FSL', 'IgnoreCase', true)
                    imageIcon = 'R&S Icon.png';
                else
                    imageIcon = 'R&S Icon.png';
                end
                Category(ii) = uitreenode(app.Tree2, 'Text', uniqueNodes{ii}, 'NodeData', uniqueNodes{ii}, 'Icon', imageIcon);
                
                auxIndex = find(ic == ii);
                for jj = 1:a_counts(ii)
                    if ismember(app.specData(auxIndex(jj)).MetaData.DataType, [60, 61, 63, 64, 67, 68, 1809])
                        Attributes(jj) = uitreenode(Category(ii), 'Text', sprintf('ThreadID %d: %.3f - %.3f MHz', ...
                                                                          app.specData(auxIndex(jj)).ThreadID, ...
                                                                          1e-6*app.specData(auxIndex(jj)).MetaData.F0, ...
                                                                          1e-6*app.specData(auxIndex(jj)).MetaData.F1), 'NodeData', auxIndex(jj));
                        
                        % GPS
                        if app.specData(auxIndex(jj)).gpsFlag == 0
                            uitreenode(Attributes(jj), 'Text', sprintf('GPS: %s', app.specData(auxIndex(jj)).gpsString), 'NodeData', auxIndex(jj));
                        elseif app.specData(auxIndex(jj)).gpsFlag == 1
                            app.Tree2_WarnCount = app.Tree2_WarnCount + 1;
                            
                            uitreenode(Attributes(jj), 'Text', 'GPS', 'NodeData', auxIndex(jj), 'Icon', 'treeAlarm2.png');
                        else
                            uitreenode(Attributes(jj), 'Text', sprintf('GPS: %s', app.specData(auxIndex(jj)).gpsString), 'NodeData', auxIndex(jj), 'Icon', 'Manual.png');
                        end
                            
                        % Fluxo(s) de Ocupação relacionado(s) ao de espectro
                        if ~isempty(app.specData(auxIndex(jj)).occRelated)
                        Temp2 = uitreenode(Attributes(jj), 'Text', 'Fluxo(s) de ocupação', 'NodeData', auxIndex(jj));

                            if isempty(app.specData(auxIndex(jj)).occFlag)
                                uitreenode(Temp2, 'Text', 'Ocupação a ser aferida', 'NodeData', auxIndex(jj), 'Icon', 'Manual.png');
                            end
                            
                            auxIndex2 = app.specData(auxIndex(jj)).occRelated;
                            for kk = auxIndex2
                                if (app.specData(auxIndex(jj)).occFlag == kk) & (kk == auxIndex2(1))
                                    uitreenode(Temp2, 'Text', sprintf('Threshold: %d %s (ThreadID %d)', app.specData(kk).MetaData.Threshold, app.specData(ii).MetaData.metaString{1}, app.specData(kk).ThreadID), 'NodeData', auxIndex(jj), 'Icon', 'Auto.png');
                                else
                                    if app.specData(auxIndex(jj)).occFlag == kk
                                        uitreenode(Temp2, 'Text', sprintf('Threshold: %d %s (ThreadID %d)', app.specData(kk).MetaData.Threshold, app.specData(ii).MetaData.metaString{1}, app.specData(kk).ThreadID), 'NodeData', auxIndex(jj), 'Icon', 'Manual.png');
                                    else
                                        uitreenode(Temp2, 'Text', sprintf('Threshold: %d %s (ThreadID %d)', app.specData(kk).MetaData.Threshold, app.specData(ii).MetaData.metaString{1}, app.specData(kk).ThreadID), 'NodeData', auxIndex(jj));
                                    end
                                end                     
                            end
                        end
                        
                    else
                        Attributes(jj) = uitreenode(Category(ii), 'Text', sprintf('ThreadID %d: %.3f - %.3f MHz (Ocupação)', ...
                                                                          app.specData(auxIndex(jj)).ThreadID, ...
                                                                          1e-6*app.specData(auxIndex(jj)).MetaData.F0, ...
                                                                          1e-6*app.specData(auxIndex(jj)).MetaData.F1), 'NodeData', auxIndex(jj));
                        
                        % Threshold
                        uitreenode(Attributes(jj), 'Text', sprintf('Threshold: %d %s', app.specData(auxIndex(jj)).MetaData.Threshold, app.specData(ii).MetaData.metaString{1}), 'NodeData', auxIndex(jj));

                    end
                    % Varreduras
                    Temp1 = uitreenode(Attributes(jj), 'Text', sprintf('%d varreduras', app.specData(auxIndex(jj)).Samples), 'NodeData', auxIndex(jj));
                    uitreenode(Temp1, 'Text', sprintf('%s (Início)', datestr(app.specData(auxIndex(jj)).Data{1}(1), 'dd/mm/yyyy HH:MM:SS')), 'NodeData', auxIndex(jj));
                    uitreenode(Temp1, 'Text', sprintf('%s (Fim)', datestr(app.specData(auxIndex(jj)).Data{1}(end),  'dd/mm/yyyy HH:MM:SS')), 'NodeData', auxIndex(jj));
                    
                end
            end
            
            if app.Tree2_WarnCount == 0
                app.Warning_Message.Text = sprintf('%d alerta(s)', app.Tree2_WarnCount);
                app.Warning_Message.FontColor = 'black';
            else
                app.Warning_Message.Text = sprintf('%d alerta(s) crítico(s)', app.Tree2_WarnCount);
                app.Warning_Message.FontColor = 'red';
            end
            
        end
        
        
        function Fcn_Tree3(app)
            
            delete(app.Tree3.Children);
            
            if ~isempty(app.filtData)
                if app.Button_Report.Value
                    app.Playback_Play.Enable = 1;
                end
            else
                return
            end
            
            for ii = 1:length(app.filtData)
                allNodes{ii,1} = app.specData(app.filtData(ii)).Node;
            end
            [uniqueNodes, ~, ic] = unique(allNodes);
            a_counts = accumarray(ic, 1);
            
            for ii = 1:length(uniqueNodes)
                if contains(uniqueNodes{ii}, 'RFeye', 'IgnoreCase', true)
                    imageIcon = 'CRFS.png';
                elseif contains(uniqueNodes{ii}, 'FSL', 'IgnoreCase', true)
                    imageIcon = 'R&S Icon.png';
                else
                    imageIcon = 'R&S Icon.png';
                end
                Category(ii) = uitreenode(app.Tree3, 'Text', uniqueNodes{ii}, 'NodeData', uniqueNodes{ii}, 'Icon', imageIcon);
                
                auxIndex1 = find(ic == ii);
                for jj = 1:a_counts(ii)
                    auxIndex2 = app.filtData(auxIndex1(jj));
                    
                    uitreenode(Category(ii), 'Text', sprintf('ThreadID %d: %.3f - %.3f MHz', ...
                                                     app.specData(auxIndex2).ThreadID, ...
                                                     1e-6*app.specData(auxIndex2).MetaData.F0, ...
                                                     1e-6*app.specData(auxIndex2).MetaData.F1), 'NodeData', auxIndex2);
                end
                
            end
            Fcn_Tree3_Aux1(app)
            
        end
        
        
        function Fcn_Tree3_Aux1(app)
            
            StartDate = [];
            StopDate  = [];
            for ii = 1:length(app.filtData)
                auxStartDate        = app.specData(app.filtData(ii)).Data{1}(1);
                auxStartDate.Format = 'dd-MMM-yyyy';
                
                auxStopDate         = app.specData(app.filtData(ii)).Data{1}(end);
                auxStopDate.Format  = 'dd-MMM-yyyy';
                
                StartDate = [StartDate auxStartDate];
                StopDate  = [StopDate  auxStopDate ];
            end
            
            app.Report_DatePicker1.Limits = [min(StartDate) max(StopDate)];
            app.Report_DatePicker1.Value  = app.Report_DatePicker1.Limits(1);
            
            app.Report_DatePicker2.Limits = app.Report_DatePicker1.Limits;
            app.Report_DatePicker2.Value  = app.Report_DatePicker2.Limits(2);
            
            app.Report_Spinner1.Value = 0;
            app.Report_Spinner2.Value = 0;
            app.Report_Spinner3.Value = 23;
            app.Report_Spinner4.Value = 59;
            
        end        
        
        
        function Fcn_Tree4(app)
            
            for ii = 1:length(app.locs)
                uitreenode(app.Tree4, 'Text', sprintf("M%d: %.3f MHz @", ii, app.x(app.locs(ii))/1e+6), 'NodeData', [ii, app.traceInfo.SelectedNode, app.locs(ii)]);
            end
            
            if ~isempty(app.locs)
                app.Tree4.SelectedNodes = app.Tree4.Children(1);
                app.Button_46.Enable = 1;
                
                app.Marker_CF.Value      = str2double(extractBetween(app.Tree4.SelectedNodes.Text, ": ", " MHz"));
                app.FrequnciacentraldomarcadorLabel.Enable = 1;
                app.Marker_CF.Enable     = 1;
                app.Marker_CFUnit.Enable = 1;
            end
            
        end
        
        
        function Fcn_Plot(app)
            
            Fcn_Plot_Layout1(app)
            Fcn_Plot_Layout2(app)
            Fcn_Plot_StartUp1(app)
            Fcn_Plot_StartUp2(app)
            
            while app.timeIndex < app.specData(app.traceInfo.SelectedNode).Samples
                if ~app.plotFlag
                    return
                elseif app.axesFlag
                    Fcn_Plot_Layout1(app)
                    Fcn_Plot_Layout2(app)
                    Fcn_Plot_StartUp1(app)
                    Fcn_Plot_StartUp2(app);
                end
                app.timeIndex = app.timeIndex+1;
                
% %             TimeStamp and Slider Update.    
                app.axes1.Subtitle.String = datestr(app.specData(app.traceInfo.SelectedNode).Data{1}(app.timeIndex), 'dd/mm/yyyy HH:MM:SS');
                app.Playback_Slider.Value  = 100 * app.timeIndex/app.specData(app.traceInfo.SelectedNode).Samples;
                
% %             Axes 1: Spectrum Plot.
                app.axes1.Children(end).YData = app.specData(app.traceInfo.SelectedNode).Data{2}(:, app.timeIndex)';
                
% %             Markers labels.
                jj = 0;
                if ~strcmp(app.axes1.Children(end).Marker, 'none')
                    for kk = 1:length(app.axes1.Children(end).MarkerIndices)
                        jj = jj+1;
                        app.axes1.Children(end-jj).Position(2) = app.axes1.Children(end).YData(app.axes1.Children(end).MarkerIndices(kk));
                        
                        if ~mod(app.timeIndex, 10)
                            auxStr = app.Tree4.Children(end-kk+1).Text(1:strfind(app.Tree4.Children(end-kk+1).Text, '@'));
                            app.Tree4.Children(end-kk+1).Text = sprintf('%s %.1f %s', auxStr, app.axes1.Children(end).YData(app.axes1.Children(end).MarkerIndices(kk)), app.Unit);
                        end
                    end
                    jj=jj+1;
                end
                
% %             Processed traces.               
                auxIndex = find(app.traceInfo.Mode ~= 0);
                if app.Playback_Radio2.Value
                    for ii = auxIndex
                        jj = jj+1;
                        
                        switch app.traceInfo.Mode(ii)
                            case 1; app.axes1.Children(end-jj).YData = min(app.axes1.Children(end-jj).YData, app.axes1.Children(end).YData);
                            case 3; app.axes1.Children(end-jj).YData = ((app.Playback_Value1.Value-1)*app.axes1.Children(end-jj).YData ...
                                                                       + app.axes1.Children(end).YData)/app.Playback_Value1.Value;
                            case 4; app.axes1.Children(end-jj).YData = max(app.axes1.Children(end-jj).YData, app.axes1.Children(end).YData);
                        end
                    end
                end
                
% %             Axes2: OCC Plot.
                if ~isempty(app.axes2.Children)
                    switch app.Playback_Value2.Value
                        case 'Linear'
                            app.axes2.Children(1).YData = ((app.Playback_Value4.Value-1)*app.axes2.Children(1).YData ...
                                                           +100*single(app.axes1.Children(end).YData > app.Playback_Value3.Value))/app.Playback_Value4.Value;
                            app.axes2.Children(2).YData = max(app.axes2.Children(1).YData, app.axes2.Children(2).YData);
                        case 'Piso de ruído (Offset)'
                            app.axes2.Children(1).YData = ((app.Playback_Value4.Value-1)*app.axes2.Children(1).YData ...
                                                           +100*single(app.axes1.Children(end).YData > app.occTHR))/app.Playback_Value4.Value;
                            app.axes2.Children(2).YData = max(app.axes2.Children(1).YData, app.axes2.Children(2).YData);
                    end
                end
                                
% %             Axes3: WaterFall Plot.
%                 app.line1.Position(2) = app.timeIndex;
                if app.traceInfo.plotLayout == 2
                    app.line1.Position(2) = .1 + (.4/(app.specData(app.traceInfo.SelectedNode).Samples-1))*(app.timeIndex-1);
                elseif app.traceInfo.plotLayout == 3
                    app.line1.Position(2) = .1 + (.3/(app.specData(app.traceInfo.SelectedNode).Samples-1))*(app.timeIndex-1);
                end
              drawnow
%             pause(0.05)

                
% %             Reload Flag.
                if app.timeIndex == app.specData(app.traceInfo.SelectedNode).Samples
                    if app.reloadFlag
                        app.timeIndex = 0;
                    else
                        Layout_Playback(app)
                    end
                end
            end
            app.timeIndex      = 0;
            app.Playback_Slider.Value = 0;
            
        end
        
        
        function Fcn_Plot_Layout1(app)
            
            app.fig.Visible = 1;
            cla(app.axes1)
            cla(app.axes2)
            
% %         Limpa WaterFall caso tenha ocorrido uma alteração do fluxo selecionado.    
            if app.traceInfo.SelectedNode ~= app.Tree2.SelectedNodes.NodeData
                app.timeIndex = 0;
                app.locs      = [];
                
                app.Button_46.Enable = 0;
                app.FrequnciacentraldomarcadorLabel.Enable = 0;
                app.Marker_CF.Enable     = 0;
                app.Marker_CFUnit.Enable = 0;
                
                delete(app.Tree4.Children)
                cla(app.axes3)
                drawnow nocallbacks
            end
            app.traceInfo.SelectedNode = app.Tree2.SelectedNodes.NodeData;
            
% %         Desabilita WaterFall caso fluxo de dados tenha apenas uma amostra.    
            if app.specData(app.traceInfo.SelectedNode).Samples > 1
                app.Playback_CheckBox5.Enable = 1;
            else
                app.Playback_CheckBox5.Enable = 0;
                app.Playback_CheckBox5.Value  = 0;
                if ismember(app.traceInfo.plotLayout, [2, 3])
                    app.traceInfo.plotLayout = 0;
                end
            end
            
        end
        
        
        function Fcn_Plot_Layout2(app)
            
            linkaxes([app.axes1, app.axes2, app.axes3], 'off')
                        
            switch app.traceInfo.plotLayout
                case 0
                    app.axes1.InnerPosition = [.08 .1 .85 .82];
                    app.axes2.InnerPosition = [0 0 0 0];
                    app.axes3.InnerPosition = [0 0 0 0];
                    
                    app.cBar1.Position = [0 0 0 0];
                    app.line1.Position(3) = 0;
                    
                    app.axes1.XTickLabelMode = 'auto';
                    
                    xlabel(app.axes1, 'Frequência (Hz)')
                    
                case 1
                    app.axes1.InnerPosition = [.08 .52 .85 .40];
                    app.axes2.InnerPosition = [.08 .1  .85 .40];
                    app.axes3.InnerPosition = [0 0 0 0];
                    
                    app.cBar1.Position = [0 0 0 0];
                    app.line1.Position(3) = 0;
                    
                    app.axes1.XTickLabel = {};
                    app.axes2.XTickLabelMode = 'auto';
                    
                    xlabel(app.axes1, '')
                    xlabel(app.axes2, 'Frequência (Hz)')
                    
                    linkaxes([app.axes1, app.axes2], 'x')

                case 2
                    app.axes1.InnerPosition = [.08 .52 .85 .40];
                    app.axes2.InnerPosition = [0 0 0 0];
                    app.axes3.InnerPosition = [.08 .1  .85 .40];
                    
                    app.cBar1.Position = [0.9405, 0.1, 0.0145, 0.1];
                    app.line1.Position(3) = .85;
                    
                    app.axes1.XTickLabel = {};
                    app.axes3.XTickLabelMode = 'auto';
                    
                    xlabel(app.axes1, '')
                    xlabel(app.axes2, '')
                    xlabel(app.axes3, 'Frequência (Hz)')
                    
                    linkaxes([app.axes1, app.axes3], 'x')

                case 3
                    app.axes1.InnerPosition = [.08 .62 .85 .30];
                    app.axes2.InnerPosition = [.08 .42 .85 .18];
                    app.axes3.InnerPosition = [.08 .1  .85 .30];
                    
                    app.cBar1.Position = [0.9405, 0.1, 0.0145, 0.1];
                    app.line1.Position(3) = .85;
                    
                    app.axes1.XTickLabel = {};
                    app.axes2.XTickLabel = {};
                    app.axes3.XTickLabelMode = 'auto';
                    
                    xlabel(app.axes1, '')
                    xlabel(app.axes2, '')
                    xlabel(app.axes3, 'Frequência (Hz)')
                    
                    linkaxes([app.axes1, app.axes2, app.axes3], 'x')
            end
            
        end
        
        
        function Fcn_Plot_StartUp1(app)
            
% %         Axes1 (Spectrum plot)            
            titleStr = [app.specData(app.traceInfo.SelectedNode).Node '. '];
            if ~isempty(app.specData(app.traceInfo.SelectedNode).gpsString)
                titleStr = [titleStr sprintf('GPS: %s. ', app.specData(app.traceInfo.SelectedNode).gpsString)];
            end
            
            if ~isempty(app.specData(app.traceInfo.SelectedNode).MetaData.metaString{4})             
                titleStr = [titleStr sprintf('%s/', app.specData(app.traceInfo.SelectedNode).MetaData.metaString{4})];
            end

            titleStr = [titleStr sprintf('%s. ', app.specData(app.traceInfo.SelectedNode).MetaData.metaString{3})];
                                    
            if ~isempty(app.specData(app.traceInfo.SelectedNode).MetaData.Resolution)
                titleStr = [titleStr sprintf('RBW: %.3f kHz. ', app.specData(app.traceInfo.SelectedNode).MetaData.Resolution/1000)];
            end        
            
            titleStr    = [titleStr sprintf('SWP: %d.', app.specData(app.traceInfo.SelectedNode).MetaData.NDATA)];
            subtitleStr = datestr(app.specData(app.traceInfo.SelectedNode).Data{1}(1), 'dd/mm/yyyy HH:MM:SS');
            title(app.axes1, titleStr, subtitleStr);
            
            if ~ismember(app.specData(app.traceInfo.SelectedNode).MetaData.DataType, [62, 65, 69])
                auxValue = min(app.specData(app.traceInfo.SelectedNode).statsData(:,1));
                downYLim = auxValue - mod(auxValue, 10);
            
                auxValue = max(app.specData(app.traceInfo.SelectedNode).statsData(:,end));
                upYLim   = auxValue - mod(auxValue, 10) + 10;
                
                app.Unit = app.specData(app.traceInfo.SelectedNode).MetaData.metaString{1};
                auxStr = ['Nível (' app.Unit ')'];
                app.Playback_Unit3.Text = app.Unit;
                
            else
                downYLim = 0;
                upYLim   = 100;
                
                auxStr = 'Ocupação (%)';
                app.Unit = '%';
            end
            set(app.axes1, 'XLim', [app.specData(app.traceInfo.SelectedNode).MetaData.F0, app.specData(app.traceInfo.SelectedNode).MetaData.F1], 'YLim', [downYLim, upYLim]);            
            ylabel(app.axes1, auxStr);
            
% %          Axes 2 (OCC plot)
            if app.Playback_CheckBox4.Value
                set(app.axes2, 'XLim', app.axes1.XLim, 'YLim' , [0, 100]);
                ylabel(app.axes2, 'Ocupação (%)');
            end
            
% %          Axes 2 (WaterFall plot)
            if app.Playback_CheckBox5.Value
                if diff(app.axes1.YLim) > 100
                    Li = (app.axes1.YLim(1)+diff(app.axes1.YLim)-100);
                    Ls = app.axes1.YLim(2);
                else
                    Li = app.axes1.YLim(1);
                    Ls = app.axes1.YLim(2);
                end
                caxis(app.axes3, [Li Ls])
                colormap(app.axes3, 'jet');
                
                set(app.cBar1, 'Limits', [Li, Ls], 'Ticks', [Li, Ls], 'TickLabels', {num2str(Li), num2str(Ls)});                
                set(app.axes3, 'XLim', app.axes1.XLim);
                ylabel(app.axes3, 'Amostras');
            end
                
        end
        
        
        function Fcn_Plot_StartUp2(app)
            
            app.x = linspace(app.specData(app.traceInfo.SelectedNode).MetaData.F0, ...
                             app.specData(app.traceInfo.SelectedNode).MetaData.F1, ...
                             app.specData(app.traceInfo.SelectedNode).MetaData.NDATA);
            
% %         Spectrum and its peaks.
            if isempty(app.locs)
                plot(app.axes1, app.x, app.specData(app.traceInfo.SelectedNode).Data{2}(:, app.timeIndex+1), 'Tag', 'ClearWrite', 'Color', app.cTraces(4,:));
            
            else
                plot(app.axes1, app.x, app.specData(app.traceInfo.SelectedNode).Data{2}(:, app.timeIndex+1), 'Tag', 'ClearWrite', 'Color', app.cTraces(4,:), ...
                     'Marker' , '.', 'MarkerIndices', app.locs, "MarkerFaceColor", [0.40,0.73,0.88], "MarkerEdgeColor", [0.40,0.73,0.88], 'MarkerSize', 14);
                
                auxStr = {};
                for ii = 1:length(app.locs)
                    auxStr = [auxStr {['  ' num2str(ii)]}];
                end
                text(app.axes1, app.x(app.locs), double(app.specData(app.traceInfo.SelectedNode).Data{2}(app.locs, app.timeIndex+1)), auxStr, "Color", [0.40,0.73,0.88], "FontSize", 8, "FontWeight", "bold", "FontName", 'Calibri', "FontSmoothing", "on");
                
                app.Tree4.SelectedNodes = app.Tree4.Children(1);
                app.line3  = images.roi.Line(app.axes1,'Position',[app.x(app.locs(1)) app.axes1.YLim(1); app.x(app.locs(1)) app.axes1.YLim(2)], ...
                                             'Color', [0.40,0.73,0.88], 'MarkerSize', 4, 'Deletable', 0, 'LineWidth', .5, 'InteractionsAllowed', 'translate', "Tag", 'mkrROI');
                
                addlistener(app.line3, 'MovingROI', @app.mkrLineROI);
                addlistener(app.line3, 'ROIMoved',  @app.mkrLineROI);
                
                app.Marker_CF.Value = round(app.line3.Position(1)/1e+6, 3);
                            
            end
            
% %         Trace processing.   
            auxIndex1 = find(app.traceInfo.Mode ~= 0);
            if ~isempty(auxIndex1)
                if app.Playback_Radio1.Value
                    for ii = auxIndex1                        
                        switch ii
                            case 1; auxStr = 'MinHold';
                            case 2
                                switch app.traceInfo.Mode(2)
                                    case 2; auxStr = 'Median';
                                    case 3; auxStr = 'Mean';
                                end
                            case 3; auxStr = 'MaxHold';
                        end
                        plot(app.axes1, app.x, app.specData(app.traceInfo.SelectedNode).statsData(:, app.traceInfo.Mode(ii)), 'Tag', auxStr, 'Color', app.cTraces(ii,:));
                        
                    end
                    
                else
                    for ii = auxIndex1
                        switch ii
                            case 1; auxStr = 'MinHold';
                            case 2; auxStr = 'Mean';
                            case 3; auxStr = 'MaxHold';
                        end
                        plot(app.axes1, app.x, app.specData(app.traceInfo.SelectedNode).Data{2}(:, app.timeIndex+1), 'Tag', auxStr, 'Color', app.cTraces(ii,:));
                        
                    end
                end
            end

% %         OCC.    
            if app.Playback_CheckBox4.Value
                if isempty(findobj('Type', 'line', 'Tag', 'thrOCC'))
                    if (app.specData(app.traceInfo.SelectedNode).MetaData.DTYPE == 0) && (app.Playback_Value3.Value > 0)
                        app.Playback_Value3.Value = -80;
                        app.Playback_Unit1.Text   = 'dBm';
                    elseif (app.specData(app.traceInfo.SelectedNode).MetaData.DTYPE == 1) && (app.Playback_Value3.Value < 0)
                        app.Playback_Value3.Value = 40;
                        app.Playback_Unit1.Text   = 'dBμV/m';
                    end
                    
                    if app.Playback_Value3.Value > app.axes1.YLim(2)
                        app.Playback_Value3.Value = double(app.axes1.YLim(2));
                    end
                    
                    switch app.Playback_Value2.Value
                        case 'Linear'
                            app.line2  = images.roi.Line(app.axes1,'Position',[app.x(1) app.Playback_Value3.Value; app.x(end) app.Playback_Value3.Value], ...
                                                                   'Color', 'red', 'MarkerSize', 4, 'Deletable', 0, 'LineWidth', 1, 'InteractionsAllowed', 'translate', "Tag", 'occROI');
                            app.label1 = text(app.axes1, app.x(end), app.Playback_Value3.Value, ' thrOCC', 'FontName', 'Calibri', 'FontSize', 9, 'Color', 'red');
                            
                            addlistener(app.line2, 'MovingROI', @app.occLineROI);
                            addlistener(app.line2, 'ROIMoved',  @app.occLineROI);
                            
                        case 'Piso de ruído (Offset)'
                            switch app.Playback_Value5.Value
                                case 'Mediana'      ; auxIndex2 = 2;
                                case 'Média (vídeo)'; auxIndex2 = 3;
                            end
                            app.occTHR = app.specData(app.traceInfo.SelectedNode).statsData(:, auxIndex2)';
                            
                            auxMatrix = sort(app.specData(app.traceInfo.SelectedNode).Data{2});
                            auxMatrix = auxMatrix(1:ceil(app.Playback_Value7.Value * app.specData(app.traceInfo.SelectedNode).MetaData.NDATA / 100), :);
                            noiseMean = mean(auxMatrix,    'all');
                            noiseStd  =  std(auxMatrix, 1, 'all');
                            
                            switch app.Playback_Value8.Value
                                case '68'; auxValue = 1;
                                case '95'; auxValue = 2;
                                case '99'; auxValue = 3;
                            end
                            app.occTHR(app.occTHR < noiseMean - auxValue*noiseStd) = noiseMean - auxValue*noiseStd;
                            app.occTHR(app.occTHR > noiseMean + auxValue*noiseStd) = noiseMean + auxValue*noiseStd;
                            
                            app.occTHR = app.occTHR + app.Playback_Value6.Value;
                            
                            plot(app.axes1, app.x, app.occTHR, 'Tag', 'thrOCC', 'Color', 'red', 'LineStyle', '-', 'LineWidth', 1, ...
                                                               'Marker', 'o', 'MarkerSize', 4, 'MarkerIndices', [1, app.specData(app.traceInfo.SelectedNode).MetaData.NDATA], ...
                                                               'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'black');
                            app.label1 = text(app.axes1, app.x(end), double(app.occTHR(end)), ' thrOCC', 'FontName', 'Calibri', 'FontSize', 9, 'Color', 'red');
                    end
                    
                    plot(app.axes2, app.x, zeros(1, length(app.axes1.Children(end).YData), 'single'), 'Tag', 'maxOCC', 'Color', app.cTraces(3,:));   % MaxHold
                    plot(app.axes2, app.x, zeros(1, length(app.axes1.Children(end).YData), 'single'), 'Tag', 'medOCC', 'Color', app.cTraces(2,:));   % Média
                end
            end
            
% %         WaterFall.    
            if app.Playback_CheckBox5.Value
                if isempty(findobj('Type', 'Surface', 'Tag', 'WaterFall'))
                    [auxRows, auxColumns] = size(app.specData(app.traceInfo.SelectedNode).Data{2});      
                    auxSize = auxRows .* auxColumns;
                    if auxSize > 1474560
                        Decimate = ceil(auxSize/1474560);
                    else
                        Decimate = 1;
                    end
                    
                    t = 1:Decimate:app.specData(app.traceInfo.SelectedNode).Samples;
                    if t(1) == t(end)
                        t(end) = t(1)+0.01;
                    end
                                                            
% %                 DateTimeRuler como opção a ser explorada.
%                     t = app.specData(app.traceInfo.SelectedNode).Data{1}(1:Decimate:end);
%                     if t(1) == t(end)
%                         t(end) = t(1)+seconds(1);
%                     end
                    
                    [X, Y] = meshgrid(app.x, t);
                    mesh(app.axes3, X, Y, app.specData(app.traceInfo.SelectedNode).Data{2}(:,1:Decimate:end)', 'Tag', 'WaterFall')
                    view(app.axes3, 0, 90);
                    
% %                 Já foram implantadas duas soluções. A line e a Cuboid (ROI). Ambas apresentaraão lentidão apenas p/ alguns fluxos de dados, não sendo compreendida
% %                 a relação entre a lentidão e os parâmetros NDATA, Samples e Decimate.
%                     app.line1 = images.roi.Cuboid(app.axes3, 'Position', [app.specData(app.traceInfo.SelectedNode).MetaData.F0, app.timeIndex+1, app.axes3.ZLim(2), ...
%                                 (app.specData(app.traceInfo.SelectedNode).MetaData.F1 - app.specData(app.traceInfo.SelectedNode).MetaData.F0), 0, 0], ...
%                                 'Color', 'red');
%                    line(app.axes3, [x(1) x(end)], [t(1) t(1)], [app.axes1.YLim(2) app.axes1.YLim(2)], 'Color', 'red', 'LineWidth', 1);
                    
                    set(app.axes3, 'YLim' , [t(1); t(end)], 'YTick', [t(1); t(end)], 'YTickLabel', [1; app.specData(app.traceInfo.SelectedNode).Samples]);
                end
            end
            drawnow nocallbacks

            app.axesFlag = 0;
            
        end
        
        
        function reportInfo = Fcn_ReportGenerator_Aux(app, auxDate1, auxDate2)
            
            reportInfo = struct('Issue',    [], 'Version',   '', 'CheckBoxes', [], ...
                                'freeText', [], 'TimeStamp', [], 'OCC', []);
            
            reportInfo.Issue      = app.Report_Value2.Value;
            reportInfo.Version    = app.Report_Value1_3.Value;
            reportInfo.CheckBoxes = [app.Report_CheckBox2.Value, ...
                                     app.Report_CheckBox3.Value, ...
                                     app.Report_CheckBox4.Value];
            
            if app.Report_CheckBox4.Value
                if isempty(app.htmlSet)
                    app.htmlSet = Fcn_htmlSet('htmlEntityBasicSet.txt');
                end
                
                htmlStr = [];
                for ii = 1:length(app.Report_TextArea.Value)
                    htmlStr = [htmlStr '<p class="Item_Nivel2">' app.Report_TextArea.Value{ii} '</p>'];
                end
                
                for ii = 2:size(app.htmlSet, 1)
                    htmlStr = replace(htmlStr, app.htmlSet.Char{ii}, app.htmlSet.Entity{ii});
                end
                reportInfo.freeText = htmlStr;
            end
            
            reportInfo.TimeStamp = [auxDate1, auxDate2];
            
            reportInfo.OCC.Threshold = app.Playback_Value2_2.Value;
            reportInfo.OCC.Value     = app.Playback_Value3_2.Value;
            reportInfo.OCC.intTime   = app.Report_Value4.Value;
            reportInfo.OCC.statsFcn  = app.Report_Value6.Value;
            reportInfo.OCC.tSamples  = app.Report_Value7.Value;
            reportInfo.OCC.uSamples  = app.Report_Value8.Value;
            reportInfo.OCC.OffSet    = app.Report_Value5.Value;
            reportInfo.OCC.ConfLevel = app.Playback_Value8_2.Value;
            
        end
        
        
        function Layout_Panel11(app)
            
            if ~isempty(app.SelectedFiles)
                app.Label_11.Enable = 1;
                app.Label_12.Enable = 1; 
                
                app.Tree1.Enable = 1;
            else
                app.Label_11.Enable = 0;
                app.Label_12.Enable = 0; 
                
                app.Tree1.Enable = 0;
                app.LeituraespectroButton.Enable = 0;
            end
            
        end
        
        
        function Layout_Panel12(app)
            
            if ~isempty(app.Tree1.SelectedNodes)
                app.Button_12.Enable   = 1;
                app.Label_13.Enable    = 1;
                
                value1 = numel(app.Tree1.SelectedNodes);
                if value1 == 1
                    app.metaData_15.Enable = 1;
                else
                    app.metaData_15.Enable = 0;
                    app.metaData_15.Value  = '';
                end
                                
            else
                app.Button_12.Enable   = 0;
                app.Label_13.Enable    = 0;
                app.metaData_15.Enable = 0;
                
                app.metaData_15.Value  = '';
            end
            
        end
        
        
        function Layout_Panel2(app)
            
            app.Grid1.ColumnWidth{3} = 300;
            
            set(app.GridLayout28.Children, 'Enable', 1)
            app.Button_Report.Value     = 0;
            app.Button_Playback.Value   = 0;
            app.Button_Info.Value       = 0;

            app.Warning_Image.Visible = 0;

            Layout_Panel2_Aux(app)
            Layout_Panel3(app)
            
        end
        
        
        function Layout_Panel2_Aux(app)
            
            set(app.Grid_2.Children, 'Enable', 0)
            app.Tree2_Label.Enable     = 1;
            app.Tree2.Enable           = 1;
            app.Warning_Message.Enable = 1;
            
            app.ImageTree.ImageSource  = 'ExpandA1.png';
            app.ImageTree.Enable       = 1;
            
            app.metaData_16.Value      = '';
            
        end
        
        
        function Layout_Panel3(app)
            
            app.Tree2.Multiselect = 0;
            
            app.Grid3_Report.Visible   = 0;
            app.Grid3_Playback.Visible = 0;
            app.Grid3_Info.Visible     = 0;
            app.Logo.Visible           = 0;
            
% %         Report Generator Mode    
            if app.Button_Report.Value
%                 app.Grid1.ColumnWidth([1:2, 6]) = {0,0, 625};
                
                app.Tree2.Multiselect = 1;
                
                if ~isempty(app.filtData)
                    app.Report_recButton.Enable  = 1;
                    app.Report_playButton.Enable = 1;
                else
                    app.Report_recButton.Enable  = 0;
                    app.Report_playButton.Enable = 0;
                end
                
                app.Grid3_Report.Visible = 1;
            
% %         Playback Mode        
            elseif app.Button_Playback.Value
                app.Grid3_Playback.Visible = 1;
                    
                auxValue = length(app.Tree2.SelectedNodes);
                if auxValue > 1
                    app.Tree2.SelectedNodes = app.Tree2.SelectedNodes(1);
                end
                
                if ~isempty(app.Tree2.SelectedNodes) && isnumeric(app.Tree2.SelectedNodes.NodeData)
                    app.Playback_Play.Enable = 1;
                else
                    app.Tree2.SelectedNodes  = [];
                    app.Playback_Play.Enable = 0;
                end

                Layout_Playback_Aux(app)
                
% %         Info/Edit Mode        
            elseif app.Button_Info.Value
                app.Grid3_Info.Visible = 1;
                
                auxValue = numel(app.Tree2.SelectedNodes);
                if auxValue > 1
                    app.Tree2.SelectedNodes = app.Tree2.SelectedNodes(1);
                end
                
                if ~isempty(app.Tree2.SelectedNodes) && isnumeric(app.Tree2.SelectedNodes.NodeData)
                    Layout_Info_Aux(app, 'on')
                else
                    app.Tree2.SelectedNodes  = [];
                    Layout_Info_Aux(app, 'off')
                end
                
            else
                app.Logo.Visible = 1;
            end
                
        end
        
        
        function Layout_Playback_Aux(app)
            
            auxValue = length(app.Tree2.SelectedNodes);
            
            if auxValue == 0
                if app.occFlag
                    app.occFlag = 0;
                    Layout_Playback_Spec(app)
                end
                return
                
            elseif auxValue == 1
                if ~isnumeric(app.Tree2.SelectedNodes.NodeData)
                    if app.occFlag
                        app.occFlag = 0;
                        Layout_Playback_Spec(app)
                    end
                    return
                end
                
                if ismember(app.specData(app.Tree2.SelectedNodes.NodeData).MetaData.DataType, [60, 61, 63, 64, 67, 68, 1809])
                    if app.occFlag
                        app.occFlag = 0;
                        Layout_Playback_Spec(app)
                    end
                    
                else
                    if ~app.occFlag
                        app.occFlag = 1;
                        Layout_Playback_OCC(app)
                    end
                    
                    switch app.traceInfo.plotLayout
                        case 1; app.traceInfo.plotLayout = 0;
                        case 3; app.traceInfo.plotLayout = 2;
                    end
                end
                Fcn_Traces(app)
                
            end
            
        end

        
        function Layout_Playback_Spec(app)
            
            % Painel α:
            app.Playback_Label1.Enable = 1;
            app.Playback_Label2.Enable = 1;
            
            app.Playback_CheckBox1.Enable = 1;
            app.Playback_CheckBox2.Enable = 1;
            app.Playback_CheckBox3.Enable = 1;
            app.Playback_CheckBox4.Enable = 1;

            app.Playback_CheckBox1.Value = 0;
            app.Playback_CheckBox2.Value = 0;
            app.Playback_CheckBox3.Value = 0;
            app.Playback_CheckBox4.Value = 0;
            
            set(app.RadioGroup1.Children, 'Enable', 1)
            set(app.RadioGroup2.Children, 'Enable', 0)
            set(app.Grid4.Children, 'Enable', 0)
            
            % Painel β:
            app.Playback_Radio6.Value  = 1;
            app.Playback_Radio7.Enable = 1;
            set(app.Grid7.Children, 'Enable', 0)
            
            app.Button_45.Enable = 0;
            app.Button_46.Enable = 0;
            
            if ~isempty(app.Tree4.Children)
                delete(app.Tree4.Children)
                app.locs = [];
            end
            
            app.FrequnciacentraldomarcadorLabel.Enable = 0;
            app.Marker_CF.Enable     = 0;
            app.Marker_CFUnit.Enable = 0;
            
        end
        
        
        function Layout_Playback_OCC(app)
                        
            app.Playback_Label1.Enable = 0;
            app.Playback_Radio1.Enable = 0;
            app.Playback_Radio2.Enable = 0;
            
            app.Playback_Label2.Enable = 0;
            app.Playback_CheckBox1.Enable = 0;
            app.Playback_CheckBox2.Enable = 0;
            app.Playback_CheckBox3.Enable = 0;
            app.Playback_CheckBox4.Enable = 0;
                            
            app.Playback_CheckBox1.Value  = 0;
            app.Playback_CheckBox2.Value  = 0;
            app.Playback_CheckBox3.Value  = 0;
            app.Playback_CheckBox4.Value  = 0;
            
            set(app.Grid4.Children, 'Enable', 0)
            
            app.Playback_Radio6.Value = 1;
            app.Playback_Radio7.Enable = 0;             
            Marker_RadioGroupSelectionChanged(app)
            
        end
        
        
        function Layout_Info_Aux(app, Flag)
            
            switch Flag
                case 'on'
                    app.Info_Label1.Enable = 1;
                    app.Info_Label2.Enable = 1;
                    app.Info_Button.Enable = 1;
                    
                    set(app.Info_Grid2.Children,   'Enable', 1)
                    set(app.Info_Grid2_2.Children, 'Enable', 1)
                    
                    auxFlag = 0;
                    selectedNodes = app.Tree2.SelectedNodes;
                    
                    % Painel "Informações extraídas de base de dados da Agência"
                    if isempty(app.nodesTable)
                        app.nodesTable = Fcn_nodeTable('FISF_editedData.csv');
                    end
                    
                    auxIndex = find(contains(app.nodesTable.Node, app.specData(selectedNodes.NodeData).Node));
                    if ~isempty(auxIndex)
                        app.Info_MetaData3.Value = app.nodesTable.Unit(auxIndex(1));
                        app.Info_MetaData4.Value = app.nodesTable.City(auxIndex(1));
                        app.Info_MetaData5.Value = app.nodesTable.Latitude(auxIndex(1));
                        app.Info_MetaData6.Value = app.nodesTable.Longitude(auxIndex(1));
                        
                        app.Info_Grid3.RowHeight = {22,128,22,132};
                    else
                        app.Info_Grid3.RowHeight = {0,0,22,132};
                    end
                    
                    
                    % Painel "Informações a editar"
                    % GPS
                    if ~app.specData(selectedNodes.NodeData).gpsFlag
                        app.Info_Latitude.Enable  = 0;
                        app.Info_Longitude.Enable = 0;
                        
                        auxStr = strsplit(app.specData(selectedNodes.NodeData).gpsString);
                        
                        app.Info_Latitude.Value  = str2double(auxStr{1});
                        app.Info_Longitude.Value = str2double(auxStr{2});
                    else
                        auxFlag = 1;
                        
                        app.Info_Latitude.Enable  = 1;
                        app.Info_Longitude.Enable = 1;
                        
                        if ~isempty(auxIndex)
                            app.Info_Latitude.Value  = app.Info_MetaData5.Value;
                            app.Info_Longitude.Value = app.Info_MetaData6.Value;
                        else
                            app.Info_Latitude.Value  = 0;
                            app.Info_Longitude.Value = 0;
                        end
                    end
                    
                    % Fluxo de ocupação
                    if isempty(app.specData(selectedNodes.NodeData).occRelated)
                        app.Info_OCC.Enable = 0;
                        app.Info_OCC.Items  = {'Ocupação a ser aferida'};
                        
                    else
                        auxFlag = 1;
                        
                        app.Info_OCC.Enable = 1;
                        
                        app.Info_OCC.Items{1} = 'Ocupação a ser aferida';
                        for ii = 1:length(app.specData(selectedNodes.NodeData).occRelated)
                            app.Info_OCC.Items{ii+1} = sprintf('Threshold: %d %s (ThreadID %d)', app.specData(app.specData(selectedNodes.NodeData).occRelated(ii)).MetaData.Threshold, app.specData(selectedNodes.NodeData).MetaData.metaString{1}, app.specData(app.specData(selectedNodes.NodeData).occRelated(ii)).ThreadID);
                        end
                        
                        if isempty(app.specData(selectedNodes.NodeData).occFlag)
                            app.Info_OCC.Value = app.Info_OCC.Items{1};
                        else
                            auxIndex = find(app.specData(selectedNodes.NodeData).occRelated == app.specData(selectedNodes.NodeData).occFlag, 1);
                            app.Info_OCC.Value = app.Info_OCC.Items{auxIndex+1};
                        end
                    end
                    
                    % Botão
                    if auxFlag
                        app.Info_CheckBox.Enable = 1;
                        app.Info_Button.Enable   = 1;
                    else
                        app.Info_CheckBox.Enable = 0;
                        app.Info_Button.Enable   = 0;
                    end
                
                case 'off'
                    app.Info_Label1.Enable = 0;
                    app.Info_Label2.Enable = 0;
                    app.Info_Button.Enable = 0;
                    
                    set(app.Info_Grid2.Children,   'Enable', 0)
                    set(app.Info_Grid2_2.Children, 'Enable', 0)
            
            end
            
        end
        
        
        function Layout_Panel33(app)
            
            app.selectedChannel.Limits = [app.specData(app.Tree2.SelectedNodes.NodeData).MetaData.F0/1e+6, ...
                                          app.specData(app.Tree2.SelectedNodes.NodeData).MetaData.F1/1e+6];
            
        end
                
        
        function Fcn_Traces(app)

            set(app.RadioGroup2.Children, 'Enable', 0)
            
            % median and mean
            if app.Playback_CheckBox1.Value
                ind1 = find(strcmp(app.Playback_TraceType.Items, 'Average'), 1);
                if isempty(ind1)
                    app.Playback_TraceType.Items{end+1} = 'Average';
                end
                
                if app.Playback_Radio1.Value
                    app.Playback_Radio4.Enable = 1;
                    app.Playback_Radio5.Enable = 1;
                else
                    app.Playback_Radio5.Value  = 1;
                    app.Playback_Radio5.Enable = 1;
                    
                    app.FatordeintegraoLabel.Enable = 1;
                    app.Playback_Value1.Enable = 1;
                end
                
                switch app.RadioGroup2.SelectedObject.Text
                    case 'Mediana'
                        app.traceInfo.Mode(2) = 2;
                    case 'Média (vídeo)'
                        app.traceInfo.Mode(2) = 3;
                end
            else
                ind1 = find(strcmp(app.Playback_TraceType.Items, 'Average'), 1);
                if ~isempty(ind1)
                    app.Playback_TraceType.Items(ind1) = [];
                end
                
                app.traceInfo.Mode(2) = 0;
            end
            
            % MinHold
            if app.Playback_CheckBox2.Value
                ind1 = find(strcmp(app.Playback_TraceType.Items, 'MinHold'), 1);
                if isempty(ind1)
                    app.Playback_TraceType.Items{end+1} = 'MinHold';
                end
                
                app.traceInfo.Mode(1) = 1;
            else
                ind1 = find(strcmp(app.Playback_TraceType.Items, 'MinHold'), 1);
                if ~isempty(ind1)
                    app.Playback_TraceType.Items(ind1) = [];
                end
                
                app.traceInfo.Mode(1) = 0;
            end

            % MaxHold
            if app.Playback_CheckBox3.Value
                ind1 = find(strcmp(app.Playback_TraceType.Items, 'MaxHold'), 1);
                if isempty(ind1)
                    app.Playback_TraceType.Items{end+1} = 'MaxHold';
                end
                
                app.traceInfo.Mode(3) = 4;
            else
                ind1 = find(strcmp(app.Playback_TraceType.Items, 'MaxHold'), 1);
                if ~isempty(ind1)
                    app.Playback_TraceType.Items(ind1) = [];
                end
                
                app.traceInfo.Mode(3) = 0;
            end
            
        end
        
        
        function Layout_ReportPanel(app)
            
            if (numel(app.Tree3.SelectedNodes) == 1) && isnumeric(app.Tree3.SelectedNodes.NodeData)
                app.Report_Value6_2.Enable = 1;
                app.ClassificaodafaixaLabel.Enable = 1;
            else
                app.Report_Value6_2.Enable = 0;
                app.ClassificaodafaixaLabel.Enable = 0;
            end
            
        end
        
        
        function Layout_Plot(app)
            
            if app.Playback_CheckBox4.Value && app.Playback_CheckBox5.Value
                app.traceInfo.plotLayout = 3;
            elseif app.Playback_CheckBox5.Value
                app.traceInfo.plotLayout = 2;
            elseif app.Playback_CheckBox4.Value
                app.traceInfo.plotLayout = 1;
            else
                app.traceInfo.plotLayout = 0;
            end
            
            if app.Playback_CheckBox4.Value
                Playback_Value2ValueChanged(app)
                app.ThresholdDropDownLabel.Enable = 1;
                app.Playback_Value2.Enable = 1;
            else
                set(app.Grid4.Children, 'Enable', 0);
                app.ThresholdDropDownLabel_2.Enable = 0;
                app.Playback_Value2.Enable = 0;
                app.ValorLabel_2.Enable = 0;
                app.Playback_Value3.Enable = 0;
                app.Playback_Unit1.Enable = 0;
                app.OffSetLabel_2.Enable = 0;
                app.Playback_Value6.Enable = 0;
                app.FatordeintegraoLabel.Enable = 0;
                app.Playback_Value4.Enable = 0;
                app.NveldeconfianaLabel.Enable = 0;
                app.Playback_Value8.Enable = 0;
                app.Playback_Label001.Enable = 0;
            end
            
        end
        
        
        function occLineROI(app, src, event)
            
            switch(event.EventName)
                case{'MovingROI'}
                    app.Playback_Value3.Value    = event.CurrentPosition(1,2);
                    app.label1.Position(2) = event.CurrentPosition(1,2);
                    
                case{'ROIMoved'}
                    app.axes2.Children(1).YData = zeros(1, length(app.axes1.Children(end).YData), 'single');
                    app.axes2.Children(2).YData = zeros(1, length(app.axes1.Children(end).YData), 'single');
            end
            
        end
        
        
        function mkrLineROI(app, src, event)
            
            switch(event.EventName)
                case{'MovingROI'}
                    auxValue1 = length(app.Tree4.SelectedNodes);
                    if  auxValue1 == 0
                        auxIndex1 = 1;
                    elseif auxValue1 == 1
                        auxIndex1 = app.Tree4.SelectedNodes.NodeData(1);
                    end
                    
                    auxValue2 = app.line3.Position(1);                      % New frequency.
                    app.Marker_CF.Value = round(auxValue2/1e+6, 3);
        
                    auxIndex2 = round((auxValue2 - app.bCoef)/app.aCoef);   % New frequency index.
                    auxValue3 = app.axes1.Children(end).YData(auxIndex2);   % Level related to new frequency.
                    
                    app.axes1.Children(end).MarkerIndices(auxIndex1) = auxIndex2;
                    app.axes1.Children(end-auxIndex1).Position(1:2)  = [auxValue2, auxValue3];
                    
                    set(app.Tree4.Children(auxIndex1), 'Text', sprintf("M%d: %.3f MHz @ %.1f %s", auxIndex1, app.Marker_CF.Value, auxValue3, app.Unit), ...
                                                        'NodeData', [auxIndex1, app.traceInfo.SelectedNode, auxIndex2]);
                    
                case{'ROIMoved'}
                    auxValue1 = length(app.Tree4.SelectedNodes);
                    if  auxValue1 == 0
                        auxIndex1 = 1;
                    elseif auxValue1 == 1
                        auxIndex1 = app.Tree4.SelectedNodes.NodeData(1);
                    end
                    auxValue2 = app.line3.Position(1);
                    auxIndex2 = round((auxValue2 - app.bCoef)/app.aCoef);
                    
                    app.locs(auxIndex1) = auxIndex2;
                    
            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                
            %% Visibilidade dos elementos principais do app.
            app.Logo.Visible = 1;
            app.Warning_Image.Visible = 0;
            
            app.Grid3_Info.Visible     = 0;
            app.Grid3_Playback.Visible = 0;
            app.Grid3_Report.Visible   = 0;
            
            app.Grid1.ColumnWidth = {300,10,50,'1x',10,325,10,0,35};
                
            %% Confirmar se o app foi aberto a partir do projeto (garantindo
            %% o mapeamento das pastas BinFiles, DataBase e Icons e a identificação
            %% do RootFolder do projeto "appAnalise.prj".
            proj = matlab.project.rootProject;            
            if isempty(proj)
                auxStr = 'O appAnalise somente poderá ser executado se o projeto "appAnalise.prj" estiver aberto.';
                uialert(app.MainApp, auxStr, 'Alerta!', 'Icon', 'error', 'CloseFcn', @(~,~)MainAppCloseRequest(app));
                
            else
                app.RootFolder = char(proj.RootFolder);
                app.UserPath.Items = {app.RootFolder};
                
                %% Busca pela planilha com a base de dados.                
                fileList = dir([app.RootFolder '\DataBase']);
                filename = extractBetween([fileList.name], 'AnatelDB', 'xlsx', 'Boundaries', 'inclusive');
                if isempty(filename)
                    app.dbFlag = 0;
                    
                    auxStr = sprintf(['A planilha que consolida extração de bases de dados da Anatel (Mosaico, SRD e STEL) ' ...
                                     'não foi encontrada na pasta "%s".\n\nFormato do nome do arquivo:\n'        ...
                                     '"AnatelDB_YYYYMMDD_THHMMSS.xlsx".'], [app.RootFolder '\DataBase']);
                    uialert(app.MainApp, auxStr, 'Alerta!', 'Icon', 'warning')
                    
                else
                    app.dbFlag = 1;
                end
                                
                
                %% Posição da janela principal do app.
                mainMonitor = get(0, 'MonitorPositions');
                mainMonitor = mainMonitor(mainMonitor(:,1) == 1,:);
                
                if mainMonitor(3) >= 1016
                    x0 = 1016;
                else
                    x0 = MainMonitor(3);
                end
                
                if mainMonitor(4) > 768
                    y0 = (mainMonitor(4) - 726);
                else
                    y0 = 42;
                end
                app.MainApp.Position = [1, y0, x0, 696];
                
                
                % Criação da figura e dos eixos.
                
                % Inicialmente apaga toda e qualquer figura que porventura
                % esteja aberta no Matlab.
                close(findobj('Type', 'Figure', '-not', 'Name', 'appAnálise'))
                
                if mainMonitor(3) > x0 + 852
                    x1 = x0+6;
                else
                    x1 = (mainMonitor(3)-852);                
                end
                
                app.fig   = figure('Name', 'appAnálise: Playback', 'NumberTitle', 'off',   ...
                                   'Position', [x1, app.MainApp.Position(2), 852, 622],    ...
                                   'MenuBar', 'none', 'ToolBar', 'none', 'Visible', 'off', ...
                                   'Color', [0.94,0.94,0.94],                              ...
                                   'CloseRequestFcn', "set(gcf, 'Visible', 'off')");
    
                app.axes1 = axes(app.fig, 'Box', 'on', 'Color', [0,0,0], 'FontName', 'Calibri', 'FontSize', 9, 'FontSmoothing', 'on', ...
                                          'XColor', [0,0,0], 'XGrid', 'on', 'XMinorGrid', 'on',  ...
                                          'YColor', [0,0,0], 'YGrid', 'on', 'YMinorGrid', 'on',  ...
                                          'XTickLabel', {}, ...
                                          'GridAlpha'     , .25, 'GridColor'     , [0.94,0.94,0.94], ...
                                          'MinorGridAlpha', .2 , 'MinorGridColor', [0.94,0.94,0.94], ...
                                          'Unit', 'normalized', 'InnerPosition', [.08 .1 .85 .82],   ...
                                          'TitleHorizontalAlignment', 'left', 'Toolbar', []);
                
                set(app.axes1.Title,    'FontSize', 10, 'FontWeight',   'bold', 'Color', [0,0,0], 'FontSmoothing', 'on');
                set(app.axes1.Subtitle, 'FontSize',  9, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on');
                set(app.axes1.XLabel,   'FontSize',  9, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on');
                set(app.axes1.YLabel,   'FontSize',  9, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on');
                
                
                app.axes2 = axes(app.fig, 'Box', 'on', 'Color', [0,0,0], 'FontName', 'Calibri', 'FontSize', 9, 'FontSmoothing', 'on', ...
                                          'XColor', [0,0,0], 'XGrid', 'on', 'XMinorGrid', 'on',  ...
                                          'YColor', [0,0,0], 'YGrid', 'on', 'YMinorGrid', 'on',  ...
                                          'XTickLabel', {}, ...
                                          'GridAlpha', .25, 'GridColor', [0.94,0.94,0.94],  ...
                                          'MinorGridAlpha', .2 , 'MinorGridColor', [0.94,0.94,0.94], ...
                                          'Unit', 'normalized', 'InnerPosition', [0 0 0 0], ...
                                          'Layer', 'top', 'Toolbar', []);
                
                set(app.axes2.XLabel,   'FontSize',  9, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on');
                set(app.axes2.YLabel,   'FontSize',  9, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on');
                
                app.axes3 = axes(app.fig, 'Box', 'on', 'Color', 'none', 'FontName', 'Calibri', 'FontSize', 9, 'FontSmoothing', 'on', ...
                                          'XColor', [0,0,0], 'YColor', [0,0,0], ...
                                          'XTickLabel', {}, ...
                                          'Unit', 'normalized', 'InnerPosition', [0 0 0 0], 'Toolbar', []);
                
                set(app.axes3.XLabel,   'FontSize',  9, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on');
                set(app.axes3.YLabel,   'FontSize',  9, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on');
                
                app.axes1.XAxis.Exponent = 6;
                app.axes2.XAxis.Exponent = 6;
                app.axes3.XAxis.Exponent = 6;
                
                app.cBar1 = colorbar('Location', 'manual', 'Units', 'normalized', 'Position', [0.935, 0.1, 0.01, 0.1], ...
                                     'AxisLocation', 'out', 'FontSize', 9, 'Color', [0,0,0]);
                
                app.line1 = annotation(app.fig, 'line', 'X', [.08 .93], 'Y', [.1 .1], 'Position', [.08 .1 0 0], 'LineWidth', .5,'Color', 'red');
                
                hold(app.axes1, 'on')
                hold(app.axes2, 'on')
                hold(app.axes3, 'on')
                
                disableDefaultInteractivity(app.axes1)
                disableDefaultInteractivity(app.axes2)
                disableDefaultInteractivity(app.axes3)
            end
            
        end

        % Button pushed function: Button_11
        function Button_OpenFile(app, event)
            
            [Files, Path] = uigetfile({'*.bin', 'CRFS Bin (*.bin)'; '*.sm1809', 'UIT SM1809 (*.sm1809)';  ...
                                       '*.*', 'Todos os arquivos'}, 'Selecione um ou mais arquivos', 'MultiSelect', 'on');
            
            if ~isnumeric(Files)
                if ~iscell(Files)
                    Files = {Files};
                end
                
                repeteadFiles = {};
                if isempty(app.SelectedFiles)
                    app.SelectedFiles = Files;
                    app.FilesPath     = repmat({Path}, 1, length(Files));
                else
                    for ii = length(Files):-1:1
                        if any(cellfun(@(s) ~isempty(strfind(Files{ii}, s)), app.SelectedFiles))
                            repeteadFiles = [repeteadFiles; Files{ii}];
                            Files(ii) = [];
                        end
                    end
                                        
                    app.SelectedFiles(end+1:end+length(Files)) = Files;
                    app.FilesPath(end+1:end+length(Files))     = repmat({Path}, 1, length(Files));                    
                end
                
                try
                    Fcn_MetaDataReader(app)
                catch ME
                    uialert(app.MainApp, ME.message, 'Alerta!', 'Icon', 'error');
                    
                    app.SelectedFiles = [];
                    app.FilesPath     = [];
                    delete(app.Tree1.Children)
                    
                    app.metaData = struct('File', [], 'Data', [], 'Samples', []);
                    
                    Layout_Panel11(app)
                    Layout_Panel12(app)
                    
                    return
                end
                
                % Alert window
                if ~isempty(repeteadFiles)
                    auxStr = 'Os metadados do(s) arquivo(s) indicado(s) a seguir já tinham sido lidos.';
                    for ii = 1:length(repeteadFiles)
                        auxStr = [auxStr sprintf('\n- %s', repeteadFiles{ii})];                       
                    end
                    uialert(app.MainApp, auxStr, 'Alerta!', 'Icon', 'warning');
                end
                
                Fcn_Tree1(app)
            end
            
            Layout_Panel11(app)
            Layout_Panel12(app)
            
        end

        % Image clicked function: Button_12
        function Button_Delete(app, event)
            
            ind = [];
            for ii = 1:numel(app.Tree1.SelectedNodes)
                ind = [ind, app.Tree1.SelectedNodes(ii).NodeData];
            end
            
            app.SelectedFiles(ind) = [];
            app.FilesPath(ind)     = [];
            app.metaData(ind)      = [];
            
            Fcn_Tree1(app)
            
            Layout_Panel11(app)
            Layout_Panel12(app)
                        
        end

        % Button pushed function: LeituraespectroButton
        function SpecDataReadCallback(app, event)
            
            app.dialogWin = uiprogressdlg(app.MainApp, 'Indeterminate', 'on');
            
            if ~isempty(app.filtData)
                app.filtData = [];
                delete(app.Tree3.Children);
            end
            
            app.traceInfo.SelectedNode = 1;
            app.timeIndex = 0;
            
            Fcn_SpecDataReader(app)
            
            Fcn_Tree2(app)
            Layout_Panel2(app)
            
            if ~isempty(app.Tree4.Children)
                app.locs = [];
                delete(app.Tree4.Children)
            end
                        
            delete(app.dialogWin)
            
        end

        % Callback function
        function SelectThreadID(app, event)
            
            value = str2double(app.metaData_13.Value);
            selectedNodes = app.Tree1.SelectedNodes;
            
            Index = find([app.metaData(selectedNodes.NodeData).Data.ThreadID] == value);
            
            app.metaData_14.Value = sprintf('%.3f - %.3f MHz', app.metaData(selectedNodes.NodeData).Data(Index).MetaData.F0.*1e-6, ...
                                                               app.metaData(selectedNodes.NodeData).Data(Index).MetaData.F1.*1e-6);
            app.metaData_15.Value = app.metaData(selectedNodes.NodeData).Data(Index).Description;
            
        end

        % Selection changed function: Tree1
        function Tree1_SelectionChanged(app, event)
            
            selectedNodes = app.Tree1.SelectedNodes;
            
            value1 = numel(selectedNodes);
            if value1 == 1
                string1 = {sprintf('Sensor: %s\nFormato: %s',  app.metaData(selectedNodes.NodeData).Data(1).Node, ...
                                                               app.metaData(selectedNodes.NodeData).Data(1).FileVersion)};
                
                if isempty(app.metaData(selectedNodes.NodeData).Data(1).gpsString)
                    string1{1} = sprintf('%s\nGPS: %s\n\n', string1{1}, 'não registrado');
                else
                    string1{1} = sprintf('%s\nGPS: %s (%s)\n\n', string1{1}, ...
                                         app.metaData(selectedNodes.NodeData).Data(1).gpsString, ...
                                         app.metaData(selectedNodes.NodeData).Data(1).gps.Location);
                end     
                
                for ii = 1:numel(app.metaData(selectedNodes.NodeData).Data)
                    string1{end+1} = sprintf('ThreadID %d: %.3f - %.3f MHz\n- Descrição: "%s"\n- %d amostras\n\n',                                  ...
                                      app.metaData(selectedNodes.NodeData).Data(ii).ThreadID,          ...
                                      1e-6*app.metaData(selectedNodes.NodeData).Data(ii).MetaData.F0,  ...
                                      1e-6*app.metaData(selectedNodes.NodeData).Data(ii).MetaData.F1,  ...
                                      app.metaData(selectedNodes.NodeData).Data(ii).Description,       ...
                                      app.metaData(selectedNodes.NodeData).Data(ii).Samples);
                end
                app.metaData_15.Value  = string1;
            end

            Layout_Panel12(app)
            
        end

        % Selection changed function: Tree2
        function Tree2_SelectionChanged(app, event)
            
            selectedNodes = app.Tree2.SelectedNodes;
            
            % Caixa de texto com metadados.
            value1 = numel(selectedNodes);
            if value1 == 1 && isnumeric(selectedNodes.NodeData)
                app.Label_31.Enable    = 1;
                app.metaData_16.Enable = 1;
                
                string1 = {sprintf('Formato: %s\nScript: "%s"\nDescrição: "%s"',  ...
                           app.specData(selectedNodes.NodeData).FileVersion,      ...
                           app.specData(selectedNodes.NodeData).ScriptName,       ...
                           app.specData(selectedNodes.NodeData).Description); ''; ...
                           sprintf('Pontos por traço: %d\nUnidade: %s\nModo/Detector: %s', ...
                           app.specData(selectedNodes.NodeData).MetaData.NDATA,       ...
                           app.specData(selectedNodes.NodeData).MetaData.metaString{1},  ...
                           app.specData(selectedNodes.NodeData).MetaData.metaString{3})};
                
                if ~isempty(app.specData(selectedNodes.NodeData).MetaData.metaString{4})
                    string1{3} = sprintf('%s/%s', string1{3}, app.specData(selectedNodes.NodeData).MetaData.metaString{4});
                end
                
                if ~isempty(app.specData(selectedNodes.NodeData).MetaData.metaString{2})
                    string1{3} = sprintf('%s\nResolução: %s', string1{3}, app.specData(selectedNodes.NodeData).MetaData.metaString{2});
                end
                
                string1{4} = sprintf('Antena: %s', app.specData(selectedNodes.NodeData).MetaData.metaString{5});
                
                app.metaData_16.Value  = string1;
                
            else
                app.Label_31.Enable    = 0;
                app.metaData_16.Enable = 0;
                app.metaData_16.Value  = '';
            end
            
            
            % Especificidades de cada funcionalidade.
            if app.Button_Report.Value 
                app.Tree3.SelectedNodes = [];
                Layout_ReportPanel(app)
                
                app.Report_addButton.Enable    = 1;
                app.Report_deleteButton.Enable = 0;
                
            elseif app.Button_Playback.Value
                Layout_Playback_Aux(app)
                
                if app.plotFlag
                    app.Button_45.Enable = 1;
                    if isnumeric(app.Tree2.SelectedNodes.NodeData)
                        app.axesFlag = 1;
                    end
                    
                else
                    if isnumeric(app.Tree2.SelectedNodes.NodeData)                        
                        app.Playback_Play.Enable = 1;
                    else
                        app.Playback_Play.Enable = 0;
                    end
                end
                
            elseif app.Button_Info.Value
                if isnumeric(app.Tree2.SelectedNodes.NodeData)
                    Layout_Info_Aux(app, 'on')
                else
                    app.Tree2.SelectedNodes  = [];
                    Layout_Info_Aux(app, 'off')
                end
                
            end
            
        end

        % Selection changed function: Tree3
        function Tree3_SelectionChanged(app, event)
            
            app.Tree2.SelectedNodes = [];
            
            app.Report_addButton.Enable    = 0;
            app.Report_deleteButton.Enable = 1;
            
            if (numel(app.Tree3.SelectedNodes) == 1) && isnumeric(app.Tree3.SelectedNodes.NodeData)
                
                % Critério 1:
                if contains(app.specData(app.Tree3.SelectedNodes.NodeData).reportInfo.Band, 'FM')
                    app.Report_Value6_2.Value = 'Radiodifusão (FM)';
                elseif contains(app.specData(app.Tree3.SelectedNodes.NodeData).reportInfo.Band, 'TV')
                    app.Report_Value6_2.Value = 'Radiodifusão (TV)';
                elseif contains(app.specData(app.Tree3.SelectedNodes.NodeData).reportInfo.Band, 'Aircraft')
                    app.Report_Value6_2.Value = 'Radiocomunicação';
                else
                    app.Report_Value6_2.Value = 'Genérico';
                end
                
                app.Report_Value9.Value  = app.specData(app.Tree3.SelectedNodes.NodeData).reportInfo.statsFcn;
                app.Report_Value11.Value = app.specData(app.Tree3.SelectedNodes.NodeData).reportInfo.minBW / 1000;
                app.Report_Value10.Value = app.specData(app.Tree3.SelectedNodes.NodeData).reportInfo.minProeminence_C1;
                app.Report_Value12.Value = app.specData(app.Tree3.SelectedNodes.NodeData).reportInfo.minDistance / 1000;
                
                % Critério 2:
                app.Report_Value13.Value = app.specData(app.Tree3.SelectedNodes.NodeData).reportInfo.minProeminence_C2;
                app.Report_Value16.Value = app.specData(app.Tree3.SelectedNodes.NodeData).reportInfo.minOCC;
                
                % Habilita ou desabilita
                app.EmrelaocurvadeMaxHoldLabel.Enable = 1;
                set(app.GridLayout23.Children, 'Enable', 1);
                app.EmrelaocurvadetendnciacentralLabel.Enable = 1;
                set(app.GridLayout22.Children, 'Enable', 1)
                app.Report_Value11.Enable = 1;
                app.Report_Unit11.Enable  = 1;
                app.Report_Value12.Enable = 1;
                app.Report_Unit12.Enable  = 1;
                app.DistnciamnimaentrepicosLabel_2.Enable = 1;
                app.LarguramnimaocupadaLabel_2.Enable = 1;
                app.Label_32.Enable = 1;
                app.ClassificaodafaixaLabel.Enable = 1;
                app.Report_Value6_2.Enable = 1;
                
                if isempty(app.specData(app.Tree3.SelectedNodes.NodeData).occFlag)
                    app.Playback_Value2_2.Enable = 1;
                    app.ThresholdDropDownLabel_2.Enable = 1;
                    
                    app.TempodeintegraoLabel.Enable = 1;
                    app.Report_Value4.Enable = 1;
                                        
                    Playback_Value2_2ValueChanged(app)
                end
                
            else
                app.EmrelaocurvadeMaxHoldLabel.Enable = 0;
                set(app.GridLayout23.Children, 'Enable', 0);
                app.EmrelaocurvadetendnciacentralLabel.Enable = 0;
                set(app.GridLayout22.Children, 'Enable', 0)
                app.Report_Value11.Enable = 0;
                app.Report_Unit11.Enable  = 0;
                app.Report_Value12.Enable = 0;
                app.Report_Unit12.Enable  = 0;
                app.DistnciamnimaentrepicosLabel_2.Enable = 0;
                app.LarguramnimaocupadaLabel_2.Enable = 0;
                app.Label_32.Enable = 0;
                app.ClassificaodafaixaLabel.Enable = 0;
                app.Report_Value6_2.Enable = 0;
                
                app.Playback_Unit1_2.Enable = 0;
                app.Playback_Value3_2.Enable = 0;
                app.ValorLabel_2.Enable = 0;
                
                set(app.Report_Grid5_2.Children, 'Enable', 0)
                app.ParmetrosrelacionadosestimativadopisoderudoLabel.Enable = 0;
                
                app.Playback_Unit1_3.Enable = 0;
                app.Playback_Value8_2.Enable = 0;
                
                app.NveldeconfianaLabel_2.Enable = 0;
                
                app.Report_Value5.Enable = 0;
                app.OffSetLabel_2.Enable = 0;
                
                app.Playback_Value2_2.Enable = 0;
                app.ThresholdDropDownLabel_2.Enable = 0;
                
                app.TempodeintegraoLabel.Enable = 0;
                app.Report_Value4.Enable = 0;
            end
            
            Layout_ReportPanel(app)
            
            % PENDENTE PENDENTE!
            
            
        end

        % Selection changed function: Tree4
        function Tree4_SelectionChanged(app, event)
            
            auxValue = length(app.Tree4.SelectedNodes);
            if auxValue == 0
                app.FrequnciacentraldomarcadorLabel.Enable = 0;
                app.Marker_CF.Enable     = 0;
                app.Marker_CFUnit.Enable = 0;
                
                app.Button_46.Enable = 0;
                
            elseif auxValue == 1
                app.Marker_CF.Value      = str2double(extractBetween(app.Tree4.SelectedNodes.Text, ": ", " MHz"));
                app.FrequnciacentraldomarcadorLabel.Enable = 1;
                app.Marker_CF.Enable     = 1;
                app.Marker_CFUnit.Enable = 1;
                
                app.Button_46.Enable = 1;
                
                app.line3.Position(:, 1) = repmat(app.x(app.Tree4.SelectedNodes.NodeData(3)), 2, 1);
            
            else
                app.FrequnciacentraldomarcadorLabel.Enable = 0;
                app.Marker_CF.Enable     = 0;
                app.Marker_CFUnit.Enable = 0;
                
                app.Button_46.Enable = 1;
                
            end
            
        end

        % Button pushed function: Button_34
        function Button_34Pushed(app, event)
            
            auxStr = sprintf(['appAnálise v.1.0\n2020-2021\n\nAplicação de pós-processamento de dados gerados em monitorações ' ...
                              'desenvolvido no âmbito dos projetos 3A e RFLOOK - Processos nºs 53554.000939/2020-26 e ' ...
                              '53500.063493/2020-76.']);            
            uialert(app.MainApp, auxStr, 'Sobre', 'Icon', 'info');
            
        end

        % Value changed function: Button_Report
        function Button_ReportValueChanged(app, event)
            
            if app.Button_Report.Value
                app.Button_Playback.Value   = 0;
                app.Button_Info.Value       = 0;   
            end
            
            Layout_Panel3(app)
            
        end

        % Value changed function: Button_Playback
        function Button_PlaybackValueChanged(app, event)
            
            if app.Button_Playback.Value
                app.Button_Report.Value     = 0;
                app.Button_Info.Value       = 0;       
            end
            
            Layout_Panel3(app)
            
        end

        % Value changed function: Button_Info
        function Button_InfoPushed(app, event)
            
            if app.Button_Info.Value
                app.Button_Report.Value     = 0;
                app.Button_Playback.Value   = 0;
            end
            
            Layout_Panel3(app)
            
        end

        % Image clicked function: Report_addButton
        function Report_addButtonPushed(app, event)
            
            auxValue = length(app.Tree2.SelectedNodes);
            
            auxIndex = [];
            for ii = 1:auxValue
                selectedNodes = app.Tree2.SelectedNodes(ii);
                
                if isnumeric(selectedNodes.NodeData)
                    auxIndex = [auxIndex, selectedNodes.NodeData];
                else
                    for jj = 1:length(app.specData)
                        if strcmp(app.specData(jj).Node, selectedNodes.NodeData)
                            auxIndex = [auxIndex, jj];
                        end
                    end
                end
            end
            auxIndex = sort(unique(auxIndex));
                        
            for kk = 1:length(auxIndex)
                if ismember(app.specData(auxIndex(kk)).MetaData.DataType, [62, 65, 69])
                    auxStr = sprintf('É possível a inclusão apenas de fluxo de espectro. O fluxo de ocupação automaticamente será incluso na elaboração do relatório, desde que selecionado no próprio fluxo de espectro.');            
                    uialert(app.MainApp, auxStr, 'Alerta!', 'Icon', 'error');
                    return
                    
                elseif isempty(app.specData(auxIndex(kk)).gpsString)
                    auxStr = sprintf('Necessária a edição de informações de coordenadas geográficas de fluxo(s) de dado(s).');            
                    uialert(app.MainApp, auxStr, 'Alerta!', 'Icon', 'error');
                    return
                    
                end
                
                if isempty(find(app.filtData == auxIndex(kk), 1))
                    app.filtData = sort([app.filtData; auxIndex(kk)]);
                end
            end
            
            Fcn_Tree3(app)
            
            if ~isempty(app.filtData)
                app.Report_recButton.Enable  = 1;
                app.Report_playButton.Enable = 1;
            else
                app.Report_recButton.Enable  = 0;
                app.Report_playButton.Enable = 0;
            end
    
        end

        % Image clicked function: Report_deleteButton
        function Report_deleteButtonPushed(app, event)
            
            auxValue = length(app.Tree3.SelectedNodes);
            
            for ii = 1:auxValue
                selectedNodes = app.Tree3.SelectedNodes(ii);
                
                if isnumeric(selectedNodes.NodeData)
                    app.filtData(find(app.filtData == selectedNodes.NodeData, 1)) = [];
                else
                    auxIndex1 = [];
                    for ii = 1:length(app.specData)
                        if strcmp(app.specData(ii).Node, selectedNodes.NodeData)
                            auxIndex1 = [auxIndex1, ii];
                        end
                    end
                    
                    for jj = 1:length(auxIndex1)
                        auxIndex2 = find(app.filtData == auxIndex1(jj), 1);
                        
                        if ~isempty(auxIndex2)
                            app.filtData(auxIndex2) = [];
                        end
                    end
                end
            end
            
            Fcn_Tree3(app)
            
            if isempty(app.filtData)
                app.Report_deleteButton.Enable = 0;
                app.Report_recButton.Enable    = 0;
                app.Report_playButton.Enable   = 0;
            end
            
        end

        % Button pushed function: Playback_Reload
        function Playback_ReloadPushed(app, event)
            
            if app.reloadFlag
                app.Playback_Reload.Icon = 'notreloadIcon.png';
                app.reloadFlag = 0;
            else
                app.Playback_Reload.Icon = 'reloadIcon.png';
                app.reloadFlag = 1;
            end
            
        end

        % Button pushed function: Report_playButton
        function Report_playButtonPushed(app, event)
            
            %% Nova verificação da existência da planilha com a base de dados...
            if ~app.dbFlag
                fileList = dir([char(app.RootFolder) '\DataBase']);
                filename = extractBetween([fileList.name], 'AnatelDB', 'xlsx', 'Boundaries', 'inclusive');
                
                if isempty(filename)
                    auxStr = sprintf(['A planilha que consolida extração de bases de dados da Anatel (Mosaico, SRD e STEL) ' ...
                                     'não foi encontrada na pasta "%s".\n\nFormato do nome do arquivo:\n'        ...
                                     '"AnatelDB_YYYYMMDD_THHMMSS.xlsx".'], [app.RootFolder '\DataBase']);
                    uialert(app.MainApp, auxStr, 'Alerta!', 'Icon', 'warning')
                else
                    app.dbFlag = 1;
                end
            end
            
            
            % Geração de relatório.
            if app.dbFlag
                auxDate1 = app.Report_DatePicker1.Value;
                auxDate1.Format = "dd/MM/yyyy HH:mm:ss";
                auxDate1 = auxDate1 + hours(app.Report_Spinner1.Value) + minutes(app.Report_Spinner2.Value);
                
                auxDate2 = app.Report_DatePicker2.Value;
                auxDate2.Format = "dd/MM/yyyy HH:mm:ss";
                auxDate2 = auxDate2 + hours(app.Report_Spinner3.Value) + minutes(app.Report_Spinner4.Value);
                
                if auxDate1 < auxDate2
                    d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on');
                    d.Message = sprintf(['Em andamento a elaboração do relatório de monitoração, o que inclui diversas manipulações ' ...
                                         'nos dados, como, por exemplo, a busca de emissões, a comparação com a base de dados da Agência ' ...
                                         'e a geração de plots.']);
                    
                    reportInfo = Fcn_ReportGenerator_Aux(app, auxDate1, auxDate2);
                    
                    filteredRows = sort([app.filtData; unique([app.specData(app.filtData).occFlag])']);
                    
                    auxValue1 = length([app.specData(app.filtData).occFlag]);
                    if auxValue1
                        auxValue2 = zeros(auxValue1, 2);
                        
                        jj = 0;
                        for ii = 1:length(filteredRows)
                            if ~isempty(app.specData(filteredRows(ii)).occFlag)
                                jj=jj+1;
                                auxValue2(jj,:) = [filteredRows(ii), app.specData(filteredRows(ii)).occFlag];
                                app.specData(filteredRows(ii)).occFlag = find(filteredRows == auxValue2(jj,2));
                            end
                        end
                    end
                    
                    [app.ReportFilename, Message] = Fcn_ReportGenerator(reportInfo, app.specData(filteredRows), [auxDate1, auxDate2], app.RootFolder);
                    
                    if isempty(Message)
                        if auxValue1
                            for jj = 1:size(auxValue2, 1)
                                app.specData(auxValue2(jj,1)).occFlag = auxValue2(jj,2);
                            end
                        end
                    else
                        uialert(app.MainApp, Message, 'Alerta!', 'Icon', 'error')
                    end
                    
                    if ~isempty(app.ReportFilename)
                        app.FiscalizaCount.Value = 0;
                        if strcmp(app.Report_Value1_3.Value, 'Preliminar')
                            app.Report_FiscalizaButton.Enable = 0;
                        else
                            app.Report_FiscalizaButton.Enable = 1;
                        end
                    end
                else
                    auxStr = 'Os valores inseridos para aplicação do filtro de TimeStamp devem ser ajustados!';
                    uialert(app.MainApp, auxStr, 'Alerta!', 'Icon', 'error')
                end
            end
            
        end

        % Button pushed function: Button_32
        function globalVariables_Callback(app, event)
            
            global metaData1
            global specData1
            
            metaData1   = app.metaData;
            specData1   = app.specData;
            
            auxStr = sprintf(['Variáveis "metaData1" e "specData1" definidas como globais, sendo possível a sua importação na área de trabalho do Matlab ' ...
                              'por meio dos comandos:\n• global metaData1\n• global specData1']);            
            uialert(app.MainApp, auxStr, 'Alerta!', 'Icon', 'warning');
            
        end

        % Value changed function: Report_CheckBox4
        function Report_CheckBox4ValueChanged(app, event)
            
            if app.Report_CheckBox4.Value
                app.Report_TextArea.Enable = 1;
            else
                if ~app.Report_CheckBox2.Value && ~app.Report_CheckBox3.Value
                    app.Report_CheckBox4.Value = 1;
                else
                    app.Report_TextArea.Enable = 0;
                end
            end
            
        end

        % Button pushed function: Info_Button
        function Info_ButtonPushed(app, event)
            
            app.dialogWin = uiprogressdlg(app.MainApp, 'Indeterminate', 'on', 'Message', 'Em andamento a edição e a organização dos fluxos de dados.');
            
            selectedNodes = app.Tree2.SelectedNodes;
            
            if app.specData(selectedNodes.NodeData).gpsFlag
                app.specData(selectedNodes.NodeData).gpsString = sprintf('%.6f, %.6f', app.Info_Latitude.Value, app.Info_Longitude.Value);            
                app.specData(selectedNodes.NodeData).gpsFlag   = 2;
            end
            
            if app.Info_CheckBox.Value
                for ii = 1:length(app.specData)
                    if ii == selectedNodes.NodeData
                        continue
                    end
                    
                    if strcmp(app.specData(selectedNodes.NodeData).Node, app.specData(ii).Node) && app.specData(ii).gpsFlag
                        app.specData(ii).gpsString = sprintf('%.6f, %.6f', app.Info_Latitude.Value, app.Info_Longitude.Value);            
                        app.specData(ii).gpsFlag   = 2;
                    end
                end
            end
            
            if ~isempty(app.specData(selectedNodes.NodeData).occRelated)
                if strcmp(app.Info_OCC.Items{1}, app.Info_OCC.Value)
                    app.specData(selectedNodes.NodeData).occFlag = [];
                else
                    auxIndex = find(strcmp(app.Info_OCC.Items, app.Info_OCC.Value))-1;
                    app.specData(selectedNodes.NodeData).occFlag = app.specData(selectedNodes.NodeData).occRelated(auxIndex);
                end
            end
            
            Fcn_Tree2(app)
            
            Layout_Panel3(app)
            
            delete(app.dialogWin)
            
        end

        % Callback function
        function Report_CheckBox6ValueChanged(app, event)
            
            if app.Report_CheckBox6.Value
                set(app.GridLayout14.Children, 'Enable', 0)
                app.Report_CheckBox6.Enable = 1;
            else
                set(app.GridLayout14.Children, 'Enable', 1)
            end
            
        end

        % Callback function
        function Report_CheckBox7ValueChanged(app, event)
            
            if app.Report_CheckBox7.Value
                set(app.GridLayout15.Children, 'Enable', 0)
                app.Report_CheckBox7.Enable = 1;
            else
                set(app.GridLayout15.Children, 'Enable', 1)
            end
            
        end

        % Value changed function: Report_CheckBox2
        function Report_CheckBox2ValueChanged(app, event)
            
            value = app.Report_CheckBox2.Value;
            
            if ~value && ~app.Report_CheckBox3.Value && ~app.Report_CheckBox4.Value
                app.Report_CheckBox2.Value = 1;  
            end
            
        end

        % Value changed function: Report_CheckBox3
        function Report_CheckBox3ValueChanged(app, event)

            value = app.Report_CheckBox3.Value;
            
            if ~value && ~app.Report_CheckBox2.Value && ~app.Report_CheckBox4.Value
                app.Report_CheckBox3.Value = 1;  
            end
            
        end

        % Button pushed function: Playback_Play
        function Layout_Playback(app, event)

            % Playback.
            app.Playback_Slider.Enable = 1;
            if ~app.plotFlag
                app.plotFlag = 1;
                
                app.Playback_Play.Text = 'Parar escrita';
                app.Playback_Play.Icon = 'Media_Pause.png';
                
                % Menu principal e painéis 1 e 2
                set(app.GridLayout28.Children, 'Enable', 0);
                app.LeituraespectroButton.Enable = 0;
                app.Report_addButton.Enable      = 0;
                app.Report_deleteButton.Enable   = 0;
                
                app.Button_45.Enable = 1;
                drawnow nocallbacks
                
                Fcn_Plot(app)
            
            else
                app.plotFlag = 0;
                
                app.Playback_Play.Text = 'Iniciar escrita';
                app.Playback_Play.Icon = 'Media_Play.png';
                
                set(app.GridLayout28.Children, 'Enable', 1);
                if ~isempty(app.SelectedFiles)
                    app.LeituraespectroButton.Enable = 1;
                end
                
                if ~isnumeric(app.Tree2.SelectedNodes.NodeData)
                    app.Report_addButton.Enable = 0;
                end
                app.Report_deleteButton.Enable = 0;
                app.Button_45.Enable = 0;
                drawnow nocallbacks
            end
            
        end

        % Close request function: MainApp
        function MainAppCloseRequest(app, event)
            
            if ~isempty(app.fig)
                delete(app.fig)
            end
            delete(app)
            
        end

        % Value changed function: Playback_Menu
        function conePanCheckBoxValueChanged(app, event)

            if app.Playback_Menu.Value
                set(app.fig, 'MenuBar', 'figure')
                pan(app.fig, 'off')
                zoom(app.fig, 'off')
                
                app.Playback_Hand.Value = 0;
                app.Playback_Zoom.Value = 0;
            else
                set(app.fig, 'MenuBar', 'none')
            end
            
        end

        % Value changed function: Playback_CheckBox4
        function CheckBox32_2ValueChanged(app, event)
            
            Layout_Plot(app)
            
            if app.plotFlag
                app.axesFlag = 1;
            end
            
        end

        % Value changing function: Playback_Slider
        function Playback_SliderValueChanging(app, event)
            
            app.timeIndex = round(event.Value/100 * app.specData(app.traceInfo.SelectedNode).Samples);
            
            if app.timeIndex < 1
                app.timeIndex = 1;
            elseif app.timeIndex > app.specData(app.traceInfo.SelectedNode).Samples
                app.timeIndex = app.specData(app.traceInfo.SelectedNode).Samples;
            end
            
            app.axes1.Subtitle.String = datestr(app.specData(app.traceInfo.SelectedNode).Data{1}(app.timeIndex), 'dd/mm/yyyy HH:MM:SS');
            app.axes1.Children(end).YData = app.specData(app.traceInfo.SelectedNode).Data{2}(:, app.timeIndex)';
            
            if app.traceInfo.plotLayout == 2
                app.line1.Position(2) = .1 + (.4/(app.specData(app.traceInfo.SelectedNode).Samples-1))*(app.timeIndex-1);
            elseif app.traceInfo.plotLayout == 3
                app.line1.Position(2) = .1 + (.3/(app.specData(app.traceInfo.SelectedNode).Samples-1))*(app.timeIndex-1);
            end
            drawnow
            
        end

        % Button pushed function: Button_31
        function winLayout_Callback(app, event)
            
            if app.Grid1.ColumnWidth{8} == 0
                app.Grid1.ColumnWidth = {0,0,50,'1x',10,625,10,1,35};
                
            elseif app.Grid1.ColumnWidth{8} == 1
                app.Grid1.ColumnWidth = {0,0,0,0,0,625,10,'1x',35};
            
            else
                app.Grid1.ColumnWidth = {300,10,50,'1x',10,325,10,0,35};
            end
            
        end

        % Selection changed function: RadioGroup1
        function integrationType_Callback(app, event)
            
            app.Playback_CheckBox1.Value = 0;
            app.Playback_CheckBox2.Value = 0;
            app.Playback_CheckBox3.Value = 0;
            
            app.Playback_Radio4.Enable = 0;
            app.Playback_Radio5.Enable = 0;
            
            app.Playback_Value1.Enable = 0;
            
            Fcn_Traces(app)
            
            Report_Value6_2ValueChanged(app)
            
        end

        % Callback function: Playback_CheckBox1, Playback_CheckBox2, 
        % Playback_CheckBox3, Playback_Value1, RadioGroup2
        function axesFlag_Callback(app, event)
            
            Fcn_Traces(app)
            
            Report_Value6_2ValueChanged(app)
            
        end

        % Value changed function: Playback_Value2
        function Playback_Value2ValueChanged(app, event)
            
            app.FatordeintegraoLabel_2.Enable = 1;
            app.Playback_Value4.Enable = 1;
            
            switch app.Playback_Value2.Value
                case 'Linear'
                    app.ValorLabel.Enable = 1;
                    app.Playback_Unit1.Enable = 1;
                    
                    app.Playback_Value3.Enable = 1;
                    
                    app.FunodetendnciacentralLabel_3.Enable = 0;
                    app.OffSetLabel.Enable = 0;
                    app.AmostrasteisLabel_2.Enable = 0;
                    app.NveldeconfianaLabel.Enable = 0;
                    
                    app.Playback_Value5.Enable = 0;
                    app.Playback_Value6.Enable = 0;
                    app.Playback_Value7.Enable = 0;
                    app.Playback_Value8.Enable = 0;
                    
                    app.Playback_Label001.Enable = 0;
                    
                case 'Piso de ruído (Offset)'
                    app.ValorLabel.Enable = 0;
                    app.Playback_Unit1.Enable = 0;
                    
                    app.Playback_Value3.Enable = 0;
                    
                    app.FunodetendnciacentralLabel_3.Enable = 1; 
                    app.OffSetLabel.Enable = 1;
                    app.AmostrasteisLabel_2.Enable = 1;
                    app.NveldeconfianaLabel.Enable = 1;
                    
                    app.Playback_Value5.Enable = 1;
                    app.Playback_Value6.Enable = 1;
                    app.Playback_Value7.Enable = 1;
                    app.Playback_Value8.Enable = 1;
                    
                    app.Playback_Label001.Enable = 1;
            end
            
            Report_Value6_2ValueChanged(app)
            
        end

        % Value changed function: Playback_Value3, Playback_Value4, 
        % Playback_Value5, Playback_Value6, Playback_Value7, 
        % Playback_Value8
        function Report_Value6_2ValueChanged(app, event)

            if app.plotFlag
                app.axesFlag = 1;
            end
            
        end

        % Value changed function: Playback_CheckBox5
        function Playback_CheckBox5ValueChanged(app, event)
            
            Layout_Plot(app)
            
            if app.plotFlag
                if event.PreviousValue
                    Fcn_Plot_Layout2(app)
                else
                    app.axesFlag = 1;
                end
            end
            
        end

        % Callback function
        function FrequnciacentraldocanalamonitorarEditFieldValueChanged(app, event)
            
            selectedNode = app.Tree2.SelectedNodes.NodeData;
            
            app.x = round(linspace(app.specData(selectedNode).MetaData.F0/1e+6, app.specData(selectedNode).MetaData.F1/1e+6, app.specData(selectedNode).MetaData.NDATA), 3);
            
            auxIndex1 = [];
            auxIndex2 = [];
            
            if (app.selectedChannel.Value >= app.specData(selectedNode).MetaData.F0/1e+6) && (app.selectedChannel.Value <= app.specData(selectedNode).MetaData.F1/1e+6)
                if ismember(num2str(app.selectedChannel.Value), app.channelData(:,1))
                    return
                end
                
                auxIndex1 = find(app.x == app.selectedChannel.Value);
                
                if ~isempty(auxIndex1)
                    auxIndex2 = fix(median(auxIndex1));
                else
                    [~, auxIndex2] = min(abs(app.x - app.selectedChannel.Value));
                end
                
            else
                return
            end
            
            if ~isempty(auxIndex2)
                app.RelaodefrequnciascentraisdoscanaisamonitorarListBox.Items = [app.RelaodefrequnciascentraisdoscanaisamonitorarListBox.Items, ...
                                                                                 num2str(app.selectedChannel.Value)];
                
                app.channelData = [app.channelData; {num2str(app.selectedChannel.Value) selectedNode auxIndex2}];
            end
            
            if ~isempty(app.RelaodefrequnciascentraisdoscanaisamonitorarListBox.Items)
                app.Button_36.Enable = 1;
                app.Button_38.Enable = 1;
            else
                app.Button_36.Enable = 0;
                app.Button_38.Enable = 0;
            end
            
        end

        % Callback function
        function Button_38Pushed(app, event)
            

            if isempty(app.RelaodefrequnciascentraisdoscanaisamonitorarListBox.Value)
                return
            end
            
            fig1 = figure;
            
            auxIndex1 = [];
            auxIndex2 = [];
            
            for ii = 1:length(app.RelaodefrequnciascentraisdoscanaisamonitorarListBox.Value)
                [~, auxIndex1] = ismember(app.RelaodefrequnciascentraisdoscanaisamonitorarListBox.Value(ii), app.channelData(:,1));
                
                if auxIndex1
                    auxIndex2 = [auxIndex2, auxIndex1];
                end
            end

            [~, ind] = min(app.specData(app.channelData{1,2}).Data{1,2}(:,1));
            plot(fig1, app.specData(app.channelData{1,2}).Data{1}, app.specData(app.channelData{1,2}).Data{2}(ind,:))
            hold on
            
            for ii = auxIndex2
                plot(fig1, app.specData(app.channelData{ii,2}).Data{1}, app.specData(app.channelData{ii,2}).Data{2}(app.channelData{ii,3},:))
            end


        end

        % Callback function
        function Button_36Pushed(app, event)
            
            if isempty(app.RelaodefrequnciascentraisdoscanaisamonitorarListBox.Value)
                return
            end
            
            auxIndex = ismember(app.channelData(:,1), app.RelaodefrequnciascentraisdoscanaisamonitorarListBox.Value);
            app.RelaodefrequnciascentraisdoscanaisamonitorarListBox.Items(auxIndex') = [];
            app.channelData(auxIndex, :) = [];

            if ~isempty(app.RelaodefrequnciascentraisdoscanaisamonitorarListBox.Items)
                app.Button_36.Enable = 1;
                app.Button_38.Enable = 1;
            else
                app.Button_36.Enable = 0;
                app.Button_38.Enable = 0;
            end
            
        end

        % Selection changed function: RadioGroup3
        function Marker_RadioGroupSelectionChanged(app, event)
            
            switch app.RadioGroup3.SelectedObject.Text
                case 'Inclusão manual'
                    set(app.Grid7.Children, 'Enable', 0);

                case 'Inclusão automática (busca de picos)'
                    set(app.Grid7.Children, 'Enable', 1);
                    
                    app.TraoDropDownLabel.Enable  = 0;
                    app.Playback_TraceType.Enable = 0;
            end
            
        end

        % Image clicked function: Button_45
        function Marker_Add(app, event)

            app.Span  = (app.specData(app.traceInfo.SelectedNode).MetaData.F1 - app.specData(app.traceInfo.SelectedNode).MetaData.F0);
            app.aCoef = app.Span ./ (app.specData(app.traceInfo.SelectedNode).MetaData.NDATA - 1);
            app.bCoef = app.specData(app.traceInfo.SelectedNode).MetaData.F0 - app.aCoef;
            
            if app.Playback_Radio6.Value
                app.locs = [round(app.specData(app.traceInfo.SelectedNode).MetaData.NDATA/2); app.locs];
                
            else
                [~, auxIndex] = findpeaks(app.specData(app.traceInfo.SelectedNode).Data{2}(:, app.timeIndex+1), ...
                                          'NPeaks',            app.Playback_Value9.Value, ...
                                          "MinPeakHeight",     app.Playback_Value10.Value, ...
                                          'MinPeakProminence', app.Playback_Value11.Value, ...
                                          "MinPeakDistance",   1000*app.Playback_Value12.Value/app.aCoef, ...
                                          "MinPeakWidth",      1000*app.Playback_Value13.Value/app.aCoef, ...
                                          "SortStr",           "descend");
                
                app.locs = [auxIndex; app.locs];
            end
            
            delete(app.Tree4.Children)
            Fcn_Tree4(app)
            
            if app.plotFlag
                app.axesFlag = 1;
            end
            
        end

        % Image clicked function: Button_46
        function Marker_Delete(app, event)
            
            for ii = length(app.Tree4.SelectedNodes):-1:1
                app.locs(app.Tree4.SelectedNodes(ii).NodeData(1)) = [];
            end
            
            delete(app.Tree4.Children)
            if ~isempty(app.locs)
                Fcn_Tree4(app)
            else
                app.Button_46.Enable = 0;
                
                app.FrequnciacentraldomarcadorLabel.Enable = 0;
                app.Marker_CF.Enable     = 0;
                app.Marker_CFUnit.Enable = 0;
            end
            
            if app.plotFlag
                app.axesFlag = 1;
            end
            
        end

        % Value changed function: Marker_CF
        function Marker_CFValueChanged(app, event)
            
            if (app.Marker_CF.Value*1e+6 < app.specData(app.traceInfo.SelectedNode).MetaData.F0) || ...
               (app.Marker_CF.Value*1e+6 > app.specData(app.traceInfo.SelectedNode).MetaData.F1)
            
               app.Marker_CF.Value = str2double(extractBetween(app.Tree4.SelectedNodes.Text, ": ", " MHz"));
               return
            end
            
            auxIndex1 = app.Tree4.SelectedNodes.NodeData(1);
            auxIndex2 = round((app.Marker_CF.Value*1e+6 - app.bCoef)/app.aCoef);    % Index of the new frequency.
            
            auxValue1 = app.axes1.Children(end).YData(auxIndex2);                   % Level related to the new frequency.
            
            app.locs(auxIndex1) = auxIndex2;
            app.axes1.Children(end).MarkerIndices(auxIndex1) = auxIndex2;
            app.axes1.Children(end-auxIndex1).Position(1:2)  = [app.Marker_CF.Value*1e+6, auxValue1];
            
            app.Marker_CF.Value = round(app.x(auxIndex2)/1e+6, 3);
            
            set(app.Tree4.Children(auxIndex1), 'Text', sprintf("M%d: %.3f MHz @ %.1f dBm", auxIndex1, app.Marker_CF.Value, auxValue1), ...
                                                'NodeData', [auxIndex1, app.traceInfo.SelectedNode, auxIndex2]);
            
            app.line3.Position(:,1) = repmat(app.x(auxIndex2), 2, 1);


        end

        % Value changed function: Playback_Hand
        function Playback_PanInterativity(app, event)
            
            if app.Playback_Hand.Value
                pan(app.fig, 'on')
                zoom(app.fig, 'off')
                
                app.Playback_Zoom.Value = 0;
            else
                pan(app.fig, 'off')
            end
            
        end

        % Value changed function: Playback_Zoom
        function Playback_ZoomInterativity(app, event)
            
            if app.Playback_Zoom.Value
                pan(app.fig, 'off')
                zoom(app.fig, 'on')
                
                app.Playback_Hand.Value = 0;
            else
                zoom(app.fig, 'off')
            end
            
        end

        % Button pushed function: Playback_FullScreen
        function Playback_FullScreenMode(app, event)
            
            pan(app.fig, 'off')
            zoom(app.fig, 'off')
            
            switch app.fig.WindowState
                case 'normal'
                    app.fig.WindowState = 'fullscreen';
                    app.Playback_FullScreen.Icon = 'minimizar.png';
                case 'fullscreen'
                    app.fig.WindowState = 'normal';
                    app.Playback_FullScreen.Icon = 'telacheia.png';
            end
            
        end

        % Value changed function: Report_recButton
        function Report_recButtonValueChanged(app, event)
            
            uiprogressdlg(app.MainApp, 'Indeterminate', 'on');
            switch app.Report_Value1_2.Value
                case 'CRFS BIN (v.5)'
                    filename = Fcn_BinFileWriter(app.specData(app.filtData(1)));
                    
                case 'SM1809'
                    filename = Fcn_SM1809FileWriter(app.specData(app.filtData(1)), app.specData(app.filtData(1)).Samples);
            end
            
            uialert(app.MainApp, sprintf('%s', filename), 'Alerta!', 'Icon', 'success');
            app.Report_recButton.Value = 0;
            
        end

        % Image clicked function: SearchPath
        function SearchPathImageClicked(app, event)
            
            newUserPath = uigetdir(app.UserPath.Value, 'Escolha a pasta de trabalho');
            
            if newUserPath
                ind = find(strcmp(app.UserPath.Items, newUserPath));
                
                if isempty(ind)
                    app.UserPath.Items(end+1) = {newUserPath};
                    app.UserPath.Value        = newUserPath;
                else
                    app.UserPath.Value        = app.UserPath.Items(ind);
                end
            end
            
        end

        % Image clicked function: ImageTree
        function ImageTreeClicked(app, event)
            
            if strcmpi(app.ImageTree.ImageSource, 'ExpandA1.png')
                for ii = 1:numel(app.Tree2.Children)
                    expand(app.Tree2.Children(ii));
                end
                app.ImageTree.ImageSource = 'Colapse0A.png';
                
            else
                collapse(app.Tree2, 'all');
                app.ImageTree.ImageSource = 'ExpandA1.png';
            end
            
        end

        % Value changed function: Report_Value6_2
        function Report_Value6_2ValueChanged2(app, event)

            switch app.Report_Value6_2.Value
                case 'Radiodifusão (FM)'
                    app.Label_32.Text = ['Emissões com alta ocupação, cujas portadoras ' ...
                                         'são moduladas em frequência e ocupam canais '  ...
                                         'espaçados em 200 kHz.'];
                    
                    %% Critério 1:
                    app.Report_Value10.Value = 10;                          % Proeminência mínima
                    app.Report_Value11.Value = 15;                          % BW mínima
                    app.Report_Value12.Value = 200;                         % Distância mínima entre picos
                    app.Report_Value9.Value  = 'Mediana';
                    
                    %% Critério 2:
                    app.Report_Value13.Value = 30;                          % Proeminência mínima
                    app.Report_Value16.Value = 67;                          % Mínima ocupação máxima
                    
                case 'Radiodifusão (TV)'
                    app.Label_32.Text = ['Emissões com alta ocupação, cujas portadoras '     ...
                                         'são moduladas por sinais analógicos ou digitais. ' ...
                                         'Canais espaçados em 6 MHz.'];
                    
                    %% Critério 1:
                    app.Report_Value10.Value = 10;                          % Proeminência mínima
                    app.Report_Value11.Value = 150;                         % BW mínima
                    app.Report_Value12.Value = 4450;                        % Distância mínima entre picos
                    app.Report_Value9.Value  = 'Mediana';
                    
                    %% Critério 2:
                    app.Report_Value13.Value = 30;                          % Proeminência mínima
                    app.Report_Value16.Value = 67;                          % Mínima ocupação máxima
                    
                case 'Radiocomunicação'
                    app.Label_32.Text = ['Radiocomunicação. Portadoras moduladas ' ...
                                         'em amplitude ou frequência, ocupando '   ...
                                         'canais espaçados em 25 kHz.'];
                    
                    %% Critério 1:
                    app.Report_Value10.Value = 10;                          % Proeminência mínima
                    app.Report_Value11.Value = 10;                          % BW mínima
                    app.Report_Value12.Value = 25;                          % Distância mínima entre picos
                    app.Report_Value9.Value  = 'Mediana';
                    
                    %% Critério 2:
                    app.Report_Value13.Value = 30;                          % Proeminência mínima
                    app.Report_Value16.Value = 1;                           % Mínima ocupação máxima
                    
                case 'Genérico'
                    app.Label_32.Text = 'Não definido.';
                    
                    %% Critério 1:
                    app.Report_Value10.Value = 10;                          % Proeminência mínima
                    app.Report_Value11.Value = 10;                          % BW mínima
                    app.Report_Value12.Value = 25;                          % Distância mínima entre picos
                    app.Report_Value9.Value  = 'Mediana';
                    
                    %% Critério 2:
                    app.Report_Value13.Value = 30;                          % Proeminência mínima
                    app.Report_Value16.Value = 1;                           % Mínima ocupação máxima
                    
            end
            
        end

        % Button pushed function: Report_FiscalizaButton
        function Report_FiscalizaButtonPushed(app, event)
            
            % PENDENTE EVOLUÇÃO!!!
            
            % CRIAR ÁREA ESPECÍFICA NO APP.
            
            % PEGAR OS DADOS DO FISCALIZA... APRESENTAR AQUILO QUE PODE SER
            % FEITO. LISTA DE TODOS OS FISCAIS ATUALIZADA? ÁRVORES, OPÇÕES
            % DE CADA UMA DAS COISAS?
            
            d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on');
            
            ISSUE = int64(app.Report_Value2.Value);
            try
                if app.FiscalizaCount.Value == 0
                    d.Message = sprintf('Inspeção nº %d\n\nPasso %d de 4.\nCriação do recurso de comunicação...', ISSUE, (app.FiscalizaCount.Value+1));
                    app.mod = py.importlib.import_module('pyRedmine');
                    
                    d.Message = sprintf('Inspeção nº %d\n\nPasso %d de 4.\nValidação do login e senha...', ISSUE, (app.FiscalizaCount.Value+1));
                    app.redmineObj = app.mod.connFcn('eric', '123456');
                    d.Message = sprintf('Inspeção nº %d\n\nPasso %d de 4.\nEm edição campos do formulário para alterar o estado de "Rascunho" para "Aguardando execução".', ISSUE, (app.FiscalizaCount.Value+1));
                    app.mod.setFcn(app.redmineObj, ISSUE, 1, py.list({'11', 'Primeira escrita...'}),                      ...
                                 py.list({'{"valor":"Técnica","texto":"Técnica"}',                                    ...
                                 '{"valor":"Uso do Espectro - Monitoração","texto":"Uso do Espectro - Monitoração"}', ...
                                 'Uma descrição qualquer...', '174', py.list({'174', '302', '1207'})}));
                
                elseif app.FiscalizaCount.Value == 1
                    d.Message = sprintf('Inspeção nº %d\n\nPasso %d de 4.\nEm edição campos do formulário para alterar o estado de "Aguardando execução" para "Em andamento".', ISSUE, (app.FiscalizaCount.Value+1));
                    app.mod.setFcn(app.redmineObj, ISSUE, 2, py.list({'13', 'Segunda escrita....'}), ...
                                 'C:/Users/Administrador/OneDrive - ANATEL/appAnalise/Temp/Report_2021.03.08_T19.52.18_FinalVersion.html');
                    
                elseif app.FiscalizaCount.Value == 2
                    d.Message = sprintf('Inspeção nº %d\n\nPasso %d de 4.\nEm edição campos do formulário para alterar o estado de "Em andamento" para "Relatando".', ISSUE, (app.FiscalizaCount.Value+1));
                    app.mod.setFcn(app.redmineObj, ISSUE, 3, py.list({'14', 'Terceira escrita...', '2021-01-01', '2021-03-08'}), py.list({'108', 'MHz', '137', 'MHz'}));
    
                elseif app.FiscalizaCount.Value == 3
                    d.Message = sprintf('Inspeção nº %d\n\nPasso %d de 4.\nEm edição campos do formulário para alterar o estado de "Relatando" para "Relatada".', ISSUE, (app.FiscalizaCount.Value+1));
                    app.mod.setFcn(app.redmineObj, ISSUE, 4, py.list({'15', 'Quarta escrita.....'}),                            ...
                                 py.list({'{"valor":"BA/Salvador","texto":"BA/Salvador"}',                                      ...
                                 '{"valor":"507 - RESTRITO - MÓVEL AERONÁUTICO","texto":"507 - RESTRITO - MÓVEL AERONÁUTICO"}', ...
                                 '10', '2', '0', '1', '2', '3', '-12.123456', '-38.123456', 'Não se aplica PF - demanda específica', 'Não', '1'}));
                    
                end
                
                
                if app.FiscalizaCount.Value == 3
                    app.FiscalizaCount.Value = 0;
                else
                    app.FiscalizaCount.Value = app.FiscalizaCount.Value + 1;
                end
                
            catch ME
                uialert(app.MainApp, sprintf('ME.identifier: %s\nME.message: %s.', ME.identifier, ME.message), 'Alerta!', 'Icon', 'error')
                
            end
            
        end

        % Button pushed function: Button_35
        function Button_35Pushed(app, event)
            
%             Main_FileWindow(app, app.MainApp.Position);
            
        end

        % Value changed function: Playback_Value2_2
        function Playback_Value2_2ValueChanged(app, event)
            
            switch app.Playback_Value2_2.Value
                case 'Linear'
                    app.Playback_Unit1_2.Enable = 1;
                    app.Playback_Value3_2.Enable = 1;
                    app.ValorLabel_2.Enable = 1;
                    
                    set(app.Report_Grid5_2.Children, 'Enable', 0)
                    app.ParmetrosrelacionadosestimativadopisoderudoLabel.Enable = 0;
                    
                    app.Playback_Unit1_3.Enable = 0;
                    app.Playback_Value8_2.Enable = 0;
                    
                    app.NveldeconfianaLabel_2.Enable = 0;
                    
                    app.Report_Value5.Enable = 0;
                    app.OffSetLabel_2.Enable = 0;
                    
                case 'Piso de ruído (Offset)'
                    app.Playback_Unit1_2.Enable = 0;
                    app.Playback_Value3_2.Enable = 0;
                    app.ValorLabel_2.Enable = 0;

                    set(app.Report_Grid5_2.Children, 'Enable', 1)
                    app.ParmetrosrelacionadosestimativadopisoderudoLabel.Enable = 1;
                    
                    app.Playback_Unit1_3.Enable = 1;
                    app.Playback_Value8_2.Enable = 1;
                    
                    app.NveldeconfianaLabel_2.Enable = 1;
                    
                    app.Report_Value5.Enable = 1;
                    app.OffSetLabel_2.Enable = 1;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainApp = uifigure('Visible', 'off');
            app.MainApp.Name = 'appAnálise';
            app.MainApp.Icon = 'Bluemine.png';
            app.MainApp.CloseRequestFcn = createCallbackFcn(app, @MainAppCloseRequest, true);
            app.Grid1 = uigridlayout(app.MainApp);
            app.ToolBar = uigridlayout(app.Grid1);
            app.Button_33 = uibutton(app.ToolBar, 'push');
            app.Button_33.Icon = 'settings2.png';
            app.Button_33.Enable = 'off';
            app.Button_33.Text = '';
            app.Button_34 = uibutton(app.ToolBar, 'push');
            app.Button_34.ButtonPushedFcn = createCallbackFcn(app, @Button_34Pushed, true);
            app.Button_34.Icon = 'InfoIcon7.png';
            app.Button_34.Text = '';
            app.Button_35 = uibutton(app.ToolBar, 'push');
            app.Button_35.ButtonPushedFcn = createCallbackFcn(app, @Button_35Pushed, true);
            app.Button_35.Icon = 'Help.png';
            app.Button_35.Text = '';
            app.Button_32 = uibutton(app.ToolBar, 'push');
            app.Button_32.ButtonPushedFcn = createCallbackFcn(app, @globalVariables_Callback, true);
            app.Button_32.Icon = 'global-icon-13.png';
            app.Button_32.Text = '';
            app.Button_31 = uibutton(app.ToolBar, 'push');
            app.Button_31.ButtonPushedFcn = createCallbackFcn(app, @winLayout_Callback, true);
            app.Button_31.Icon = 'ChangeLayout2.png';
            app.Button_31.Text = '';
            app.MainMenu = uigridlayout(app.Grid1);
            app.GridLayout27 = uigridlayout(app.MainMenu);
            app.UserPath = uidropdown(app.GridLayout27);
            app.UserPath.Items = {'Path'};
            app.UserPath.Value = 'Path';
            app.SearchPath = uiimage(app.GridLayout27);
            app.SearchPath.ImageClickedFcn = createCallbackFcn(app, @SearchPathImageClicked, true);
            app.SearchPath.ImageSource = 'Zoom4.png';
            app.GridLayout28 = uigridlayout(app.MainMenu);
            app.Button_11 = uibutton(app.GridLayout28, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_OpenFile, true);
            app.Button_11.Icon = '62319c.png';
            app.Button_11.Text = '';
            app.Button_Info = uibutton(app.GridLayout28, 'state');
            app.Button_Info.ValueChangedFcn = createCallbackFcn(app, @Button_InfoPushed, true);
            app.Button_Info.Enable = 'off';
            app.Button_Info.Tooltip = {''};
            app.Button_Info.Icon = 'Edit2.png';
            app.Button_Info.Text = '';
            app.Image2 = uiimage(app.GridLayout28);
            app.Image2.Enable = 'off';
            app.Image2.ImageSource = 'Line.png';
            app.Button_Playback = uibutton(app.GridLayout28, 'state');
            app.Button_Playback.ValueChangedFcn = createCallbackFcn(app, @Button_PlaybackValueChanged, true);
            app.Button_Playback.Enable = 'off';
            app.Button_Playback.Tooltip = {''};
            app.Button_Playback.Icon = 'RF02.png';
            app.Button_Playback.Text = '';
            app.Button_Report = uibutton(app.GridLayout28, 'state');
            app.Button_Report.ValueChangedFcn = createCallbackFcn(app, @Button_ReportValueChanged, true);
            app.Button_Report.Enable = 'off';
            app.Button_Report.Tooltip = {''};
            app.Button_Report.Icon = 'ReportIcon.png';
            app.Button_Report.Text = '';
            app.Report_recButton = uibutton(app.GridLayout28, 'state');
            app.Report_recButton.ValueChangedFcn = createCallbackFcn(app, @Report_recButtonValueChanged, true);
            app.Report_recButton.Enable = 'off';
            app.Report_recButton.Tooltip = {''};
            app.Report_recButton.Icon = 'REC1a.png';
            app.Report_recButton.Text = '';
            app.Report_FiscalizaButton = uibutton(app.GridLayout28, 'push');
            app.Report_FiscalizaButton.ButtonPushedFcn = createCallbackFcn(app, @Report_FiscalizaButtonPushed, true);
            app.Report_FiscalizaButton.Icon = 'Redmine.png';
            app.Report_FiscalizaButton.Enable = 'off';
            app.Report_FiscalizaButton.Text = '';
            app.Grid_2 = uigridlayout(app.Grid1);
            app.Warning_Image = uiimage(app.Grid_2);
            app.Warning_Image.ImageSource = 'treeAlarm2.png';
            app.Tree2 = uitree(app.Grid_2);
            app.Tree2.SelectionChangedFcn = createCallbackFcn(app, @Tree2_SelectionChanged, true);
            app.Tree2.Enable = 'off';
            app.Tree2_Label = uilabel(app.Grid_2);
            app.Tree2_Label.Enable = 'off';
            app.Tree2_Label.Text = 'Fluxo(s) de dados';
            app.Warning_Message = uilabel(app.Grid_2);
            app.Warning_Message.Enable = 'off';
            app.Warning_Message.Text = '0 alerta(s)';
            app.Label_31 = uilabel(app.Grid_2);
            app.Label_31.Enable = 'off';
            app.Label_31.Text = 'Metadados';
            app.metaData_16 = uitextarea(app.Grid_2);
            app.metaData_16.Editable = 'off';
            app.metaData_16.Enable = 'off';
            app.ImageTree = uiimage(app.Grid_2);
            app.ImageTree.ImageClickedFcn = createCallbackFcn(app, @ImageTreeClicked, true);
            app.ImageTree.Enable = 'off';
            app.ImageTree.ImageSource = 'ExpandA1.png';
            app.Grid_1 = uigridlayout(app.Grid1);
            app.Tree1 = uitree(app.Grid_1);
            app.Tree1.Multiselect = 'on';
            app.Tree1.SelectionChangedFcn = createCallbackFcn(app, @Tree1_SelectionChanged, true);
            app.Tree1.Enable = 'off';
            app.LeituraespectroButton = uibutton(app.Grid_1, 'push');
            app.LeituraespectroButton.ButtonPushedFcn = createCallbackFcn(app, @SpecDataReadCallback, true);
            app.LeituraespectroButton.Icon = 'Connect.png';
            app.LeituraespectroButton.Enable = 'off';
            app.LeituraespectroButton.Text = 'Leitura espectro';
            app.Label_13 = uilabel(app.Grid_1);
            app.Label_13.Enable = 'off';
            app.Label_13.Text = 'Informações gerais';
            app.Label_12 = uilabel(app.Grid_1);
            app.Label_12.Enable = 'off';
            app.Label_12.Text = '0 arquivo(s) selecionado(s)';
            app.Label_11 = uilabel(app.Grid_1);
            app.Label_11.Enable = 'off';
            app.Label_11.Text = 'Arquivo(s)';
            app.Button_12 = uiimage(app.Grid_1);
            app.Button_12.ImageClickedFcn = createCallbackFcn(app, @Button_Delete, true);
            app.Button_12.Enable = 'off';
            app.Button_12.Tooltip = {'Abrir arquivo(s).'};
            app.Button_12.ImageSource = 'adicionar3b.png';
            app.metaData_15 = uitextarea(app.Grid_1);
            app.metaData_15.Editable = 'off';
            app.metaData_15.Enable = 'off';
            app.Logo = uiimage(app.Grid1);
            app.Logo.Enable = 'off';
            app.Logo.Visible = 'off';
            app.Logo.ImageSource = 'RFLook3A.png';
            app.Grid3_Playback = uigridlayout(app.Grid1);
            app.Grid3_Playback.Visible = 'off';
            app.Report_Label1_4 = uilabel(app.Grid3_Playback);
            app.Report_Label1_4.Text = 'Playback';
            app.Playback_Play = uibutton(app.Grid3_Playback, 'push');
            app.Playback_Play.ButtonPushedFcn = createCallbackFcn(app, @Layout_Playback, true);
            app.Playback_Play.Icon = 'Media_Play.png';
            app.Playback_Play.Enable = 'off';
            app.Playback_Play.Text = 'Iniciar escrita';
            app.Playback_FullScreen = uibutton(app.Grid3_Playback, 'push');
            app.Playback_FullScreen.ButtonPushedFcn = createCallbackFcn(app, @Playback_FullScreenMode, true);
            app.Playback_FullScreen.Icon = 'telacheia.png';
            app.Playback_FullScreen.Text = '';
            app.Playback_Hand = uibutton(app.Grid3_Playback, 'state');
            app.Playback_Hand.ValueChangedFcn = createCallbackFcn(app, @Playback_PanInterativity, true);
            app.Playback_Hand.Tooltip = {'Análise de faixa de frequência.'};
            app.Playback_Hand.Icon = 'HandIcon.png';
            app.Playback_Hand.Text = '';
            app.Playback_Zoom = uibutton(app.Grid3_Playback, 'state');
            app.Playback_Zoom.ValueChangedFcn = createCallbackFcn(app, @Playback_ZoomInterativity, true);
            app.Playback_Zoom.Tooltip = {'Análise de faixa de frequência.'};
            app.Playback_Zoom.Icon = 'ZoomIcon.png';
            app.Playback_Zoom.Text = '';
            app.Playback_Menu = uibutton(app.Grid3_Playback, 'state');
            app.Playback_Menu.ValueChangedFcn = createCallbackFcn(app, @conePanCheckBoxValueChanged, true);
            app.Playback_Menu.Tooltip = {'Análise de faixa de frequência.'};
            app.Playback_Menu.Icon = 'aa.png';
            app.Playback_Menu.Text = '';
            app.Playback_Reload = uibutton(app.Grid3_Playback, 'push');
            app.Playback_Reload.ButtonPushedFcn = createCallbackFcn(app, @Playback_ReloadPushed, true);
            app.Playback_Reload.Icon = 'reloadIcon.png';
            app.Playback_Reload.Text = '';
            app.Panel_21 = uipanel(app.Grid3_Playback);
            app.GridLayout24 = uigridlayout(app.Panel_21);
            app.Report_Grid1_2 = uigridlayout(app.GridLayout24);
            app.Playback_Label1 = uilabel(app.Report_Grid1_2);
            app.Playback_Label1.Text = 'Processamento dos traços';
            app.RadioGroup1 = uibuttongroup(app.Report_Grid1_2);
            app.RadioGroup1.SelectionChangedFcn = createCallbackFcn(app, @integrationType_Callback, true);
            app.Playback_Radio2 = uiradiobutton(app.RadioGroup1);
            app.Playback_Radio2.Text = '"Tempo real"';
            app.Playback_Radio1 = uiradiobutton(app.RadioGroup1);
            app.Playback_Radio1.Text = 'Integraçao infinita';
            app.Playback_Radio1.Value = true;
            app.Panel1 = uipanel(app.Report_Grid1_2);
            app.GridLayout4 = uigridlayout(app.Panel1);
            app.Playback_CheckBox3 = uicheckbox(app.GridLayout4);
            app.Playback_CheckBox3.ValueChangedFcn = createCallbackFcn(app, @axesFlag_Callback, true);
            app.Playback_CheckBox3.Text = 'MaxHold';
            app.Playback_CheckBox2 = uicheckbox(app.GridLayout4);
            app.Playback_CheckBox2.ValueChangedFcn = createCallbackFcn(app, @axesFlag_Callback, true);
            app.Playback_CheckBox2.Text = 'MinHold';
            app.Playback_Label2 = uilabel(app.GridLayout4);
            app.Playback_Label2.Text = 'Limites:';
            app.RadioGroup2 = uibuttongroup(app.GridLayout4);
            app.RadioGroup2.SelectionChangedFcn = createCallbackFcn(app, @axesFlag_Callback, true);
            app.Playback_Radio5 = uiradiobutton(app.RadioGroup2);
            app.Playback_Radio5.Enable = 'off';
            app.Playback_Radio5.Text = 'Média (vídeo)';
            app.Playback_Radio4 = uiradiobutton(app.RadioGroup2);
            app.Playback_Radio4.Enable = 'off';
            app.Playback_Radio4.Text = 'Mediana';
            app.Playback_Radio4.Value = true;
            app.Playback_CheckBox1 = uicheckbox(app.GridLayout4);
            app.Playback_CheckBox1.ValueChangedFcn = createCallbackFcn(app, @axesFlag_Callback, true);
            app.Playback_CheckBox1.Text = 'Tendência central:';
            app.FatordeintegraoLabel = uilabel(app.GridLayout4);
            app.FatordeintegraoLabel.Enable = 'off';
            app.FatordeintegraoLabel.Text = 'Fator de integração:';
            app.Playback_Value1 = uispinner(app.GridLayout4);
            app.Playback_Value1.Limits = [2 Inf];
            app.Playback_Value1.ValueDisplayFormat = '%d';
            app.Playback_Value1.ValueChangedFcn = createCallbackFcn(app, @axesFlag_Callback, true);
            app.Playback_Value1.Enable = 'off';
            app.Playback_Value1.Value = 100;
            app.Playback_Label3 = uilabel(app.Report_Grid1_2);
            app.Playback_Label3.Text = 'Marcadores';
            app.RadioGroup3 = uibuttongroup(app.Report_Grid1_2);
            app.RadioGroup3.SelectionChangedFcn = createCallbackFcn(app, @Marker_RadioGroupSelectionChanged, true);
            app.Playback_Radio7 = uiradiobutton(app.RadioGroup3);
            app.Playback_Radio7.Text = 'Inclusão automática (busca de picos)';
            app.Playback_Radio6 = uiradiobutton(app.RadioGroup3);
            app.Playback_Radio6.Text = 'Inclusão manual';
            app.Playback_Radio6.Value = true;
            app.Panel3 = uipanel(app.Report_Grid1_2);
            app.Grid7 = uigridlayout(app.Panel3);
            app.LarguramnimaocupadaLabel = uilabel(app.Grid7);
            app.LarguramnimaocupadaLabel.Enable = 'off';
            app.LarguramnimaocupadaLabel.Text = 'Largura mínima ocupada:';
            app.Marker_Label3 = uilabel(app.Grid7);
            app.Marker_Label3.Enable = 'off';
            app.Marker_Label3.Text = {'Distância mínima '; 'entre picos:'};
            app.Marker_Label2 = uilabel(app.Grid7);
            app.Marker_Label2.Enable = 'off';
            app.Marker_Label2.Text = 'Proeminência mínima:';
            app.Marker_Label1 = uilabel(app.Grid7);
            app.Marker_Label1.Enable = 'off';
            app.Marker_Label1.Text = 'Threshold:';
            app.Playback_Unit6 = uilabel(app.Grid7);
            app.Playback_Unit6.Enable = 'off';
            app.Playback_Unit6.Text = 'kHz';
            app.Playback_Value13 = uispinner(app.Grid7);
            app.Playback_Value13.Step = 10;
            app.Playback_Value13.Limits = [0 Inf];
            app.Playback_Value13.RoundFractionalValues = 'on';
            app.Playback_Value13.ValueDisplayFormat = '%.0f';
            app.Playback_Value13.Enable = 'off';
            app.Playback_Value13.Value = 10;
            app.Playback_Unit5 = uilabel(app.Grid7);
            app.Playback_Unit5.Enable = 'off';
            app.Playback_Unit5.Text = 'kHz';
            app.Playback_Value12 = uispinner(app.Grid7);
            app.Playback_Value12.Step = 25;
            app.Playback_Value12.Limits = [0 Inf];
            app.Playback_Value12.RoundFractionalValues = 'on';
            app.Playback_Value12.ValueDisplayFormat = '%.0f';
            app.Playback_Value12.Enable = 'off';
            app.Playback_Value12.Value = 25;
            app.Playback_Unit3 = uilabel(app.Grid7);
            app.Playback_Unit3.Enable = 'off';
            app.Playback_Unit3.Text = 'dBμV/m';
            app.Playback_Unit4 = uilabel(app.Grid7);
            app.Playback_Unit4.Enable = 'off';
            app.Playback_Unit4.Text = 'dB';
            app.Playback_Value11 = uispinner(app.Grid7);
            app.Playback_Value11.Step = 10;
            app.Playback_Value11.Limits = [0 Inf];
            app.Playback_Value11.RoundFractionalValues = 'on';
            app.Playback_Value11.ValueDisplayFormat = '%.0f';
            app.Playback_Value11.Enable = 'off';
            app.Playback_Value11.Value = 30;
            app.Playback_Value10 = uispinner(app.Grid7);
            app.Playback_Value10.Step = 10;
            app.Playback_Value10.RoundFractionalValues = 'on';
            app.Playback_Value10.ValueDisplayFormat = '%.0f';
            app.Playback_Value10.Enable = 'off';
            app.Playback_Value10.Value = -Inf;
            app.Playback_Value9 = uispinner(app.Grid7);
            app.Playback_Value9.Limits = [1 10];
            app.Playback_Value9.RoundFractionalValues = 'on';
            app.Playback_Value9.ValueDisplayFormat = '%.0f';
            app.Playback_Value9.Enable = 'off';
            app.Playback_Value9.Value = 1;
            app.NmerodepicosLabel = uilabel(app.Grid7);
            app.NmerodepicosLabel.Enable = 'off';
            app.NmerodepicosLabel.Text = 'Número de picos:';
            app.TraoDropDownLabel = uilabel(app.Grid7);
            app.TraoDropDownLabel.Enable = 'off';
            app.TraoDropDownLabel.Text = 'Traço:';
            app.Playback_TraceType = uidropdown(app.Grid7);
            app.Playback_TraceType.Items = {'ClearWrite'};
            app.Playback_TraceType.Enable = 'off';
            app.Playback_TraceType.Value = 'ClearWrite';
            app.Button_45 = uiimage(app.Report_Grid1_2);
            app.Button_45.ImageClickedFcn = createCallbackFcn(app, @Marker_Add, true);
            app.Button_45.Enable = 'off';
            app.Button_45.ImageSource = 'adicionar3a.png';
            app.Button_46 = uiimage(app.Report_Grid1_2);
            app.Button_46.ImageClickedFcn = createCallbackFcn(app, @Marker_Delete, true);
            app.Button_46.Enable = 'off';
            app.Button_46.ImageSource = 'adicionar3d.png';
            app.Tree4 = uitree(app.Report_Grid1_2);
            app.Tree4.Multiselect = 'on';
            app.Tree4.SelectionChangedFcn = createCallbackFcn(app, @Tree4_SelectionChanged, true);
            app.Marker_CF = uieditfield(app.Report_Grid1_2, 'numeric');
            app.Marker_CF.ValueDisplayFormat = '%.3f';
            app.Marker_CF.ValueChangedFcn = createCallbackFcn(app, @Marker_CFValueChanged, true);
            app.Marker_CF.Enable = 'off';
            app.FrequnciacentraldomarcadorLabel = uilabel(app.Report_Grid1_2);
            app.FrequnciacentraldomarcadorLabel.Enable = 'off';
            app.FrequnciacentraldomarcadorLabel.Text = 'Frequência central do marcador:';
            app.Marker_CFUnit = uilabel(app.Report_Grid1_2);
            app.Marker_CFUnit.Enable = 'off';
            app.Marker_CFUnit.Text = 'MHz';
            app.GridLayout25 = uigridlayout(app.GridLayout24);
            app.Playback_CheckBox4 = uicheckbox(app.GridLayout25);
            app.Playback_CheckBox4.ValueChangedFcn = createCallbackFcn(app, @CheckBox32_2ValueChanged, true);
            app.Playback_CheckBox4.Text = 'Ocupação em "tempo real"';
            app.Playback_Value2 = uidropdown(app.GridLayout25);
            app.Playback_Value2.Items = {'Linear', 'Piso de ruído (Offset)'};
            app.Playback_Value2.ValueChangedFcn = createCallbackFcn(app, @Playback_Value2ValueChanged, true);
            app.Playback_Value2.Enable = 'off';
            app.Playback_Value2.Value = 'Linear';
            app.ThresholdDropDownLabel = uilabel(app.GridLayout25);
            app.ThresholdDropDownLabel.Enable = 'off';
            app.ThresholdDropDownLabel.Text = 'Threshold:';
            app.Playback_Value3 = uispinner(app.GridLayout25);
            app.Playback_Value3.RoundFractionalValues = 'on';
            app.Playback_Value3.ValueDisplayFormat = '%d';
            app.Playback_Value3.ValueChangedFcn = createCallbackFcn(app, @Report_Value6_2ValueChanged, true);
            app.Playback_Value3.Enable = 'off';
            app.Playback_Value3.Value = -80;
            app.ValorLabel = uilabel(app.GridLayout25);
            app.ValorLabel.Enable = 'off';
            app.ValorLabel.Text = 'Valor:';
            app.Playback_Unit1 = uilabel(app.GridLayout25);
            app.Playback_Unit1.Enable = 'off';
            app.Playback_Unit1.Text = 'dBm';
            app.Playback_Value6 = uispinner(app.GridLayout25);
            app.Playback_Value6.Limits = [0 12];
            app.Playback_Value6.ValueChangedFcn = createCallbackFcn(app, @Report_Value6_2ValueChanged, true);
            app.Playback_Value6.Enable = 'off';
            app.Playback_Value6.Value = 12;
            app.OffSetLabel = uilabel(app.GridLayout25);
            app.OffSetLabel.Enable = 'off';
            app.OffSetLabel.Text = 'OffSet:';
            app.Playback_Value4 = uispinner(app.GridLayout25);
            app.Playback_Value4.Limits = [2 Inf];
            app.Playback_Value4.ValueDisplayFormat = '%d';
            app.Playback_Value4.ValueChangedFcn = createCallbackFcn(app, @Report_Value6_2ValueChanged, true);
            app.Playback_Value4.Enable = 'off';
            app.Playback_Value4.Value = 100;
            app.FatordeintegraoLabel_2 = uilabel(app.GridLayout25);
            app.FatordeintegraoLabel_2.Enable = 'off';
            app.FatordeintegraoLabel_2.Text = 'Fator de integração:';
            app.NveldeconfianaLabel = uilabel(app.GridLayout25);
            app.NveldeconfianaLabel.Enable = 'off';
            app.NveldeconfianaLabel.Text = 'Nível de confiança (%):';
            app.Playback_Value8 = uidropdown(app.GridLayout25);
            app.Playback_Value8.Items = {'68', '95', '99'};
            app.Playback_Value8.ValueChangedFcn = createCallbackFcn(app, @Report_Value6_2ValueChanged, true);
            app.Playback_Value8.Enable = 'off';
            app.Playback_Value8.Value = '95';
            app.Playback_Label001 = uilabel(app.GridLayout25);
            app.Playback_Label001.Enable = 'off';
            app.Playback_Label001.Text = 'Parâmetros relacionados à estimativa do piso de ruído:';
            app.Panel2 = uipanel(app.GridLayout25);
            app.Grid4 = uigridlayout(app.Panel2);
            app.Playback_Value7 = uispinner(app.Grid4);
            app.Playback_Value7.Step = 10;
            app.Playback_Value7.Limits = [10 100];
            app.Playback_Value7.RoundFractionalValues = 'on';
            app.Playback_Value7.ValueDisplayFormat = '%.0f';
            app.Playback_Value7.ValueChangedFcn = createCallbackFcn(app, @Report_Value6_2ValueChanged, true);
            app.Playback_Value7.Enable = 'off';
            app.Playback_Value7.Value = 20;
            app.Playback_Value5 = uidropdown(app.Grid4);
            app.Playback_Value5.Items = {'Mediana', 'Média (vídeo)'};
            app.Playback_Value5.ValueChangedFcn = createCallbackFcn(app, @Report_Value6_2ValueChanged, true);
            app.Playback_Value5.Enable = 'off';
            app.Playback_Value5.Value = 'Mediana';
            app.AmostrasteisLabel_2 = uilabel(app.Grid4);
            app.AmostrasteisLabel_2.Enable = 'off';
            app.AmostrasteisLabel_2.Text = 'Amostras úteis (%):';
            app.FunodetendnciacentralLabel_3 = uilabel(app.Grid4);
            app.FunodetendnciacentralLabel_3.Enable = 'off';
            app.FunodetendnciacentralLabel_3.Text = 'Função de tendência central:';
            app.Playback_CheckBox5 = uicheckbox(app.GridLayout25);
            app.Playback_CheckBox5.ValueChangedFcn = createCallbackFcn(app, @Playback_CheckBox5ValueChanged, true);
            app.Playback_CheckBox5.Text = 'Waterfall';
            app.GridLayout26 = uigridlayout(app.GridLayout24);
            app.Playback_Slider = uislider(app.GridLayout26);
            app.Playback_Slider.MajorTicks = [0 25 50 75 100];
            app.Playback_Slider.MajorTickLabels = {'0', '25', '50', '75', '100'};
            app.Playback_Slider.ValueChangingFcn = createCallbackFcn(app, @Playback_SliderValueChanging, true);
            app.Playback_Slider.Enable = 'off';
            app.Grid3_Info = uigridlayout(app.Grid1);
            app.Grid3_Info.Visible = 'off';
            app.Info_Panel2 = uipanel(app.Grid3_Info);
            app.Info_Grid3 = uigridlayout(app.Info_Panel2);
            app.Info_Label1 = uilabel(app.Info_Grid3);
            app.Info_Label1.Text = 'Informações extraídas de base de dados da Agência';
            app.Info_Label2 = uilabel(app.Info_Grid3);
            app.Info_Label2.Text = 'Informações passíveis de edição';
            app.Info_Panel1 = uipanel(app.Info_Grid3);
            app.Info_Grid2 = uigridlayout(app.Info_Panel1);
            app.LongitudeEditFieldLabel = uilabel(app.Info_Grid2);
            app.LongitudeEditFieldLabel.Text = 'Longitude:';
            app.Info_MetaData6 = uieditfield(app.Info_Grid2, 'numeric');
            app.Info_MetaData6.ValueDisplayFormat = '%.6f';
            app.Info_MetaData6.Editable = 'off';
            app.Info_MetaData6.Value = -35.092938;
            app.Info_MetaData5 = uieditfield(app.Info_Grid2, 'numeric');
            app.Info_MetaData5.ValueDisplayFormat = '%.6f';
            app.Info_MetaData5.Editable = 'off';
            app.Info_MetaData5.Value = -12.345762;
            app.LatitudeEditFieldLabel = uilabel(app.Info_Grid2);
            app.LatitudeEditFieldLabel.Text = 'Latitude:';
            app.Info_MetaData4 = uieditfield(app.Info_Grid2, 'text');
            app.Info_MetaData4.Editable = 'off';
            app.Info_MetaData4.Value = 'Salvador/BA';
            app.LocalidadeLabel = uilabel(app.Info_Grid2);
            app.LocalidadeLabel.Text = 'Localidade:';
            app.Info_MetaData3 = uieditfield(app.Info_Grid2, 'text');
            app.Info_MetaData3.Editable = 'off';
            app.Info_MetaData3.Value = 'GR08';
            app.UnidaderesponsvelLabel_2 = uilabel(app.Info_Grid2);
            app.UnidaderesponsvelLabel_2.Text = 'Unidade responsável:';
            app.Info_Panel1_2 = uipanel(app.Info_Grid3);
            app.Info_Grid2_2 = uigridlayout(app.Info_Panel1_2);
            app.LatitudeEditField_2Label = uilabel(app.Info_Grid2_2);
            app.LatitudeEditField_2Label.Text = 'Latitude:';
            app.Info_Latitude = uieditfield(app.Info_Grid2_2, 'numeric');
            app.Info_Latitude.ValueDisplayFormat = '%.6f';
            app.Info_Latitude.Value = -12.345762;
            app.Info_Longitude = uieditfield(app.Info_Grid2_2, 'numeric');
            app.Info_Longitude.ValueDisplayFormat = '%.6f';
            app.Info_Longitude.Value = -12.345762;
            app.LongitudeEditField_2Label = uilabel(app.Info_Grid2_2);
            app.LongitudeEditField_2Label.Text = 'Longitude:';
            app.Info_CheckBox = uicheckbox(app.Info_Grid2_2);
            app.Info_CheckBox.Text = 'Transportar esses valores para outros fluxos de dados do instrumento, caso existentes.';
            app.Info_OCC = uidropdown(app.Info_Grid2_2);
            app.Info_OCC.Items = {'Ocupação a ser aferida'};
            app.Info_OCC.Value = 'Ocupação a ser aferida';
            app.OcupaoDropDownLabel = uilabel(app.Info_Grid2_2);
            app.OcupaoDropDownLabel.Text = 'Ocupação:';
            app.Info_Button = uibutton(app.Grid3_Info, 'push');
            app.Info_Button.ButtonPushedFcn = createCallbackFcn(app, @Info_ButtonPushed, true);
            app.Info_Button.Icon = 'Edit2.png';
            app.Info_Button.Text = 'Editar';
            app.Info_Label1_2 = uilabel(app.Grid3_Info);
            app.Info_Label1_2.Text = 'Fluxo de dados selecionado';
            app.Grid3_Report = uigridlayout(app.Grid1);
            app.Report_playButton = uibutton(app.Grid3_Report, 'push');
            app.Report_playButton.ButtonPushedFcn = createCallbackFcn(app, @Report_playButtonPushed, true);
            app.Report_playButton.Icon = 'Media_Play.png';
            app.Report_playButton.Enable = 'off';
            app.Report_playButton.Text = 'Gerar relatório';
            app.Report_Label1_3 = uilabel(app.Grid3_Report);
            app.Report_Label1_3.Text = 'Relatório';
            app.Panel_22 = uipanel(app.Grid3_Report);
            app.GridLayout20 = uigridlayout(app.Panel_22);
            app.Report_Grid1 = uigridlayout(app.GridLayout20);
            app.Tree3_Label = uilabel(app.Report_Grid1);
            app.Tree3_Label.Text = 'Fluxo(s) de dados a processar';
            app.Tree3 = uitree(app.Report_Grid1);
            app.Tree3.Multiselect = 'on';
            app.Tree3.SelectionChangedFcn = createCallbackFcn(app, @Tree3_SelectionChanged, true);
            app.Report_Label3 = uilabel(app.Report_Grid1);
            app.Report_Label3.Text = 'Período de observação a processar';
            app.Report_Grid4 = uigridlayout(app.Report_Grid1);
            app.Report_DatePicker1 = uidatepicker(app.Report_Grid4);
            app.Report_DatePicker1.DisplayFormat = 'dd/MMM/uuuu';
            app.Report_Spinner1 = uispinner(app.Report_Grid4);
            app.Report_Spinner1.Limits = [0 23];
            app.Report_Spinner1.RoundFractionalValues = 'on';
            app.Report_Spinner1.ValueDisplayFormat = '%.0f';
            app.Report_Spinner2 = uispinner(app.Report_Grid4);
            app.Report_Spinner2.Step = 10;
            app.Report_Spinner2.Limits = [0 59];
            app.Report_Spinner2.RoundFractionalValues = 'on';
            app.Report_Spinner2.ValueDisplayFormat = '%.0f';
            app.Report_DatePicker2 = uidatepicker(app.Report_Grid4);
            app.Report_DatePicker2.DisplayFormat = 'dd/MMM/uuuu';
            app.Report_Spinner3 = uispinner(app.Report_Grid4);
            app.Report_Spinner3.Limits = [0 23];
            app.Report_Spinner3.RoundFractionalValues = 'on';
            app.Report_Spinner3.ValueDisplayFormat = '%.0f';
            app.Report_Spinner3.Value = 23;
            app.Report_Spinner4 = uispinner(app.Report_Grid4);
            app.Report_Spinner4.Step = 10;
            app.Report_Spinner4.Limits = [0 59];
            app.Report_Spinner4.RoundFractionalValues = 'on';
            app.Report_Spinner4.ValueDisplayFormat = '%.0f';
            app.Report_Spinner4.Value = 59;
            app.Report_Label1 = uilabel(app.Report_Grid1);
            app.Report_Label1.Text = 'Aspectos gerais';
            app.GridLayout19 = uigridlayout(app.Report_Grid1);
            app.Report_Value2 = uieditfield(app.GridLayout19, 'numeric');
            app.Report_Value2.Limits = [0 Inf];
            app.Report_Value2.ValueDisplayFormat = '%d';
            app.InspeoFiscalizaLabel = uilabel(app.GridLayout19);
            app.InspeoFiscalizaLabel.Text = 'Inspeção (Fiscaliza):';
            app.Report_Value1_3 = uidropdown(app.GridLayout19);
            app.Report_Value1_3.Items = {'Preliminar', 'Definitiva'};
            app.Report_Value1_3.Value = 'Preliminar';
            app.VersoLabel = uilabel(app.GridLayout19);
            app.VersoLabel.Text = 'Versão:';
            app.Report_CheckBox2 = uicheckbox(app.GridLayout19);
            app.Report_CheckBox2.ValueChangedFcn = createCallbackFcn(app, @Report_CheckBox2ValueChanged, true);
            app.Report_CheckBox2.Text = 'Incluir detalhes acerca do PMEC.';
            app.Report_CheckBox2.Value = true;
            app.Report_CheckBox3 = uicheckbox(app.GridLayout19);
            app.Report_CheckBox3.ValueChangedFcn = createCallbackFcn(app, @Report_CheckBox3ValueChanged, true);
            app.Report_CheckBox3.Text = 'Incluir detalhes acerca do PRD.';
            app.Report_CheckBox4 = uicheckbox(app.GridLayout19);
            app.Report_CheckBox4.ValueChangedFcn = createCallbackFcn(app, @Report_CheckBox4ValueChanged, true);
            app.Report_CheckBox4.Text = 'Incluir a informação textual indicada a seguir.';
            app.Report_TextArea = uitextarea(app.GridLayout19);
            app.Report_TextArea.Enable = 'off';
            app.Report_TextArea.Value = {'O Ministério das Comunicações solicitou, por meio do Ofício nº XX/2021-MC, a realização de monitoração da faixa 450-470 MHz.'};
            app.Report_Label2 = uilabel(app.GridLayout19);
            app.Report_Label2.Text = 'Em relação à mascara textual da introdução:';
            app.Report_addButton = uiimage(app.Report_Grid1);
            app.Report_addButton.ImageClickedFcn = createCallbackFcn(app, @Report_addButtonPushed, true);
            app.Report_addButton.Enable = 'off';
            app.Report_addButton.Tooltip = {''};
            app.Report_addButton.ImageSource = 'adicionar3a.png';
            app.Report_deleteButton = uiimage(app.Report_Grid1);
            app.Report_deleteButton.ImageClickedFcn = createCallbackFcn(app, @Report_deleteButtonPushed, true);
            app.Report_deleteButton.Enable = 'off';
            app.Report_deleteButton.Tooltip = {''};
            app.Report_deleteButton.ImageSource = 'adicionar3d.png';
            app.Report_Grid1_3 = uigridlayout(app.GridLayout20);
            app.Report_Label4 = uilabel(app.Report_Grid1_3);
            app.Report_Label4.Text = 'Ocupação';
            app.Report_Label7 = uilabel(app.Report_Grid1_3);
            app.Report_Label7.Text = 'Identificação de emissões';
            app.GridLayout21 = uigridlayout(app.Report_Grid1_3);
            app.ParmetrosrelacionadosestimativadopisoderudoLabel = uilabel(app.GridLayout21);
            app.ParmetrosrelacionadosestimativadopisoderudoLabel.Enable = 'off';
            app.ParmetrosrelacionadosestimativadopisoderudoLabel.Text = 'Parâmetros relacionados à estimativa do piso de ruído:';
            app.Report_Panel2_2 = uipanel(app.GridLayout21);
            app.Report_Grid5_2 = uigridlayout(app.Report_Panel2_2);
            app.Report_Value6 = uidropdown(app.Report_Grid5_2);
            app.Report_Value6.Items = {'Mediana', 'Média'};
            app.Report_Value6.Enable = 'off';
            app.Report_Value6.Value = 'Mediana';
            app.FunodetendnciacentralLabel = uilabel(app.Report_Grid5_2);
            app.FunodetendnciacentralLabel.Enable = 'off';
            app.FunodetendnciacentralLabel.Text = 'Função de tendência central:';
            app.AmostrasdescartadasLabel = uilabel(app.Report_Grid5_2);
            app.AmostrasdescartadasLabel.Enable = 'off';
            app.AmostrasdescartadasLabel.Text = 'Amostras descartadas (%):';
            app.Report_Value7 = uispinner(app.Report_Grid5_2);
            app.Report_Value7.Limits = [0 10];
            app.Report_Value7.Enable = 'off';
            app.AmostrasteisLabel = uilabel(app.Report_Grid5_2);
            app.AmostrasteisLabel.Enable = 'off';
            app.AmostrasteisLabel.Text = 'Amostras úteis (%):';
            app.Report_Value8 = uispinner(app.Report_Grid5_2);
            app.Report_Value8.Limits = [10 90];
            app.Report_Value8.Enable = 'off';
            app.Report_Value8.Value = 20;
            app.ThresholdDropDownLabel_2 = uilabel(app.GridLayout21);
            app.ThresholdDropDownLabel_2.Enable = 'off';
            app.ThresholdDropDownLabel_2.Text = 'Threshold:';
            app.Playback_Value2_2 = uidropdown(app.GridLayout21);
            app.Playback_Value2_2.Items = {'Linear', 'Piso de ruído (Offset)'};
            app.Playback_Value2_2.ValueChangedFcn = createCallbackFcn(app, @Playback_Value2_2ValueChanged, true);
            app.Playback_Value2_2.Enable = 'off';
            app.Playback_Value2_2.Value = 'Linear';
            app.Report_Value4 = uispinner(app.GridLayout21);
            app.Report_Value4.Limits = [1 60];
            app.Report_Value4.Enable = 'off';
            app.Report_Value4.Value = 60;
            app.TempodeintegraoLabel = uilabel(app.GridLayout21);
            app.TempodeintegraoLabel.Enable = 'off';
            app.TempodeintegraoLabel.Text = 'Tempo de integração:';
            app.ValorLabel_2 = uilabel(app.GridLayout21);
            app.ValorLabel_2.Enable = 'off';
            app.ValorLabel_2.Text = 'Valor:';
            app.Playback_Value3_2 = uispinner(app.GridLayout21);
            app.Playback_Value3_2.RoundFractionalValues = 'on';
            app.Playback_Value3_2.ValueDisplayFormat = '%d';
            app.Playback_Value3_2.Enable = 'off';
            app.Playback_Value3_2.Value = -80;
            app.Playback_Unit1_2 = uilabel(app.GridLayout21);
            app.Playback_Unit1_2.Enable = 'off';
            app.Playback_Unit1_2.Text = 'dBm';
            app.Playback_Value8_2 = uidropdown(app.GridLayout21);
            app.Playback_Value8_2.Items = {'68', '95', '99'};
            app.Playback_Value8_2.Enable = 'off';
            app.Playback_Value8_2.Value = '95';
            app.NveldeconfianaLabel_2 = uilabel(app.GridLayout21);
            app.NveldeconfianaLabel_2.Enable = 'off';
            app.NveldeconfianaLabel_2.Text = 'Nível de confiança (%):';
            app.OffSetLabel_2 = uilabel(app.GridLayout21);
            app.OffSetLabel_2.Enable = 'off';
            app.OffSetLabel_2.Text = 'OffSet:';
            app.Report_Value5 = uispinner(app.GridLayout21);
            app.Report_Value5.Limits = [5 12];
            app.Report_Value5.Enable = 'off';
            app.Report_Value5.Value = 12;
            app.Playback_Unit1_3 = uilabel(app.GridLayout21);
            app.Playback_Unit1_3.Enable = 'off';
            app.Playback_Unit1_3.Text = 'minutos';
            app.GridLayout15 = uigridlayout(app.Report_Grid1_3);
            app.LarguramnimaocupadaLabel_2 = uilabel(app.GridLayout15);
            app.LarguramnimaocupadaLabel_2.Enable = 'off';
            app.LarguramnimaocupadaLabel_2.Text = {'Largura mínima'; 'ocupada:'};
            app.Report_Value11 = uispinner(app.GridLayout15);
            app.Report_Value11.Step = 10;
            app.Report_Value11.Limits = [0 Inf];
            app.Report_Value11.RoundFractionalValues = 'on';
            app.Report_Value11.ValueDisplayFormat = '%.0f';
            app.Report_Value11.Enable = 'off';
            app.Report_Value11.Value = 10;
            app.Report_Unit11 = uilabel(app.GridLayout15);
            app.Report_Unit11.Enable = 'off';
            app.Report_Unit11.Text = 'kHz';
            app.DistnciamnimaentrepicosLabel_2 = uilabel(app.GridLayout15);
            app.DistnciamnimaentrepicosLabel_2.Enable = 'off';
            app.DistnciamnimaentrepicosLabel_2.Text = {'Distância mínima '; 'entre picos:'};
            app.Report_Value12 = uispinner(app.GridLayout15);
            app.Report_Value12.Step = 10;
            app.Report_Value12.Limits = [0 Inf];
            app.Report_Value12.RoundFractionalValues = 'on';
            app.Report_Value12.ValueDisplayFormat = '%.0f';
            app.Report_Value12.Enable = 'off';
            app.Report_Value12.Value = 25;
            app.Report_Unit12 = uilabel(app.GridLayout15);
            app.Report_Unit12.Enable = 'off';
            app.Report_Unit12.Text = 'kHz';
            app.ClassificaodafaixaLabel = uilabel(app.GridLayout15);
            app.ClassificaodafaixaLabel.Enable = 'off';
            app.ClassificaodafaixaLabel.Text = 'Classificação da faixa:';
            app.Report_Value6_2 = uidropdown(app.GridLayout15);
            app.Report_Value6_2.Items = {'Genérico', 'Radiocomunicação', 'Radiodifusão (FM)', 'Radiodifusão (TV)'};
            app.Report_Value6_2.ValueChangedFcn = createCallbackFcn(app, @Report_Value6_2ValueChanged2, true);
            app.Report_Value6_2.Enable = 'off';
            app.Report_Value6_2.Value = 'Radiodifusão (FM)';
            app.EmrelaocurvadetendnciacentralLabel = uilabel(app.GridLayout15);
            app.EmrelaocurvadetendnciacentralLabel.Enable = 'off';
            app.EmrelaocurvadetendnciacentralLabel.Text = 'Em relação à curva de tendência central:';
            app.Panel_23 = uipanel(app.GridLayout15);
            app.GridLayout22 = uigridlayout(app.Panel_23);
            app.Report_Value9 = uidropdown(app.GridLayout22);
            app.Report_Value9.Items = {'Média', 'Mediana'};
            app.Report_Value9.Enable = 'off';
            app.Report_Value9.Value = 'Mediana';
            app.FunodetendnciacentralLabel_2 = uilabel(app.GridLayout22);
            app.FunodetendnciacentralLabel_2.Enable = 'off';
            app.FunodetendnciacentralLabel_2.Text = 'Função de tendência central:';
            app.Report_Value10 = uispinner(app.GridLayout22);
            app.Report_Value10.Step = 10;
            app.Report_Value10.Limits = [1 Inf];
            app.Report_Value10.RoundFractionalValues = 'on';
            app.Report_Value10.ValueDisplayFormat = '%.0f';
            app.Report_Value10.Enable = 'off';
            app.Report_Value10.Value = 10;
            app.ProeminnciamnimaLabel_2 = uilabel(app.GridLayout22);
            app.ProeminnciamnimaLabel_2.Enable = 'off';
            app.ProeminnciamnimaLabel_2.Text = {'Proeminência '; 'mínima:'};
            app.Report_Unit10 = uilabel(app.GridLayout22);
            app.Report_Unit10.Enable = 'off';
            app.Report_Unit10.Text = 'dB';
            app.Panel_24 = uipanel(app.GridLayout15);
            app.GridLayout23 = uigridlayout(app.Panel_24);
            app.Report_Value13 = uispinner(app.GridLayout23);
            app.Report_Value13.Step = 10;
            app.Report_Value13.Limits = [1 Inf];
            app.Report_Value13.RoundFractionalValues = 'on';
            app.Report_Value13.ValueDisplayFormat = '%.0f';
            app.Report_Value13.Enable = 'off';
            app.Report_Value13.Value = 30;
            app.ProeminnciamnimaLabel_3 = uilabel(app.GridLayout23);
            app.ProeminnciamnimaLabel_3.Enable = 'off';
            app.ProeminnciamnimaLabel_3.Text = {'Proeminência '; 'mínima:'};
            app.Report_Unit13 = uilabel(app.GridLayout23);
            app.Report_Unit13.Enable = 'off';
            app.Report_Unit13.Text = 'dB';
            app.Report_Value16 = uispinner(app.GridLayout23);
            app.Report_Value16.Step = 10;
            app.Report_Value16.Limits = [0 Inf];
            app.Report_Value16.RoundFractionalValues = 'on';
            app.Report_Value16.ValueDisplayFormat = '%.0f';
            app.Report_Value16.Enable = 'off';
            app.Report_Value16.Value = 1;
            app.MnimaocupaomximaLabel = uilabel(app.GridLayout23);
            app.MnimaocupaomximaLabel.Enable = 'off';
            app.MnimaocupaomximaLabel.Text = 'Mínima ocupação máxima (%):';
            app.EmrelaocurvadeMaxHoldLabel = uilabel(app.GridLayout15);
            app.EmrelaocurvadeMaxHoldLabel.Enable = 'off';
            app.EmrelaocurvadeMaxHoldLabel.Text = 'Em relação à curva de MaxHold:';
            app.Label_32 = uilabel(app.GridLayout15);
            app.Label_32.Enable = 'off';
            app.Label_32.Text = 'Caracterizada por emissões com alta ocupação, cujas portadoras são moduladas em frequência e os canais espaçados de 200 kHz.';
            app.Report_playButton_2 = uibutton(app.Grid3_Report, 'push');
            app.Report_playButton_2.Icon = 'RF02.png';
            app.Report_playButton_2.Enable = 'off';
            app.Report_playButton_2.Text = 'Gerar prévia';
            app.Report_recButton_2 = uibutton(app.Grid3_Report, 'state');
            app.Report_recButton_2.Enable = 'off';
            app.Report_recButton_2.Tooltip = {''};
            app.Report_recButton_2.Icon = 'save3.png';
            app.Report_recButton_2.Text = '';
            % Show the figure after all components are created
            app.MainApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainApp)
        end
    end
end