classdef winCredential < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Grid1                 matlab.ui.container.GridLayout
        Button                matlab.ui.control.Button
        Panel                 matlab.ui.container.Panel
        Grid2                 matlab.ui.container.GridLayout
        Password              matlab.ui.control.EditField
        SenhaEditFieldLabel   matlab.ui.control.Label
        Login                 matlab.ui.control.EditField
        UsurioEditFieldLabel  matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        Title                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp
        
        PWD = '';
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Position)
            
            x0 = fix(Position(1)+Position(3)/2-164);
            x1 = fix(Position(2)+Position(4)/2-91);
            app.UIFigure.Position(1:2) = [x0, x1];

            app.CallingApp = mainapp;
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin2 = [];
            delete(app)
            
        end

        % Value changing function: Password
        function PasswordValueChanging(app, event)

            ind = numel(event.Value);
            
            if ind > numel(app.PWD)
                app.PWD(end+1) = event.Value(end);
                app.Password.Value(ind) = '*';
            else
                app.PWD(end) = '';
            end
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            if ~isempty(app.Login.Value) & ~isempty(app.Password.Value)
                uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Message', 'Verificando as credenciais...');
                
                try
                    pause(3) % Simulando a conexão via API
                    
                    app.CallingApp.auxWin_Fiscaliza(app.Login.Value, app.PWD)
                    closeFcn(app)
                catch ME
                    uialert(app.UIFigure, ME.message, 'Fiscaliza', 'Icon', 'error')
                end
            
            else
                uialert(app.UIFigure, 'Favor inserir as suas credenciais.', 'Fiscaliza', 'Icon', 'error')
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise';
            app.UIFigure.Icon = 'Bluemine.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Title = uilabel(app.Grid1);
            app.Title.Text = {'Fiscaliza'; 'Sistema de Contole e Gestão da Fiscalização'};
            app.Label = uilabel(app.Grid1);
            app.Label.Text = 'Credenciais';
            app.Panel = uipanel(app.Grid1);
            app.Grid2 = uigridlayout(app.Panel);
            app.UsurioEditFieldLabel = uilabel(app.Grid2);
            app.UsurioEditFieldLabel.Text = 'Usuário:';
            app.Login = uieditfield(app.Grid2, 'text');
            app.SenhaEditFieldLabel = uilabel(app.Grid2);
            app.SenhaEditFieldLabel.Text = 'Senha:';
            app.Password = uieditfield(app.Grid2, 'text');
            app.Password.ValueChangingFcn = createCallbackFcn(app, @PasswordValueChanging, true);
            app.Button = uibutton(app.Grid1, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'Media_PlayWhite.png';
            app.Button.Text = 'Confirmar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winCredential(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end