classdef winFiscaliza < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Grid3_Report                   matlab.ui.container.GridLayout
        INSP_GR08_2021_0017Label       matlab.ui.control.Label
        AspectosgeraisdafiscalizaoLabel  matlab.ui.control.Label
        AspectostcnicosdafiscalizaoLabel  matlab.ui.control.Label
        Panel_4                        matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        GridLayout11                   matlab.ui.container.GridLayout
        MunicpioEditField              matlab.ui.control.EditField
        MunicpioEditFieldLabel         matlab.ui.control.Label
        CoordenadasgeogrficasLabel     matlab.ui.control.Label
        Panel_9                        matlab.ui.container.Panel
        GridLayout13                   matlab.ui.container.GridLayout
        LatitudeEditFieldLabel         matlab.ui.control.Label
        LatitudeEditField              matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel        matlab.ui.control.Label
        LongitudeEditField             matlab.ui.control.NumericEditField
        RelatriodemonitoraoTextAreaLabel  matlab.ui.control.Label
        RelatriodemonitoraoTextArea    matlab.ui.control.TextArea
        GridLayout12                   matlab.ui.container.GridLayout
        LimitesdasfaixasdefrequnciaLabel  matlab.ui.control.Label
        Panel_10                       matlab.ui.container.Panel
        GridLayout14                   matlab.ui.container.GridLayout
        InferiorEditFieldLabel         matlab.ui.control.Label
        InferiorEditField              matlab.ui.control.NumericEditField
        MHzLabel                       matlab.ui.control.Label
        SuperiorEditFieldLabel         matlab.ui.control.Label
        SuperiorEditField              matlab.ui.control.NumericEditField
        MHzLabel_2                     matlab.ui.control.Label
        EmissesidentificadasEditField  matlab.ui.control.NumericEditField
        EmissesidentificadasEditFieldLabel  matlab.ui.control.Label
        EmissesnolicenciadasEditFieldLabel  matlab.ui.control.Label
        EmissesnolicenciadasEditField  matlab.ui.control.NumericEditField
        GridLayout15                   matlab.ui.container.GridLayout
        ServiosdetelecomunicaesrelacionadossemissesTextArea  matlab.ui.control.TextArea
        ServiosdetelecomunicaesrelacionadossemissesidentificadasLabel  matlab.ui.control.Label
        TabelaainserirnohistricoTextAreaLabel  matlab.ui.control.Label
        TabelaainserirnohistricoTextArea  matlab.ui.control.TextArea
        hiperlinkButton                matlab.ui.control.Button
        Report_playButton              matlab.ui.control.Button
        FiscalizaSistemadeContoleeGestodaFiscalizaoLabel  matlab.ui.control.Label
        CredenciaisLabel               matlab.ui.control.Label
        Panel_5                        matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        Report_playButton_2            matlab.ui.control.Button
        UsurioEditFieldLabel           matlab.ui.control.Label
        Login                          matlab.ui.control.EditField
        SenhaEditFieldLabel            matlab.ui.control.Label
        Password                       matlab.ui.control.EditField
        Inspeon123456relacionadaAon000001Label  matlab.ui.control.Label
        Panel_8                        matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout7                    matlab.ui.container.GridLayout
        DescriodainspeoTextArea        matlab.ui.control.TextArea
        DescriodainspeoTextAreaLabel   matlab.ui.control.Label
        EstimativasdehorasLabel        matlab.ui.control.Label
        Panel_3                        matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        PreparaoEditField              matlab.ui.control.NumericEditField
        PreparaoEditFieldLabel         matlab.ui.control.Label
        DeslocamentoEditField          matlab.ui.control.NumericEditField
        DeslocamentoEditFieldLabel     matlab.ui.control.Label
        ExecuoEditFieldLabel           matlab.ui.control.Label
        ExecuoEditField                matlab.ui.control.NumericEditField
        ConclusoEditField              matlab.ui.control.NumericEditField
        ConclusoEditFieldLabel         matlab.ui.control.Label
        GridLayout8                    matlab.ui.container.GridLayout
        Report_occType                 matlab.ui.control.DropDown
        SituaoLabel                    matlab.ui.control.Label
        PrioridadeLabel                matlab.ui.control.Label
        Report_occType_2               matlab.ui.control.DropDown
        Report_occType_3               matlab.ui.control.DropDown
        ClasseLabel                    matlab.ui.control.Label
        TipoLabel                      matlab.ui.control.Label
        Report_occType_4               matlab.ui.control.DropDown
        AnodeexecuoEditField           matlab.ui.control.EditField
        AnodeexecuoEditFieldLabel      matlab.ui.control.Label
        ProcessoSEIEditField           matlab.ui.control.EditField
        ProcessoSEIEditFieldLabel      matlab.ui.control.Label
        GridLayout9                    matlab.ui.container.GridLayout
        FiscaisenvolvidosnafiscalizaoLabel  matlab.ui.control.Label
        Tree3_2                        matlab.ui.container.Tree
        Fiscal1Node                    matlab.ui.container.TreeNode
        Fiscal2Node                    matlab.ui.container.TreeNode
        Fiscal3Node                    matlab.ui.container.TreeNode
        FiscalresponsvelDropDown       matlab.ui.control.DropDown
        FiscalresponsvelDropDownLabel  matlab.ui.control.Label
        AtualizadoporFulanodeTalnodia12022021s120222Label  matlab.ui.control.Label
        GridLayout10                   matlab.ui.container.GridLayout
        PrevisodeincioLabel            matlab.ui.control.Label
        Report_DatePicker1             matlab.ui.control.DatePicker
        DatalimiteLabel                matlab.ui.control.Label
        Report_DatePicker1_2           matlab.ui.control.DatePicker
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        CallingApp
        
        Issue = '123456';
        
        USR = '';
        PWD = '';
        
    end
    
    methods (Access = private)
        
        function Fcn_status(app)
            
            switch status_id
                case 'Rascunho'
                    app.Report_occType.Items = {'Rascunho',            ...
                                                'Aguardando Execução', ...
                                                'Em andamento',        ...
                                                'Relatando'};
                case 'Aguardando Execução'
                    app.Report_occType.Items = {'Aguardando Execução', ...
                                                'Em andamento',        ...
                                                'Relatando'};
                case 'Em andamento'
                    app.Report_occType.Items = {'Em andamento',        ...
                                                'Relatando'};
                case 'Relatando'
                    app.Report_occType.Items = {'Relatando',           ...
                                                'Relatada'};
                case 'Relatada'
                    app.Report_occType.Items = {'Relatada',            ...
                                                'Conferida'};
                case 'Conferida'
                    app.Report_occType.Items = {'Conferida'};
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.Grid3_Report.ColumnWidth = {24,24,250,0,0};
            app.Grid3_Report.RowHeight   = {30,22,64,0,0,0,0,0,0,0,0};
            
            app.UIFigure.Position(3:4) = [328, 166];

            mainMonitor = get(0, 'MonitorPositions');
            mainMonitor = mainMonitor(mainMonitor(:,1) == 1,:);
            
            app.UIFigure.Position(1:2) = [round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.CallingApp = mainapp;           
            
            
%             d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on');
%             
%             ISSUE = int64(app.Report_Issue.Value);
%             try
%                 if app.FiscalizaCount.Value == 0
%                     d.Message = sprintf('Inspeção nº %d\n\nPasso %d de 4.\nCriação do recurso de comunicação...', ISSUE, (app.FiscalizaCount.Value+1));
%                     app.mod = py.importlib.import_module('pyRedmine');
%                     
%                     d.Message = sprintf('Inspeção nº %d\n\nPasso %d de 4.\nValidação do login e senha...', ISSUE, (app.FiscalizaCount.Value+1));
%                     app.redmineObj = app.mod.connFcn('eric', '123456');
%                     d.Message = sprintf('Inspeção nº %d\n\nPasso %d de 4.\nEm edição campos do formulário para alterar o estado de "Rascunho" para "Aguardando execução".', ISSUE, (app.FiscalizaCount.Value+1));
%                     app.mod.setFcn(app.redmineObj, ISSUE, 1, py.list({'11', 'Primeira escrita...'}),                      ...
%                                  py.list({'{"valor":"Técnica","texto":"Técnica"}',                                    ...
%                                  '{"valor":"Uso do Espectro - Monitoração","texto":"Uso do Espectro - Monitoração"}', ...
%                                  'Uma descrição qualquer...', '174', py.list({'174', '302', '1207'})}));
%                 
%                 elseif app.FiscalizaCount.Value == 1
%                     d.Message = sprintf('Inspeção nº %d\n\nPasso %d de 4.\nEm edição campos do formulário para alterar o estado de "Aguardando execução" para "Em andamento".', ISSUE, (app.FiscalizaCount.Value+1));
%                     app.mod.setFcn(app.redmineObj, ISSUE, 2, py.list({'13', 'Segunda escrita....'}), ...
%                                  'C:/Users/Administrador/OneDrive - ANATEL/appAnalise/Temp/Report_2021.03.08_T19.52.18_FinalVersion.html');
%                     
%                 elseif app.FiscalizaCount.Value == 2
%                     d.Message = sprintf('Inspeção nº %d\n\nPasso %d de 4.\nEm edição campos do formulário para alterar o estado de "Em andamento" para "Relatando".', ISSUE, (app.FiscalizaCount.Value+1));
%                     app.mod.setFcn(app.redmineObj, ISSUE, 3, py.list({'14', 'Terceira escrita...', '2021-01-01', '2021-03-08'}), py.list({'108', 'MHz', '137', 'MHz'}));
%     
%                 elseif app.FiscalizaCount.Value == 3
%                     d.Message = sprintf('Inspeção nº %d\n\nPasso %d de 4.\nEm edição campos do formulário para alterar o estado de "Relatando" para "Relatada".', ISSUE, (app.FiscalizaCount.Value+1));
%                     app.mod.setFcn(app.redmineObj, ISSUE, 4, py.list({'15', 'Quarta escrita.....'}),                            ...
%                                  py.list({'{"valor":"BA/Salvador","texto":"BA/Salvador"}',                                      ...
%                                  '{"valor":"507 - RESTRITO - MÓVEL AERONÁUTICO","texto":"507 - RESTRITO - MÓVEL AERONÁUTICO"}', ...
%                                  '10', '2', '0', '1', '2', '3', '-12.123456', '-38.123456', 'Não se aplica PF - demanda específica', 'Não', '1'}));
%                     
%                 end
%                 
%                 
%                 if app.FiscalizaCount.Value == 3
%                     app.FiscalizaCount.Value = 0;
%                 else
%                     app.FiscalizaCount.Value = app.FiscalizaCount.Value + 1;
%                 end
%                 
%             catch ME
%                 uialert(app.MainApp, sprintf('ME.identifier: %s\nME.message: %s.', ME.identifier, ME.message), 'Alerta!', 'Icon', 'error')
%                 
%             end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin2 = [];
            delete(app)
            
        end

        % Value changed function: Report_occType_3
        function Report_occType_3ValueChanged(app, event)
            
            switch app.Report_occType_3.Value
                case 'Tributária'
                    app.Report_occType_4.Items = {'FUST - INTERCONEXÃO',    ...
                                                  'Situação Cadastral',     ...
                                                  'FUNTELL - INTERCONEXÃO', ...
                                                  'FUNTELL 0500',           ...
                                                  'FUST',                   ...
                                                  'FUNTELL'};
                    
                case 'Técnica'
                    app.Report_occType_4.Items = {'Outorga - Aspectos não Técnicos', ...
                                                  'Medição de CEMRF (RNI)',          ...
                                                  'Outorga - Aspectos Técnicos',     ...
                                                  'TV Digital',                      ...
                                                  'Uso do Espectro - Monitoração',   ...
                                                  'Uso do Espectro - Não Outorgado', ...
                                                  'Certificação'};
                    
                case 'Serviço'
                    app.Report_occType_4.Items = {'Serviço', ...
                                                  'Backhaul - PPP'};
                    
            end
            
        end

        % Button pushed function: Report_playButton_2
        function Report_playButton_2Pushed(app, event)
            
            uiprogressdlg(app.UIFigure, 'Indeterminate', 'on');
            
            % Integração 1. Aciona Fiscaliza e com as credenciais
            % (login/senha) e o número da issue, analisa se a issue 
            % é uma Inspeção e se o usuário possui perfil para editá-la.


            set(app.Grid3_Report, 'ColumnWidth', {24,24,250,'1x',110}, ...
                                  'RowHeight', {0,0,0,22,15,15,22,200,22,'1x',24});
            
            mainMonitor = get(0, 'MonitorPositions');
            mainMonitor = mainMonitor(mainMonitor(:,1) == 1,:);
            
            app.UIFigure.Position = [round((mainMonitor(3)-852)/2), ...
                                     round((mainMonitor(4)-622)/2), ...
                                     852, 622];
            
        end

        % Value changing function: Password
        function PasswordValueChanging(app, event)

            ind = numel(event.Value);
            
            if ind > numel(app.PWD)
                app.PWD(end+1) = event.Value(end);
                app.Password.Value(ind) = '*';
            else
                app.PWD(end) = '';
            end
            
        end

        % Button pushed function: Report_playButton
        function Report_playButtonPushed(app, event)
            
            startupFcn(app)
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            
            [file, path] = uigetfile(['Report_*_' app.Issue '.html']);
            
            if file
                app.RelatriodemonitoraoTextArea.Value = fullfile(path, file);
            end
            
        end

        % Button pushed function: hiperlinkButton
        function hiperlinkButtonPushed(app, event)
            
            rptview(char(app.RelatriodemonitoraoTextArea.Value))
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise: Fiscaliza';
            app.UIFigure.Icon = 'Bluemine.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid3_Report = uigridlayout(app.UIFigure);
            app.INSP_GR08_2021_0017Label = uilabel(app.Grid3_Report);
            app.INSP_GR08_2021_0017Label.Text = 'INSP_GR08_2021_0017';
            app.AspectosgeraisdafiscalizaoLabel = uilabel(app.Grid3_Report);
            app.AspectosgeraisdafiscalizaoLabel.Text = 'Aspectos gerais da fiscalização.';
            app.AspectostcnicosdafiscalizaoLabel = uilabel(app.Grid3_Report);
            app.AspectostcnicosdafiscalizaoLabel.Text = 'Aspectos técnicos da fiscalização.';
            app.Panel_4 = uipanel(app.Grid3_Report);
            app.GridLayout4 = uigridlayout(app.Panel_4);
            app.GridLayout11 = uigridlayout(app.GridLayout4);
            app.MunicpioEditField = uieditfield(app.GridLayout11, 'text');
            app.MunicpioEditField.Value = 'Salvador/BA';
            app.MunicpioEditFieldLabel = uilabel(app.GridLayout11);
            app.MunicpioEditFieldLabel.Text = 'Município:';
            app.CoordenadasgeogrficasLabel = uilabel(app.GridLayout11);
            app.CoordenadasgeogrficasLabel.Text = 'Coordenadas geográficas:';
            app.Panel_9 = uipanel(app.GridLayout11);
            app.GridLayout13 = uigridlayout(app.Panel_9);
            app.LatitudeEditFieldLabel = uilabel(app.GridLayout13);
            app.LatitudeEditFieldLabel.Text = 'Latitude:';
            app.LatitudeEditField = uieditfield(app.GridLayout13, 'numeric');
            app.LatitudeEditField.ValueDisplayFormat = '%.6f';
            app.LatitudeEditField.Value = -12.123456;
            app.LongitudeEditFieldLabel = uilabel(app.GridLayout13);
            app.LongitudeEditFieldLabel.Text = 'Longitude:';
            app.LongitudeEditField = uieditfield(app.GridLayout13, 'numeric');
            app.LongitudeEditField.ValueDisplayFormat = '%.6f';
            app.LongitudeEditField.Value = -38.123456;
            app.RelatriodemonitoraoTextAreaLabel = uilabel(app.GridLayout4);
            app.RelatriodemonitoraoTextAreaLabel.Text = 'Relatório de monitoração:';
            app.RelatriodemonitoraoTextArea = uitextarea(app.GridLayout4);
            app.RelatriodemonitoraoTextArea.Editable = 'off';
            app.GridLayout12 = uigridlayout(app.GridLayout4);
            app.LimitesdasfaixasdefrequnciaLabel = uilabel(app.GridLayout12);
            app.LimitesdasfaixasdefrequnciaLabel.Text = 'Limites das faixas de frequência:';
            app.Panel_10 = uipanel(app.GridLayout12);
            app.GridLayout14 = uigridlayout(app.Panel_10);
            app.InferiorEditFieldLabel = uilabel(app.GridLayout14);
            app.InferiorEditFieldLabel.Text = 'Inferior:';
            app.InferiorEditField = uieditfield(app.GridLayout14, 'numeric');
            app.InferiorEditField.ValueDisplayFormat = '%.6f';
            app.InferiorEditField.Value = 54;
            app.MHzLabel = uilabel(app.GridLayout14);
            app.MHzLabel.Text = 'MHz';
            app.SuperiorEditFieldLabel = uilabel(app.GridLayout14);
            app.SuperiorEditFieldLabel.Text = 'Superior:';
            app.SuperiorEditField = uieditfield(app.GridLayout14, 'numeric');
            app.SuperiorEditField.ValueDisplayFormat = '%.6f';
            app.SuperiorEditField.Value = 798;
            app.MHzLabel_2 = uilabel(app.GridLayout14);
            app.MHzLabel_2.Text = 'MHz';
            app.EmissesidentificadasEditField = uieditfield(app.GridLayout12, 'numeric');
            app.EmissesidentificadasEditField.Value = 64;
            app.EmissesidentificadasEditFieldLabel = uilabel(app.GridLayout12);
            app.EmissesidentificadasEditFieldLabel.Text = 'Emissões identificadas:';
            app.EmissesnolicenciadasEditFieldLabel = uilabel(app.GridLayout12);
            app.EmissesnolicenciadasEditFieldLabel.Text = 'Emissões não licenciadas:';
            app.EmissesnolicenciadasEditField = uieditfield(app.GridLayout12, 'numeric');
            app.EmissesnolicenciadasEditField.Value = 8;
            app.GridLayout15 = uigridlayout(app.GridLayout4);
            app.ServiosdetelecomunicaesrelacionadossemissesTextArea = uitextarea(app.GridLayout15);
            app.ServiosdetelecomunicaesrelacionadossemissesTextArea.Value = {'019 - Limitado Privado'; '700 - Radiodifusão Sonora em FM'};
            app.ServiosdetelecomunicaesrelacionadossemissesidentificadasLabel = uilabel(app.GridLayout15);
            app.ServiosdetelecomunicaesrelacionadossemissesidentificadasLabel.Text = 'Serviços de telecomunicações relacionados às emissões identificadas:';
            app.TabelaainserirnohistricoTextAreaLabel = uilabel(app.GridLayout15);
            app.TabelaainserirnohistricoTextAreaLabel.Text = 'Informações acerca das faixas de frequência a inserir no histórico da inspeção:';
            app.TabelaainserirnohistricoTextArea = uitextarea(app.GridLayout15);
            app.TabelaainserirnohistricoTextArea.Value = {'54, 88, 4, 1'; '72, 108, 26, 4'; '174, 216, 14, 2'; '470, 798, 20, 1'};
            app.hiperlinkButton = uibutton(app.GridLayout4, 'push');
            app.hiperlinkButton.ButtonPushedFcn = createCallbackFcn(app, @hiperlinkButtonPushed, true);
            app.hiperlinkButton.Text = 'hiperlink';
            app.Report_playButton = uibutton(app.Grid3_Report, 'push');
            app.Report_playButton.ButtonPushedFcn = createCallbackFcn(app, @Report_playButtonPushed, true);
            app.Report_playButton.Icon = 'Edit2.png';
            app.Report_playButton.Text = 'Editar';
            app.FiscalizaSistemadeContoleeGestodaFiscalizaoLabel = uilabel(app.Grid3_Report);
            app.FiscalizaSistemadeContoleeGestodaFiscalizaoLabel.Text = {'Fiscaliza'; 'Sistema de Contole e Gestão da Fiscalização'};
            app.CredenciaisLabel = uilabel(app.Grid3_Report);
            app.CredenciaisLabel.Text = 'Credenciais';
            app.Panel_5 = uipanel(app.Grid3_Report);
            app.GridLayout6 = uigridlayout(app.Panel_5);
            app.Report_playButton_2 = uibutton(app.GridLayout6, 'push');
            app.Report_playButton_2.ButtonPushedFcn = createCallbackFcn(app, @Report_playButton_2Pushed, true);
            app.Report_playButton_2.Icon = 'Media_Play.png';
            app.Report_playButton_2.Text = 'Confirmar';
            app.UsurioEditFieldLabel = uilabel(app.GridLayout6);
            app.UsurioEditFieldLabel.Text = 'Usuário:';
            app.Login = uieditfield(app.GridLayout6, 'text');
            app.SenhaEditFieldLabel = uilabel(app.GridLayout6);
            app.SenhaEditFieldLabel.Text = 'Senha:';
            app.Password = uieditfield(app.GridLayout6, 'text');
            app.Password.ValueChangingFcn = createCallbackFcn(app, @PasswordValueChanging, true);
            app.Inspeon123456relacionadaAon000001Label = uilabel(app.Grid3_Report);
            app.Inspeon123456relacionadaAon000001Label.Text = 'Inspeção nº 123456, relacionada à Ação nº 000001';
            app.Panel_8 = uipanel(app.Grid3_Report);
            app.GridLayout2 = uigridlayout(app.Panel_8);
            app.GridLayout7 = uigridlayout(app.GridLayout2);
            app.DescriodainspeoTextArea = uitextarea(app.GridLayout7);
            app.DescriodainspeoTextArea.Value = {'Monitoração das faixas X, Y e Z no município de Salvador/BA no âmbito da demanda de fiscalização registrada na Ação nº 000001.'};
            app.DescriodainspeoTextAreaLabel = uilabel(app.GridLayout7);
            app.DescriodainspeoTextAreaLabel.Text = 'Descrição da inspeção:';
            app.EstimativasdehorasLabel = uilabel(app.GridLayout7);
            app.EstimativasdehorasLabel.Text = 'Estimativas de horas:';
            app.Panel_3 = uipanel(app.GridLayout7);
            app.GridLayout3 = uigridlayout(app.Panel_3);
            app.PreparaoEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PreparaoEditField.Limits = [0 Inf];
            app.PreparaoEditField.RoundFractionalValues = 'on';
            app.PreparaoEditField.ValueDisplayFormat = '%.0f';
            app.PreparaoEditFieldLabel = uilabel(app.GridLayout3);
            app.PreparaoEditFieldLabel.Text = 'Preparação:';
            app.DeslocamentoEditField = uieditfield(app.GridLayout3, 'numeric');
            app.DeslocamentoEditField.Limits = [0 Inf];
            app.DeslocamentoEditField.RoundFractionalValues = 'on';
            app.DeslocamentoEditField.ValueDisplayFormat = '%.0f';
            app.DeslocamentoEditFieldLabel = uilabel(app.GridLayout3);
            app.DeslocamentoEditFieldLabel.Text = 'Deslocamento:';
            app.ExecuoEditFieldLabel = uilabel(app.GridLayout3);
            app.ExecuoEditFieldLabel.Text = 'Execução:';
            app.ExecuoEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ExecuoEditField.Limits = [0 Inf];
            app.ExecuoEditField.RoundFractionalValues = 'on';
            app.ExecuoEditField.ValueDisplayFormat = '%.0f';
            app.ConclusoEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ConclusoEditField.Limits = [0 Inf];
            app.ConclusoEditField.RoundFractionalValues = 'on';
            app.ConclusoEditField.ValueDisplayFormat = '%.0f';
            app.ConclusoEditFieldLabel = uilabel(app.GridLayout3);
            app.ConclusoEditFieldLabel.Text = 'Conclusão:';
            app.GridLayout8 = uigridlayout(app.GridLayout2);
            app.Report_occType = uidropdown(app.GridLayout8);
            app.Report_occType.Items = {'Rascunho', 'Aguardando Execução', 'Cancelada'};
            app.Report_occType.Value = 'Aguardando Execução';
            app.SituaoLabel = uilabel(app.GridLayout8);
            app.SituaoLabel.Text = 'Situação:';
            app.PrioridadeLabel = uilabel(app.GridLayout8);
            app.PrioridadeLabel.Text = 'Prioridade:';
            app.Report_occType_2 = uidropdown(app.GridLayout8);
            app.Report_occType_2.Items = {'Baixa', 'Normal', 'Alta', 'Urgente', 'Imediata'};
            app.Report_occType_2.Value = 'Baixa';
            app.Report_occType_3 = uidropdown(app.GridLayout8);
            app.Report_occType_3.Items = {'Tributária', 'Técnica', 'Serviço'};
            app.Report_occType_3.ValueChangedFcn = createCallbackFcn(app, @Report_occType_3ValueChanged, true);
            app.Report_occType_3.Value = 'Técnica';
            app.ClasseLabel = uilabel(app.GridLayout8);
            app.ClasseLabel.Text = 'Classe:';
            app.TipoLabel = uilabel(app.GridLayout8);
            app.TipoLabel.Text = 'Tipo:';
            app.Report_occType_4 = uidropdown(app.GridLayout8);
            app.Report_occType_4.Items = {'Uso do Espectro - Monitoração'};
            app.Report_occType_4.Value = 'Uso do Espectro - Monitoração';
            app.AnodeexecuoEditField = uieditfield(app.GridLayout8, 'text');
            app.AnodeexecuoEditField.Editable = 'off';
            app.AnodeexecuoEditField.Value = '2021';
            app.AnodeexecuoEditFieldLabel = uilabel(app.GridLayout8);
            app.AnodeexecuoEditFieldLabel.Text = 'Ano de execução:';
            app.ProcessoSEIEditField = uieditfield(app.GridLayout8, 'text');
            app.ProcessoSEIEditField.Editable = 'off';
            app.ProcessoSEIEditField.Value = '53554.001111/2020-12';
            app.ProcessoSEIEditFieldLabel = uilabel(app.GridLayout8);
            app.ProcessoSEIEditFieldLabel.Text = 'Processo (SEI):';
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.FiscaisenvolvidosnafiscalizaoLabel = uilabel(app.GridLayout9);
            app.FiscaisenvolvidosnafiscalizaoLabel.Text = 'Fiscais envolvidos na fiscalização:';
            app.Tree3_2 = uitree(app.GridLayout9);
            app.Tree3_2.Multiselect = 'on';
            app.Fiscal1Node = uitreenode(app.Tree3_2);
            app.Fiscal1Node.Text = 'Fiscal 1';
            app.Fiscal2Node = uitreenode(app.Tree3_2);
            app.Fiscal2Node.Text = 'Fiscal 2';
            app.Fiscal3Node = uitreenode(app.Tree3_2);
            app.Fiscal3Node.Text = 'Fiscal 3';
            app.FiscalresponsvelDropDown = uidropdown(app.GridLayout9);
            app.FiscalresponsvelDropDown.Items = {};
            app.FiscalresponsvelDropDown.Enable = 'off';
            app.FiscalresponsvelDropDown.Value = {};
            app.FiscalresponsvelDropDownLabel = uilabel(app.GridLayout9);
            app.FiscalresponsvelDropDownLabel.Text = 'Fiscal responsável:';
            app.AtualizadoporFulanodeTalnodia12022021s120222Label = uilabel(app.Grid3_Report);
            app.AtualizadoporFulanodeTalnodia12022021s120222Label.Text = 'Atualizado por Fulano de Tal no dia 12/02/2021 às 12:02:22.';
            app.GridLayout10 = uigridlayout(app.Grid3_Report);
            app.PrevisodeincioLabel = uilabel(app.GridLayout10);
            app.PrevisodeincioLabel.Text = 'Previsão de início:';
            app.Report_DatePicker1 = uidatepicker(app.GridLayout10);
            app.Report_DatePicker1.DisplayFormat = 'dd/MMM/uuuu';
            app.DatalimiteLabel = uilabel(app.GridLayout10);
            app.DatalimiteLabel.Text = 'Data limite:';
            app.Report_DatePicker1_2 = uidatepicker(app.GridLayout10);
            app.Report_DatePicker1_2.DisplayFormat = 'dd/MMM/uuuu';
            app.Button_2 = uibutton(app.Grid3_Report, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = '62319c.png';
            app.Button_2.Text = '';
            app.Button = uibutton(app.Grid3_Report, 'push');
            app.Button.Icon = 'reloadIcon.png';
            app.Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winFiscaliza(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end