classdef winMaskDraw < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        appAnliseEdiodemscaraUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Button2                        matlab.ui.control.Button
        EdiodamscaraLabel              matlab.ui.control.Label
        Panel_6                        matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        THR_Right                      matlab.ui.control.NumericEditField
        LargurakHzEditFieldLabel       matlab.ui.control.Label
        BW                             matlab.ui.control.NumericEditField
        Frequency                      matlab.ui.control.NumericEditField
        FrequnciaMHzEditFieldLabel     matlab.ui.control.Label
        THR_Left                       matlab.ui.control.NumericEditField
        ThresholdabaixoeacimadaemissodBLabel  matlab.ui.control.Label
        LimitemximodeemissesainserirnamscaranovaLabel  matlab.ui.control.Label
        limiteEmissoes                 matlab.ui.control.NumericEditField
        EfetividadedesejadaLabel       matlab.ui.control.Label
        EfetividadedesejadaEditField   matlab.ui.control.NumericEditField
        CheckBox                       matlab.ui.control.CheckBox
        Tree                           matlab.ui.container.Tree
        GridLayout6                    matlab.ui.container.GridLayout
        File_Label2_3                  matlab.ui.control.Label
        File_Label2                    matlab.ui.control.Label
        LOGLabel                       matlab.ui.control.Label
        LOGTextArea                    matlab.ui.control.TextArea
        EfetividadeEditField_2Label    matlab.ui.control.Label
        EfetividadeEditField_2         matlab.ui.control.EditField
        MscaranovaTextAreaLabel        matlab.ui.control.Label
        MscaranovaTextArea             matlab.ui.control.TextArea
        EfetividadeEditFieldLabel      matlab.ui.control.Label
        EfetividadeEditField           matlab.ui.control.EditField
        Button3                        matlab.ui.control.Button
        GridLayout2                    matlab.ui.container.GridLayout
        NmerodetraosLabel_2            matlab.ui.control.Label
        Samples                        matlab.ui.control.NumericEditField
        rfeye                          matlab.ui.control.Label
        ThreadID                       matlab.ui.control.Label
        Button1                        matlab.ui.control.Button
        MscarainicialTextAreaLabel     matlab.ui.control.Label
        MscarainicialTextArea          matlab.ui.control.TextArea
        ContextMenu                    matlab.ui.container.ContextMenu
        IncluirMenu                    matlab.ui.container.Menu
        ExcluirMenu                    matlab.ui.container.Menu
        EditarMenu                     matlab.ui.container.Menu
    end

    
    properties
        
        CallingApp
        specData
        maskParam
        
        Mode = 0;
        passoCount = 0 % Description
               
    end
    
    properties (Access = private)
        winDialog % Description
    end
        
    
    methods (Access = private)
        
        function Layout(app)
            
            switch app.Mode
                case 0 % Sem edição
                    set(app.Frequency, 'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                    set(app.BW,        'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                    set(app.THR_Left,  'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                    set(app.THR_Right, 'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                    set(app.EfetividadedesejadaEditField, 'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                    set(app.limiteEmissoes, 'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                    
                    app.CheckBox.Value = 0;
                    
                    app.EfetividadedesejadaLabel.Enable     = 0;
                    app.EfetividadedesejadaEditField.Enable = 0;
                    
                    app.LimitemximodeemissesainserirnamscaranovaLabel.Enable = 0;
                    app.limiteEmissoes.Enable = 0;
                    
                    app.Button2.Enable = 0;
                    app.Button2.Text = 'Confirmar edição';
                
                case 1 % Editar manualmente
                    set(app.Frequency, 'Editable', 1, 'BackgroundColor', [1,1,1])
                    set(app.BW,        'Editable', 1, 'BackgroundColor', [1,1,1])
                    set(app.THR_Left,  'Editable', 1, 'BackgroundColor', [1,1,1])
                    set(app.THR_Right, 'Editable', 1, 'BackgroundColor', [1,1,1])
                    
                    app.CheckBox.Value = 0;
                    
                    app.EfetividadedesejadaLabel.Enable     = 0;
                    app.EfetividadedesejadaEditField.Enable = 0;
                    
                    app.LimitemximodeemissesainserirnamscaranovaLabel.Enable = 0;
                    app.limiteEmissoes.Enable = 0;
                    
                    app.Button2.Enable = 1;                 
                    app.Button2.Text = 'Confirmar edição';
                    
                case 2 % Inclusao manual
                    set(app.Frequency, 'Editable', 1, 'BackgroundColor', [1,1,1])
                    set(app.BW,        'Editable', 1, 'BackgroundColor', [1,1,1])
                    set(app.THR_Left,  'Editable', 1, 'BackgroundColor', [1,1,1])
                    set(app.THR_Right, 'Editable', 1, 'BackgroundColor', [1,1,1])
                    
                    app.CheckBox.Value = 0;
                    
                    app.Button2.Enable = 1;                 
                    app.Button2.Text = 'Incluir emissão';
                    
                    app.EfetividadedesejadaLabel.Enable     = 0;
                    app.EfetividadedesejadaEditField.Enable = 0;
                    
                    app.LimitemximodeemissesainserirnamscaranovaLabel.Enable = 0;
                    app.limiteEmissoes.Enable = 0;
                    
                    app.Frequency.Value = 121.5;
                    app.BW.Value = 25;
                    app.THR_Left.Value = -80;
                    app.THR_Right.Value = -80;
                    
                case 3 % Inclusão auto
                    set(app.Frequency, 'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                    set(app.BW,        'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                    set(app.THR_Left,  'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                    set(app.THR_Right, 'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                    set(app.EfetividadedesejadaEditField, 'Editable', 1, 'BackgroundColor', [1,1,1])
                    set(app.limiteEmissoes, 'Editable', 1, 'Enable', 1,'BackgroundColor', [1,1,1])

                    app.EfetividadedesejadaLabel.Enable     = 1;
                    app.EfetividadedesejadaEditField.Enable = 1;
                    app.LimitemximodeemissesainserirnamscaranovaLabel.Enable = 1;
                    
                    app.Button2.Enable = 1;                 
                    app.Button2.Text = 'Calcular emissões';

            end
            
        end
        
        
        function TreeBuilding(app)
            
            delete(app.Tree.Children)
            
            if ~isempty(app.maskParam.freq)
                for i = 1:height(app.maskParam.freq)
                   uitreenode(app.Tree, 'Text', sprintf('%.3f MHz, %d kHz, [%d, %d] dBm', ...
                                                        app.maskParam.freq(i,1)/1000,     ...
                                                        app.maskParam.freq(i,2),          ...
                                                        app.maskParam.threshold(i),       ...
                                                        app.maskParam.threshold(i+1)),    ...
                                        'NodeData', i, 'ContextMenu', app.ContextMenu);
                end
                
                app.Tree.SelectedNodes = app.Tree.Children(1);
                TreeSelectionChanged(app)
                set(app.ContextMenu.Children, 'Enable', 1)
                
            else
                app.IncluirMenu.Enable = 1;
                app.ExcluirMenu.Enable = 0;
                app.EditarMenu.Enable  = 0;
            end
            
        end
        
        
        function TruncatedPeaks = Fcn_TruncatedFrequency(app, FrequencyPeaks)
            
            TruncatedPeaks = [];
            FrequencyChannels = 108000:25:137000;
            
            for ii = length(FrequencyPeaks):-1:1
                    [~, auxIndex]        = min(abs(FrequencyChannels - FrequencyPeaks(ii)));
                    TruncatedPeaks(ii,1) = FrequencyChannels(auxIndex);
            end
            
        end
        
        
        function inMask = maskParam2mask(app)
            
            if isempty(app.maskParam.freq) && isempty(app.maskParam.threshold)
                inMask = [];
                
            elseif isempty(app.maskParam.freq) && ~isempty(app.maskParam.threshold)
                inMask = [app.maskParam.FreqStart app.maskParam.FreqStop app.maskParam.threshold];
                
            else
                start = [app.maskParam.FreqStart; app.maskParam.freq(:,1)+ceil(app.maskParam.freq(:,2)/2)];
                stop = [app.maskParam.freq(:,1)-ceil(app.maskParam.freq(:,2)/2); app.maskParam.FreqStop];
                inMask = [start stop app.maskParam.threshold];
            end
            
        end
        
        
        function mask = maskParam2Xmask(app)
            
            mask = ones([1 app.specData(1).MetaData.DataPoints])*1000;
            freq = linspace(app.specData(1).MetaData.FreqStart/1000,app.specData(1).MetaData.FreqStop/1000,app.specData(1).MetaData.DataPoints);
            
            inMask = maskParam2mask(app);
            
            for i = 1:size(inMask,1)
                mask(freq>=inMask(i,1) & freq<=inMask(i,2)) = inMask(i,3);
            end             
             
        end
        
        
        function eff = eficiencia(app)
            
             mask = maskParam2Xmask(app);
             if app.Mode ~= 3
                uiprogressdlg(app.appAnliseEdiodemscaraUIFigure, 'Indeterminate', 'on','Title','Atualizando valor da eficiencia');
             end
             occMat = zeros([app.specData(1).MetaData.DataPoints app.specData(1).Samples]);
             
             occMat(bsxfun(@gt,app.specData(1).Data{1,2},mask')) = 1;
             eff = 1 - sum(any(occMat))/app.specData(1).Samples;
                      
        end
        
        
        function pMask = printMask(app)
            
             string1 = sprintf('Start(kHz),Stop(kHz),Threshold\n');
             outMask = maskParam2mask(app);
             for ind = 1:height(outMask)
                 string = sprintf('%.0f,%.0f,%.0f\n',  outMask(ind,1), outMask(ind,2), outMask(ind,3));
                 string1 = [string1,'',string];
             end                                                  
             pMask = string1;   
             
        end
        
        
        function calculoAuto(app)          
            
           app.winDialog = uiprogressdlg(app.appAnliseEdiodemscaraUIFigure, 'Indeterminate', 'on');
           
           app.MscaranovaTextArea.Enable = 1; 
            
           minPksDist = 25; %kHz
           minPksDist_Index = round(minPksDist*app.specData(1).MetaData.DataPoints/(app.specData(1).MetaData.FreqStop/1000 - app.specData(1).MetaData.FreqStart/1000));
           
           mask = maskParam2Xmask(app);
           
           %% FindPeaks Section (Mask broken analysis)
           freqsOver = sum(app.specData(1).Data{2}>mask',2)/size(app.specData(1).Data{2},2);
           [~, locPksOver1, PeakWidth1] = findpeaks(freqsOver, 'MinPeakDistance', minPksDist_Index, ...
                                                               'SortStr', 'descend');
           %% FindPeaks Section (Spectral analysis)
            THR = -inf;
            if ~isempty(app.specData.MetaData.Threshold)
                THR = app.specData.MetaData.Threshold + 10;
            end
           
           [~, locPksOver2, PeakWidth2] = findpeaks(app.specData.statsData(:,4), 'MinPeakHeight', THR,                ...
                                                                                 'MinPeakDistance', minPksDist_Index, ...
                                                                                 'MinPeakProminence', 10,             ...
                                                                                 'SortStr', 'descend');
           
           % Truncamento e interseção dos valores.
           Span  = (app.specData.MetaData.FreqStop - app.specData.MetaData.FreqStart);
           aCoef = Span ./ (app.specData.MetaData.DataPoints - 1);
           bCoef = app.specData.MetaData.FreqStart - aCoef;
           
           locPksOver1_Freq = aCoef .* locPksOver1 + bCoef;  
           locPksOver2_Freq = aCoef .* locPksOver2 + bCoef;  
           
           TruncatedPeaks1 = Fcn_TruncatedFrequency(app, locPksOver1_Freq/1000);
           TruncatedPeaks2 = Fcn_TruncatedFrequency(app, locPksOver2_Freq/1000);
           
           [~, ind1, ind2] = intersect(TruncatedPeaks1, TruncatedPeaks2, 'stable');
           
           PeakWidth = max(PeakWidth1(ind1), PeakWidth2(ind2));
                  
           % Aqui continua o baba...
           effic = str2double(app.EfetividadeEditField_2.Value(1:end-1))/100;
           freq = TruncatedPeaks1(ind1);

           ii = 1;
           countEmiss = 0;
           while (effic < app.EfetividadedesejadaEditField.Value/100) && app.limiteEmissoes.Value > countEmiss
                  % modifying an existing mask
                  if isempty(app.maskParam.freq)
                    indNewFreq = [];
                  else
                    indNewFreq = find(freq(ii)<app.maskParam.freq(:,1),1);
                  end
                  if isempty(find(app.maskParam.freq==freq(ii), 1))
                      if isempty(indNewFreq)
                          app.maskParam.freq =[app.maskParam.freq; freq(ii) ceil(PeakWidth(ii)/25)*25];
                          app.maskParam.threshold = [app.maskParam.threshold; app.maskParam.threshold(end)];
                      elseif indNewFreq == 1
                          app.maskParam.freq = [freq(ii) ceil(PeakWidth(ii)/25)*25; app.maskParam.freq];
                          app.maskParam.threshold = [app.maskParam.threshold(1); app.maskParam.threshold];
                      else
                          app.maskParam.freq = [app.maskParam.freq(1:indNewFreq-1,:);freq(ii) ceil(PeakWidth(ii)/25)*25; app.maskParam.freq(indNewFreq:end,:)];
                          app.maskParam.threshold = [app.maskParam.threshold(1:indNewFreq-1); app.maskParam.threshold(indNewFreq); app.maskParam.threshold(indNewFreq:end)];
                      end
                  end

                  if ii < length(freq)
                      freq_atual = freq(ii)/1000;
                      ii = ii + 1;
                  else
                      break
                  end

              effic_old = effic;
              effic = eficiencia(app);
              app.winDialog.Message = sprintf('Efetividade inicial: %d %% \nEfetividade Atual: %d %%\n\nCalculando a efetividade da nova máscara excluindo a frequência %.3f MHz',...
                                               uint16(ceil(effic_old*100)), uint16(ceil(effic*100)), freq_atual);
              
              writeLog(app,freq_atual, ceil(PeakWidth(ii)/25)*25, [], [], uint16(ceil(effic_old*100)), uint16(ceil(effic*100)),1, 'Inclusão Automática')
            
              countEmiss = countEmiss + 1;
              
           end
           app.EfetividadeEditField_2.Value = [num2str(ceil(effic*100)) '%'];
           if ~isempty(app.maskParam.freq)
               TreeBuilding(app)
           end
           app.MscaranovaTextArea.Value = printMask(app);
           
           app.CheckBox.Value = 0;
           delete(app.winDialog)
           
        end
        
        
        function writeLog(app,freq,BW,thr_left, thr_right, effic_old,effic_new, flag, acao)
            
             app.passoCount = app.passoCount + 1;
             if flag
                app.LOGTextArea.Value(end+1) = {sprintf('Passo %d: %s\n%.3f MHz, %d kHz, -80 dBm, -80 dBm.\n Efetividades: inicial: %d %%; final: %d %%\n', ...
                      app.passoCount, acao, freq, BW, effic_old, effic_new)};
             else
                app.LOGTextArea.Value(end+1) = {sprintf('Passo %d: %s\n%.3f MHz, %d kHz, %d dBm, %d dBm.\n Efetividades: inicial: %d %%; final: %d %%\n', ...
                      app.passoCount, acao, freq, BW,thr_left, thr_right, effic_old, effic_new)};
             end
            
        end
        
        
        function freqIntersection = checkIntersection(app, freq_new, BW_new, position)
            
            freqIntersection = 0;
            
           if (freq_new - BW_new/2) < app.maskParam.FreqStart || (freq_new + BW_new/2) > app.maskParam.FreqStop
               freqIntersection = 1;
               return
           end
            
            if position > 1
                if height(app.maskParam.freq) == position
                   if ((freq_new - BW_new/2) < (app.maskParam.freq(position-1,1) + app.maskParam.freq(position-1,2)/2))
                       freqIntersection = 1;
                   end
                else
                   if ((freq_new - BW_new/2) < (app.maskParam.freq(position-1,1)    + app.maskParam.freq(position-1,2)/2)) || ...
                      ((freq_new + BW_new/2) > (app.maskParam.freq(position + 1, 1) - app.maskParam.freq(position + 1, 2)/2)) 
                       freqIntersection = 1;
                   end
                end

            else
                if height(app.maskParam.freq) > 1
                   if (freq_new + BW_new/2) > (app.maskParam.freq(position + 1, 1) - app.maskParam.freq(position + 1, 2)/2)
                       freqIntersection = 1;
                   end
                end
            end

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Data)
            mainMonitor = get(0, 'MonitorPositions');
            mainMonitor = mainMonitor(mainMonitor(:,1) == 1,:);            
           
            app.appAnliseEdiodemscaraUIFigure.Position(1:2) = [round((mainMonitor(3)-app.appAnliseEdiodemscaraUIFigure.Position(3))/2), ...
                                          round((mainMonitor(4)-app.appAnliseEdiodemscaraUIFigure.Position(4))/2)];

            app.CallingApp = mainapp;
            
            app.specData = Data;
                                 
            max(app.specData(1).Data{1,2},[],2);
            app.maskParam = struct('threshold',[-80; -80; -80; -80],'freq',[146600 19200; 159400 5000; 241050 157900],'FreqStart',108000,'FreqStop',6000000);
            
                                 
            app.MscarainicialTextArea.Value = printMask(app);
            app.MscaranovaTextArea.Value = printMask(app);
            TreeBuilding(app)

            effic = eficiencia(app);
            app.EfetividadeEditField_2.Value = [num2str(ceil(effic*100)) '%'];
            app.EfetividadeEditField.Value = [num2str(ceil(effic*100)) '%'];
            
            app.ThreadID.Text = sprintf('ThreadID %d: %.3f - %.3f MHz', app.specData.ThreadID, ...
                                                                        app.specData.MetaData.FreqStart/1e+6, ...
                                                                        app.specData.MetaData.FreqStop/1e+6);
            
            app.Samples.Value = app.specData.Samples;
            
            app.rfeye.Text =  app.specData.Node;

        end

        % Close request function: appAnliseEdiodemscaraUIFigure
        function appAnliseEdiodemscaraUIFigureCloseRequest(app, event)

            app.CallingApp.auxWin4 = [];
            delete(app)
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
            ind = app.Tree.SelectedNodes.NodeData;
            
            app.Frequency.Value = app.maskParam.freq(ind,1)/1000;
            app.BW.Value        = app.maskParam.freq(ind,2);
            app.THR_Left.Value  = app.maskParam.threshold(ind);
            app.THR_Right.Value = app.maskParam.threshold(ind+1);
            
        end

        % Button pushed function: Button1
        function Button1Pushed(app, event)
                       
                 [file, path, ~] = uigetfile({'*.csv',           'Mask (*.csv)'},       ...
                                              'Selecione uma máscara', 'MultiSelect', 'off');
                 
                 if file
                     inData = readtable([path file],'VariableNamingRule' ,'preserve');
                     inData.Properties.VariableNames{1} = 'Start';
                     inData.Properties.VariableNames{2} = 'Stop';          
                     
                     % povoar app.maskParam com dados da máscara carregada             
                     app.maskParam.threshold = inData.Threshold;
                     app.maskParam.FreqStart = inData.Start(1);
                     app.maskParam.FreqStop = inData.Stop(end);
                     
                     app.Frequency.Limits = [app.maskParam.FreqStart app.maskParam.FreqStop] ./ 1000;
                     
                     bw = inData.Start(2:end) - inData.Stop(1:end-1);
                     app.maskParam.freq = [(inData.Stop(1:end-1)+ bw/2)  bw];
                     
                     app.MscarainicialTextArea.Value = printMask(app);
                     app.MscaranovaTextArea.Value = printMask(app);
                     
                     TreeBuilding(app)
                     
                     eff = eficiencia(app);
                     
                     app.EfetividadeEditField.Value = [num2str(ceil(eff*100)) '%'];
                     app.EfetividadeEditField_2.Value = [num2str(ceil(eff*100)) '%'];
                     app.LOGTextArea.Value = '';
                     app.passoCount = 0;
                 end
                 
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            
            if app.Mode == 2              
                app.maskParam.freq = [app.maskParam.freq; 0, 25];
                app.maskParam.threshold = [app.maskParam.threshold; -80];
                
                app.maskParam.freq(end,1) = app.Frequency.Value*1000;
                app.maskParam.freq(end,2) = app.BW.Value;       
                app.maskParam.threshold(end) = app.THR_Right.Value;
                
                freq_atual = app.Frequency.Value;
                BW_atual = app.BW.Value;
                THR_right = app.THR_Right.Value;
                THR_left = app.THR_Left.Value;
                
                if height(app.maskParam.freq)==1
                    indSort = [1];
                else
                    [app.maskParam.freq,indSort] = sortrows(app.maskParam.freq);
                end 

                [~,indMax] = max(indSort(:,1));
                freqIntersection = checkIntersection(app,freq_atual*1000, BW_atual, indMax);
                if freqIntersection
                    uialert(app.appAnliseEdiodemscaraUIFigure,['O intervalo de frequência submetido engloba outro(s) intervalo(s). ' ...
                        'Corrija os dados de frequência ou bandwidth submetidos, ou exclua intervalo(s) englobado(s) antes de prosseguir'], ...
                        'Interseção de Intervalos')
                    app.Mode = 0;
                    Layout(app)
                    app.maskParam.freq(indMax,:) = [];
                    app.maskParam.threshold(indMax) = [];
                    
                    TreeSelectionChanged(app)
                    
                    return
                end

                app.maskParam.threshold = [app.maskParam.threshold(1); app.maskParam.threshold(indSort(:,1)+1)];
                 
                app.maskParam.threshold(indMax) = app.THR_Left.Value;   
                                
                effic_old = app.EfetividadeEditField_2.Value;
                effic = eficiencia(app);
                app.EfetividadeEditField_2.Value = [num2str(ceil(effic*100)) '%'];
                
                
                writeLog(app,freq_atual, BW_atual, THR_left, THR_right, str2double(extractBefore(effic_old,'%')), uint16(ceil(effic*100)),0, 'Inclusão Manual')
                 

            elseif app.Mode == 1
                ind = app.Tree.SelectedNodes.NodeData;

                freqIntersection = checkIntersection(app,app.Frequency.Value*1000, app.BW.Value, ind);
                if freqIntersection
                    uialert(app.appAnliseEdiodemscaraUIFigure,['O intervalo de frequência submetido engloba outro(s) intervalo(s). ' ...
                        'Corrija os dados de frequência ou bandwidth, ou exclua intervalo(s) englobado(s) antes de prosseguir'], ...
                        'Interseção de Intervalos')
                    app.Mode = 0;
                    Layout(app)
                    
                    TreeSelectionChanged(app)
                    
                    return
                end

                app.maskParam.freq(ind,1) = app.Frequency.Value*1000;
                app.maskParam.freq(ind,2) = app.BW.Value;
                app.maskParam.threshold(ind) = app.THR_Left.Value;
                app.maskParam.threshold(ind+1) = app.THR_Right.Value; 
                
                freq_atual = app.Frequency.Value;
                BW_atual = app.BW.Value;
                THR_right = app.THR_Right.Value;
                THR_left = app.THR_Left.Value;
                
                effic_old = app.EfetividadeEditField_2.Value;
                effic = eficiencia(app);
                app.EfetividadeEditField_2.Value = [num2str(ceil(effic*100)) '%'];
                
                writeLog(app,freq_atual, BW_atual, THR_left, THR_right, str2double(extractBefore(effic_old,'%')), uint16(ceil(effic*100)),0, 'Edição Manual')
                
            elseif app.Mode == 3
                calculoAuto(app)                
            end           
            TreeBuilding(app)
            
            scroll(app.LOGTextArea,'bottom')

            app.MscaranovaTextArea.Value = printMask(app);
            app.Mode = 0;
            Layout(app)
            
        end

        % Button pushed function: Button3
        function Button3Pushed(app, event)
            maskValues = maskParam2mask(app);
            mask = cell2table(num2cell(maskValues),'VariableNames',["Start(kHz)","Stop(kHz)","Threshold"]);
            writetable(mask,'mask.csv');
            string1 = sprintf('Favor investigar frequências abaixo, retiradas da máscara e não encontradas nas bases de dados:\n');
            for ii=1:height(app.maskParam.freq)
                string1 = [string1 sprintf('%.3f MHz \n', app.maskParam.freq(ii,1)/1000)];
            end
            uialert(app.appAnliseEdiodemscaraUIFigure, string1, 'Arquivo de máscara gerado na pasta corrente', 'Icon', 'warning')
        end

        % Menu selected function: EditarMenu
        function ManualMode(app, event)

            app.Mode = 1;
            Layout(app)
            
        end

        % Menu selected function: IncluirMenu
        function AddFrequency(app, event)

           app.Mode = 2;
           Layout(app)

        end

        % Menu selected function: ExcluirMenu
        function DeleteFrequency(app, event)
           
            ind = app.Tree.SelectedNodes.NodeData;
           
           freq_atual = app.maskParam.freq(ind,1)/1000;
           BW_atual = app.maskParam.freq(ind,2);
           THR_left = app.maskParam.threshold(ind);
           THR_right = app.maskParam.threshold(ind+1);
           
           app.maskParam.freq(ind,:) = [];
           app.maskParam.threshold(ind) = [];
           TreeBuilding(app)
           app.MscaranovaTextArea.Value = printMask(app);           
           
           effic_old = app.EfetividadeEditField_2.Value;
           effic = eficiencia(app);
           app.EfetividadeEditField_2.Value = [num2str(ceil(effic*100)) '%'];
           
           writeLog(app,freq_atual, BW_atual, THR_left, THR_right, str2double(extractBefore(effic_old,'%')), uint16(ceil(effic*100)),0, 'Exclusão Manual')
                           
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
      
            if app.CheckBox.Value
                app.Mode = 3;
            else
                app.Mode = 0;
            end
           Layout(app)
            
        end

        % Value changed function: BW
        function BWValueChanged(app, event)
            if mod(app.BW.Value,25)
                app.BW.Value = ceil(app.BW.Value/25)*25;
            end
        end

        % Value changed function: Frequency
        function FrequencyValueChanged(app, event)
            
            DecimalPart = 1000* (round(app.Frequency.Value, 3) - fix(app.Frequency.Value));
            if mod(DecimalPart, 25)
                DecimalPart = round(DecimalPart/25)*25;
            end
            
            app.Frequency.Value = fix(app.Frequency.Value) + DecimalPart/1000;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.appAnliseEdiodemscaraUIFigure = uifigure('Visible', 'off');
            app.appAnliseEdiodemscaraUIFigure.Name = 'appAnálise: Edição de máscara';
            app.appAnliseEdiodemscaraUIFigure.Icon = 'Bluemine.png';
            app.appAnliseEdiodemscaraUIFigure.CloseRequestFcn = createCallbackFcn(app, @appAnliseEdiodemscaraUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.appAnliseEdiodemscaraUIFigure);
            app.MscarainicialTextArea = uitextarea(app.GridLayout);
            app.MscarainicialTextArea.Editable = 'off';
            app.MscarainicialTextArea.Value = {'Start(kHz),Stop(kHz),Threshold'; '108000,6000000,-80'};
            app.MscarainicialTextAreaLabel = uilabel(app.GridLayout);
            app.MscarainicialTextAreaLabel.Text = 'Máscara inicial:';
            app.Button1 = uibutton(app.GridLayout, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Icon = 'OpenFile.png';
            app.Button1.Text = 'Outra máscara';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ThreadID = uilabel(app.GridLayout2);
            app.ThreadID.Text = 'ThreadID 100: 88.000 - 108.000 MHz';
            app.rfeye = uilabel(app.GridLayout2);
            app.rfeye.Text = 'RFeye002092';
            app.Samples = uieditfield(app.GridLayout2, 'numeric');
            app.Samples.Limits = [0 Inf];
            app.Samples.ValueDisplayFormat = '%d';
            app.NmerodetraosLabel_2 = uilabel(app.GridLayout2);
            app.NmerodetraosLabel_2.Text = 'Número de traços:';
            app.Button3 = uibutton(app.GridLayout, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button3.Icon = 'Media_PlayWhite.png';
            app.Button3.Text = 'Criar máscara';
            app.EfetividadeEditField = uieditfield(app.GridLayout, 'text');
            app.EfetividadeEditField.Editable = 'off';
            app.EfetividadeEditField.Value = '2%';
            app.EfetividadeEditFieldLabel = uilabel(app.GridLayout);
            app.EfetividadeEditFieldLabel.Text = 'Efetividade:';
            app.MscaranovaTextArea = uitextarea(app.GridLayout);
            app.MscaranovaTextArea.Editable = 'off';
            app.MscaranovaTextArea.Value = {'Start(kHz),Stop(kHz),Threshold'; '108000,6000000,-80'};
            app.MscaranovaTextAreaLabel = uilabel(app.GridLayout);
            app.MscaranovaTextAreaLabel.Text = 'Máscara nova:';
            app.EfetividadeEditField_2 = uieditfield(app.GridLayout, 'text');
            app.EfetividadeEditField_2.Editable = 'off';
            app.EfetividadeEditField_2.Value = '0%';
            app.EfetividadeEditField_2Label = uilabel(app.GridLayout);
            app.EfetividadeEditField_2Label.Text = 'Efetividade:';
            app.LOGTextArea = uitextarea(app.GridLayout);
            app.LOGTextArea.Editable = 'off';
            app.LOGLabel = uilabel(app.GridLayout);
            app.LOGLabel.Text = 'LOG';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.File_Label2 = uilabel(app.GridLayout6);
            app.File_Label2.Text = 'Característica da máscara';
            app.File_Label2_3 = uilabel(app.GridLayout6);
            app.File_Label2_3.Text = 'Frequência central, ocupação e threshold (à esquerda e à direita)';
            app.Tree = uitree(app.GridLayout);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Panel_6 = uipanel(app.GridLayout);
            app.GridLayout8 = uigridlayout(app.Panel_6);
            app.CheckBox = uicheckbox(app.GridLayout8);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = 'Inclusão automática de emissões';
            app.EfetividadedesejadaEditField = uieditfield(app.GridLayout8, 'numeric');
            app.EfetividadedesejadaEditField.Limits = [1 100];
            app.EfetividadedesejadaEditField.ValueDisplayFormat = '%d';
            app.EfetividadedesejadaEditField.Enable = 'off';
            app.EfetividadedesejadaEditField.Value = 50;
            app.EfetividadedesejadaLabel = uilabel(app.GridLayout8);
            app.EfetividadedesejadaLabel.Enable = 'off';
            app.EfetividadedesejadaLabel.Text = 'Efetividade desejada (%):';
            app.limiteEmissoes = uieditfield(app.GridLayout8, 'numeric');
            app.limiteEmissoes.Limits = [1 Inf];
            app.limiteEmissoes.Enable = 'off';
            app.limiteEmissoes.Value = 5;
            app.LimitemximodeemissesainserirnamscaranovaLabel = uilabel(app.GridLayout8);
            app.LimitemximodeemissesainserirnamscaranovaLabel.Enable = 'off';
            app.LimitemximodeemissesainserirnamscaranovaLabel.Text = 'Limite máximo de emissões a inserir na máscara nova:';
            app.ThresholdabaixoeacimadaemissodBLabel = uilabel(app.GridLayout8);
            app.ThresholdabaixoeacimadaemissodBLabel.Text = {'Threshold'; 'abaixo e acima da emissão (dB):'};
            app.THR_Left = uieditfield(app.GridLayout8, 'numeric');
            app.THR_Left.RoundFractionalValues = 'on';
            app.THR_Left.ValueDisplayFormat = '%d';
            app.THR_Left.Editable = 'off';
            app.THR_Left.Value = -80;
            app.FrequnciaMHzEditFieldLabel = uilabel(app.GridLayout8);
            app.FrequnciaMHzEditFieldLabel.Text = 'Frequência (MHz):';
            app.Frequency = uieditfield(app.GridLayout8, 'numeric');
            app.Frequency.Limits = [108 6000];
            app.Frequency.ValueDisplayFormat = '%0.3f';
            app.Frequency.ValueChangedFcn = createCallbackFcn(app, @FrequencyValueChanged, true);
            app.Frequency.Editable = 'off';
            app.Frequency.Value = 121.5;
            app.BW = uieditfield(app.GridLayout8, 'numeric');
            app.BW.Limits = [25 Inf];
            app.BW.ValueDisplayFormat = '%.3f';
            app.BW.ValueChangedFcn = createCallbackFcn(app, @BWValueChanged, true);
            app.BW.Editable = 'off';
            app.BW.Value = 25;
            app.LargurakHzEditFieldLabel = uilabel(app.GridLayout8);
            app.LargurakHzEditFieldLabel.Text = 'Largura (kHz):';
            app.THR_Right = uieditfield(app.GridLayout8, 'numeric');
            app.THR_Right.RoundFractionalValues = 'on';
            app.THR_Right.ValueDisplayFormat = '%d';
            app.THR_Right.Editable = 'off';
            app.THR_Right.Value = -80;
            app.EdiodamscaraLabel = uilabel(app.GridLayout);
            app.EdiodamscaraLabel.Text = 'Edição da máscara';
            app.Button2 = uibutton(app.GridLayout, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Icon = 'EditWhite.png';
            app.Button2.Enable = 'off';
            app.Button2.Text = 'Confirma edição';
            app.ContextMenu = uicontextmenu(app.appAnliseEdiodemscaraUIFigure);
            app.IncluirMenu = uimenu(app.ContextMenu);
            app.IncluirMenu.MenuSelectedFcn = createCallbackFcn(app, @AddFrequency, true);
            app.IncluirMenu.Text = 'Incluir';
            app.ExcluirMenu = uimenu(app.ContextMenu);
            app.ExcluirMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteFrequency, true);
            app.ExcluirMenu.Text = 'Excluir';
            app.EditarMenu = uimenu(app.ContextMenu);
            app.EditarMenu.MenuSelectedFcn = createCallbackFcn(app, @ManualMode, true);
            app.EditarMenu.Text = 'Editar';
            % Assign app.ContextMenu
            app.Tree.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.appAnliseEdiodemscaraUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winMaskDraw(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.appAnliseEdiodemscaraUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.appAnliseEdiodemscaraUIFigure)
        end
    end
end