classdef winTableEdit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        appAnlisePrviaderelatrioUIFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        UITable                      matlab.ui.control.Table
        GridLayout2                  matlab.ui.container.GridLayout
        RegistroselecionadoLabel     matlab.ui.control.Label
        EmissoDropDownLabel          matlab.ui.control.Label
        EmissoDropDown               matlab.ui.control.DropDown
        ProvvelemissorTextAreaLabel  matlab.ui.control.Label
        ProvvelemissorTextArea       matlab.ui.control.TextArea
        sugeridopeloalgoritmoembarcadonoappAnliseLabel  matlab.ui.control.Label
        FrequnciacentraldocanalEditFieldLabel  matlab.ui.control.Label
        FrequnciacentraldocanalEditField  matlab.ui.control.EditField
        MHzLabel                     matlab.ui.control.Label
        GridLayout3                  matlab.ui.container.GridLayout
        TipodeemissoDropDown         matlab.ui.control.DropDown
        TipodeemissoDropDownLabel    matlab.ui.control.Label
        ProvvelesquemademodulaoDropDown  matlab.ui.control.DropDown
        ProvvelesquemademodulaoDropDownLabel  matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        EmissorLabel                 matlab.ui.control.Label
        Label                        matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp
        
    end
    
    methods (Access = private)
        
        function Fcn1(app, row)
            
            app.FrequnciacentraldocanalEditField.Value = app.UITable.Data.Truncated{row};
            app.ProvvelemissorTextArea.Value = app.UITable.Data.("Stel/Mosaico database"){row};


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            global peakValues
            app.UITable.Data = peakValues(:,2:end);
            global x
            x = app.UITable.Data;
            
            app.UITable.ColumnName = {sprintf('Frequência\n(MHz)'), ...
                                      sprintf('Frequência\ncentral do\ncanal (MHz)'), ...
                                      sprintf('Nível\nMínimo\n(dBm)'), ...
                                      sprintf('Nível\nMáximo\n(dBm)'), ...
                                      sprintf('Ocupação\nmédia\n(%%)'), ...
                                      sprintf('Ocupação\nmáxima\n(%%)'), ...
                                      sprintf('Identificação do provável emissor\n(Serviço, Entidade (Fistel, Estação), Município/UF)'), ...
                                      sprintf('Distância\n(km)')};


%             app.CallingApp = mainapp;
%             app.UITable.Data = app.CallingApp.peaksTable;
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            
            if height(event.Indices) == 1
                row = event.Indices(1);
                Fcn1(app, row)
            end
            
        end

        % Value changed function: EmissoDropDown
        function EmissoDropDownValueChanged(app, event)
            
            switch app.EmissoDropDown.Value
                case 'Licenciada'
                    set(app.GridLayout3.Children, 'Enable', 0);
                case 'Não licenciada'
                    set(app.GridLayout3.Children, 'Enable', 1);
                case 'Não passível de licenciamento'
                    set(app.GridLayout3.Children, 'Enable', 1);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.appAnlisePrviaderelatrioUIFigure = uifigure('Visible', 'off');
            app.appAnlisePrviaderelatrioUIFigure.Name = 'appAnálise: Prévia de relatório';
            app.appAnlisePrviaderelatrioUIFigure.Icon = 'Bluemine.png';
            app.GridLayout = uigridlayout(app.appAnlisePrviaderelatrioUIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.RegistroselecionadoLabel = uilabel(app.GridLayout2);
            app.RegistroselecionadoLabel.Text = 'Registro selecionado:';
            app.EmissoDropDownLabel = uilabel(app.GridLayout2);
            app.EmissoDropDownLabel.Text = 'Emissão:';
            app.EmissoDropDown = uidropdown(app.GridLayout2);
            app.EmissoDropDown.Items = {'Licenciada', 'Não licenciada', 'Não passível de licenciamento'};
            app.EmissoDropDown.ValueChangedFcn = createCallbackFcn(app, @EmissoDropDownValueChanged, true);
            app.EmissoDropDown.Value = 'Licenciada';
            app.ProvvelemissorTextAreaLabel = uilabel(app.GridLayout2);
            app.ProvvelemissorTextAreaLabel.Text = 'Provável emissor:';
            app.ProvvelemissorTextArea = uitextarea(app.GridLayout2);
            app.ProvvelemissorTextArea.Editable = 'off';
            app.sugeridopeloalgoritmoembarcadonoappAnliseLabel = uilabel(app.GridLayout2);
            app.sugeridopeloalgoritmoembarcadonoappAnliseLabel.Text = '(sugerido pelo algoritmo embarcado no appAnálise)';
            app.FrequnciacentraldocanalEditFieldLabel = uilabel(app.GridLayout2);
            app.FrequnciacentraldocanalEditFieldLabel.Text = 'Frequência central do canal:';
            app.FrequnciacentraldocanalEditField = uieditfield(app.GridLayout2, 'text');
            app.FrequnciacentraldocanalEditField.Editable = 'off';
            app.MHzLabel = uilabel(app.GridLayout2);
            app.MHzLabel.Text = 'MHz';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.TipodeemissoDropDown = uidropdown(app.GridLayout3);
            app.TipodeemissoDropDown.Items = {'Normal', 'Espúria', 'Não identificada', 'Não se manifestou'};
            app.TipodeemissoDropDown.Enable = 'off';
            app.TipodeemissoDropDown.Value = 'Normal';
            app.TipodeemissoDropDownLabel = uilabel(app.GridLayout3);
            app.TipodeemissoDropDownLabel.Enable = 'off';
            app.TipodeemissoDropDownLabel.Text = 'Tipo de emissão:';
            app.ProvvelesquemademodulaoDropDown = uidropdown(app.GridLayout3);
            app.ProvvelesquemademodulaoDropDown.Items = {'Analógico', 'Digital'};
            app.ProvvelesquemademodulaoDropDown.Enable = 'off';
            app.ProvvelesquemademodulaoDropDown.Value = 'Analógico';
            app.ProvvelesquemademodulaoDropDownLabel = uilabel(app.GridLayout3);
            app.ProvvelesquemademodulaoDropDownLabel.Enable = 'off';
            app.ProvvelesquemademodulaoDropDownLabel.Text = 'Provável esquema de modulação:';
            app.TextArea = uitextarea(app.GridLayout3);
            app.TextArea.Enable = 'off';
            app.EmissorLabel = uilabel(app.GridLayout3);
            app.EmissorLabel.Enable = 'off';
            app.EmissorLabel.Text = 'Emissor:';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Enable = 'off';
            app.Label.Text = '(indicar informações que possam ser úteis em eventual diligência de campo)';
            % Show the figure after all components are created
            app.appAnlisePrviaderelatrioUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winTableEdit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.appAnlisePrviaderelatrioUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.appAnlisePrviaderelatrioUIFigure)
        end
    end
end