classdef winTreeEdit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Grid1                        matlab.ui.container.GridLayout
        Label3                       matlab.ui.control.Label
        Label1                       matlab.ui.control.Label
        GridLayout                   matlab.ui.container.GridLayout
        UITable                      matlab.ui.control.Table
        FrequnciaMHzLabel            matlab.ui.control.Label
        FrequnciacentraldocanalMHzLabel  matlab.ui.control.Label
        NvelmnimodBmLabel            matlab.ui.control.Label
        NvelmximodBmLabel            matlab.ui.control.Label
        OcupaomdiaLabel              matlab.ui.control.Label
        OcupaomximaLabel             matlab.ui.control.Label
        IdentificaodoprovvelemissorServioStatusEntidadeMunicpioUFLabel  matlab.ui.control.Label
        DistnciakmLabel              matlab.ui.control.Label
        Tree                         matlab.ui.container.Tree
        Grid3                        matlab.ui.container.GridLayout
        Grid5                        matlab.ui.container.GridLayout
        Description                  matlab.ui.control.TextArea
        InformaesadicionaisLabel     matlab.ui.control.Label
        SubLabel2                    matlab.ui.control.Label
        Type                         matlab.ui.control.DropDown
        TipodeemissoDropDownLabel    matlab.ui.control.Label
        Modulation                   matlab.ui.control.DropDown
        ProvvelesquemademodulaoDropDownLabel  matlab.ui.control.Label
        Grid4                        matlab.ui.container.GridLayout
        FrequnciacentraldocanalEditFieldLabel  matlab.ui.control.Label
        ProvvelemissorTextAreaLabel  matlab.ui.control.Label
        SubLabel1                    matlab.ui.control.Label
        Station                      matlab.ui.control.TextArea
        CF                           matlab.ui.control.EditField
        Unit                         matlab.ui.control.Label
        regulatoryInfo               matlab.ui.control.DropDown
        EmissoDropDownLabel          matlab.ui.control.Label
        Label2                       matlab.ui.control.Label
        addButton                    matlab.ui.control.Button
        deleteButton                 matlab.ui.control.Image
    end

    
    properties (Access = private)
        
        CallingApp
        peakValues
        tableStyle
        
    end
    
    methods (Access = private)
        
        function Fcn1(app)

            delete(app.Tree.Children);
            app.deleteButton.Enable = 0;
            
            if ~isempty(app.CallingApp.exceptionList)
                app.CallingApp.exceptionList = sortrows(app.CallingApp.exceptionList, 1);
                
                for ii = 1:height(app.CallingApp.exceptionList)
                    str = sprintf('[%s][%s]', ...
                                  app.CallingApp.exceptionList.regulatoryInfo(ii), ...
                                  app.CallingApp.exceptionList.Type(ii));
                    
                    if ~strcmp(app.CallingApp.exceptionList.Type(ii), 'Não se manifestou')
                        str = sprintf('%s[%s]', str, app.CallingApp.exceptionList.Modulation(ii));
                    end      
                    
                    Node = uitreenode(app.Tree, 'Text', sprintf('%.3f MHz, %s',  ...
                                      app.CallingApp.exceptionList.CF(ii), str), 'NodeData', ii);
                    
                    uitreenode(Node, 'Text', sprintf('"%s"', ...
                                      app.CallingApp.exceptionList.Description(ii)), 'NodeData', ii);
                    
                    
                end
            end
            
        end
        
        
        function Fcn2(app)
            
            if ~isempty(app.CallingApp.exceptionList)
                ind1 = [];
                for ii = 1:height(app.CallingApp.exceptionList)
                    ind2 = find(strcmp(app.CallingApp.peaksTable.Truncated, sprintf('%.3f', app.CallingApp.exceptionList.CF(ii))));
                    if ~isempty(ind2)
                        ind1 = [ind1, ind2];
                    end
                end
                if ~isempty(ind1)
                    addStyle(app.UITable, app.tableStyle, 'cell', [ind1', repmat(7, numel(ind1), 1)])
                    addStyle(app.UITable, app.tableStyle, 'cell', [ind1', repmat(8, numel(ind1), 1)])
                end
            end
            
        end
        
        
        function Fcn3(app, row)
            
            app.CF.Value       = app.UITable.Data.Truncated{row};
            app.Station.Value = app.UITable.Data.("Stel/Mosaico database"){row};
            
            ind = [];
            if ~isempty(app.CallingApp.exceptionList)
                ind = find(app.CallingApp.exceptionList.CF == str2double(app.UITable.Data.Truncated{row}));
            end
            
            app.regulatoryInfo.Items = {'Licenciada', 'Não licenciada', 'Não passível de licenciamento'};
            if ~isempty(ind)
                app.regulatoryInfo.Items = {'Não licenciada', 'Não passível de licenciamento'}; 
                
                app.regulatoryInfo.Value = app.CallingApp.exceptionList.regulatoryInfo(ind);
                app.Type.Value           = app.CallingApp.exceptionList.Type(ind);
                app.Modulation.Value     = app.CallingApp.exceptionList.Modulation(ind);
                app.Description.Value    = app.CallingApp.exceptionList.Description(ind);
                
                app.Tree.SelectedNodes  = app.Tree.Children(ind);
            else
                str = app.CallingApp.peaksTable.regulatoryInfo{row};
                if strcmp(str, 'Não licenciada')
                    app.regulatoryInfo.Items = {'Não licenciada', 'Não passível de licenciamento'}; 
                end
                
                app.regulatoryInfo.Value = str;
                app.Type.Value           = 'Normal';
                app.Modulation.Value     = 'Analógico';
                app.Description.Value    = '';
                
                app.Tree.SelectedNodes  = [];
            end
            TreeSelectionChanged(app)
            
            regulatoryInfo_ValueChanged(app)
            
            if ~strcmp(app.regulatoryInfo.Value, 'Licenciada')
                Type_ValueChanged(app)
                app.addButton.Enable = 1;
            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            mainMonitor = get(0, 'MonitorPositions');
            mainMonitor = mainMonitor(mainMonitor(:,1) == 1,:);
            
            app.UIFigure.Position(1:2) = [round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.CallingApp = mainapp;

            if isempty(app.CallingApp.exceptionList)
                app.CallingApp.exceptionList = table('Size',[0,5], 'VariableTypes', {'double', 'string', 'string', 'string', 'string'});
                app.CallingApp.exceptionList.Properties.VariableNames = {'CF', 'regulatoryInfo', 'Type', 'Modulation', 'Description'};
            end

            app.tableStyle   = uistyle("BackgroundColor", '#bfe5ff');
            app.UITable.Data = app.CallingApp.peaksTable(:,2:9);
            
            value = height(app.UITable.Data);
            if value <= 11
                app.GridLayout.ColumnWidth = {95,95,80,80,70,70,'1x',70};
            else
                app.GridLayout.ColumnWidth = {95,95,80,80,70,70,'1x',88};
            end

            Fcn1(app)
            Fcn2(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin1 = [];
            delete(app)
            
        end

        % Cell selection callback: UITable
        function UITable_CellSelection(app, event)
            
            if numel(unique(event.Indices(:,1))) == 1
                set(app.Grid4.Children, 'Enable', 1)
                
                row = event.Indices(1);
                Fcn3(app, row)
            else
                set(app.Grid4.Children, 'Enable', 0)
                set(app.Grid5.Children, 'Enable', 0)
            end
            
        end

        % Value changed function: regulatoryInfo
        function regulatoryInfo_ValueChanged(app, event)
            
            switch app.regulatoryInfo.Value
                case 'Licenciada'
                    set(app.Grid5.Children, 'Enable', 0);
                    app.addButton.Enable = 0;
                case 'Não licenciada'
                    set(app.Grid5.Children, 'Enable', 1);
                    app.addButton.Enable = 1;
                case 'Não passível de licenciamento'
                    set(app.Grid5.Children, 'Enable', 1);
                    app.addButton.Enable = 1;
            end
            
        end

        % Value changed function: Type
        function Type_ValueChanged(app, event)

            switch app.Type.Value
                case 'Não se manifestou'
                    app.ProvvelesquemademodulaoDropDownLabel.Enable = 0;
                    app.Modulation.Enable      = 0;
                otherwise
                    app.ProvvelesquemademodulaoDropDownLabel.Enable = 1;
                    app.Modulation.Enable      = 1;
            end
            
        end

        % Button pushed function: addButton
        function addButton_Clicked(app, event)
            
            if ~strcmp(deblank(app.Description.Value), {''})
                str = '';
                for ii = 1:length(app.Description.Value)
                    str = [str, app.Description.Value{ii}];
                end
                
                exceptionValue = {str2double(app.CF.Value), ...
                                  app.regulatoryInfo.Value, ...
                                  app.Type.Value,           ...
                                  app.Modulation.Value,     ...
                                  str};
                
                ind = [];
                if ~isempty(app.CallingApp.exceptionList)
                    ind = find(app.CallingApp.exceptionList.CF == str2double(app.CF.Value));
                end
                
                if isempty(ind)
                    app.CallingApp.exceptionList(end+1,:) = exceptionValue;
                else
                    app.CallingApp.exceptionList(ind,:)   = exceptionValue;
                end
                
                Fcn1(app)
                Fcn2(app)
                
            else
                uialert(app.UIFigure, 'Campo "Informações adicionais" não pode ficar vazio.', 'Alerta', 'Icon', 'warning')
                
            end
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
            if isempty(app.Tree.SelectedNodes)
                app.deleteButton.Enable = 0;
            else
                app.deleteButton.Enable = 1;
            end
            
        end

        % Image clicked function: deleteButton
        function deleteButtonImageClicked(app, event)
            
            selectedNodes = app.Tree.SelectedNodes;
            
            if ~isempty(selectedNodes)
                ind = [];
                for ii = 1:numel(selectedNodes)
                    ind = [ind, selectedNodes(ii).NodeData];
                end
                
                app.CallingApp.exceptionList(ind,:) = [];
                removeStyle(app.UITable)
                
                set(app.Grid4.Children, 'Enable', 0)
                set(app.Grid5.Children, 'Enable', 0)
                app.addButton.Enable = 0;
                
                Fcn1(app)
                Fcn2(app)
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise: Prévia de relatório';
            app.UIFigure.Icon = 'Bluemine.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Label3 = uilabel(app.Grid1);
            app.Label3.Text = 'Lista de exceções';
            app.Label1 = uilabel(app.Grid1);
            app.Label1.Text = 'Picos que atendem às condições estabelecidas';
            app.GridLayout = uigridlayout(app.Grid1);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {''; ''; ''; ''; ''; ''; ''; ''};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITable_CellSelection, true);
            app.FrequnciaMHzLabel = uilabel(app.GridLayout);
            app.FrequnciaMHzLabel.Text = {'  Frequência'; '  (MHz)'};
            app.FrequnciacentraldocanalMHzLabel = uilabel(app.GridLayout);
            app.FrequnciacentraldocanalMHzLabel.Text = {'  Frequência central'; '  do canal (MHz)'};
            app.NvelmnimodBmLabel = uilabel(app.GridLayout);
            app.NvelmnimodBmLabel.Text = {'  Nível mínimo'; '  (dBm)'};
            app.NvelmximodBmLabel = uilabel(app.GridLayout);
            app.NvelmximodBmLabel.Text = {'  Nível máximo'; '  (dBm)'};
            app.OcupaomdiaLabel = uilabel(app.GridLayout);
            app.OcupaomdiaLabel.Text = {'  Ocupação'; '  média (%)'};
            app.OcupaomximaLabel = uilabel(app.GridLayout);
            app.OcupaomximaLabel.Text = {'  Ocupação'; '  máxima (%)'};
            app.IdentificaodoprovvelemissorServioStatusEntidadeMunicpioUFLabel = uilabel(app.GridLayout);
            app.IdentificaodoprovvelemissorServioStatusEntidadeMunicpioUFLabel.Text = {'  Identificação do provável emissor'; '  (Serviço, Status, Entidade, Município/UF)'};
            app.DistnciakmLabel = uilabel(app.GridLayout);
            app.DistnciakmLabel.Text = {'  Distância'; '  (km)'};
            app.Tree = uitree(app.Grid1);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Grid3 = uigridlayout(app.Grid1);
            app.Grid5 = uigridlayout(app.Grid3);
            app.Description = uitextarea(app.Grid5);
            app.Description.Enable = 'off';
            app.InformaesadicionaisLabel = uilabel(app.Grid5);
            app.InformaesadicionaisLabel.Enable = 'off';
            app.InformaesadicionaisLabel.Text = 'Informações adicionais:';
            app.SubLabel2 = uilabel(app.Grid5);
            app.SubLabel2.Enable = 'off';
            app.SubLabel2.Text = '(inserir, em especial, informações que podem ser úteis em eventual diligência de campo)';
            app.Type = uidropdown(app.Grid5);
            app.Type.Items = {'Normal', 'Espúrio', 'Não identificado', 'Não se manifestou'};
            app.Type.ValueChangedFcn = createCallbackFcn(app, @Type_ValueChanged, true);
            app.Type.Enable = 'off';
            app.Type.Value = 'Normal';
            app.TipodeemissoDropDownLabel = uilabel(app.Grid5);
            app.TipodeemissoDropDownLabel.Enable = 'off';
            app.TipodeemissoDropDownLabel.Text = 'Tipo de emissão:';
            app.Modulation = uidropdown(app.Grid5);
            app.Modulation.Items = {'Analógico', 'Digital', 'Não identificado'};
            app.Modulation.Enable = 'off';
            app.Modulation.Value = 'Analógico';
            app.ProvvelesquemademodulaoDropDownLabel = uilabel(app.Grid5);
            app.ProvvelesquemademodulaoDropDownLabel.Enable = 'off';
            app.ProvvelesquemademodulaoDropDownLabel.Text = 'Provável esquema de modulação:';
            app.Grid4 = uigridlayout(app.Grid3);
            app.FrequnciacentraldocanalEditFieldLabel = uilabel(app.Grid4);
            app.FrequnciacentraldocanalEditFieldLabel.Enable = 'off';
            app.FrequnciacentraldocanalEditFieldLabel.Text = 'Frequência central do canal:';
            app.ProvvelemissorTextAreaLabel = uilabel(app.Grid4);
            app.ProvvelemissorTextAreaLabel.Enable = 'off';
            app.ProvvelemissorTextAreaLabel.Text = 'Provável emissor:';
            app.SubLabel1 = uilabel(app.Grid4);
            app.SubLabel1.Enable = 'off';
            app.SubLabel1.Text = '(sugerido pelo algoritmo embarcado no appAnálise)';
            app.Station = uitextarea(app.Grid4);
            app.Station.Editable = 'off';
            app.Station.Enable = 'off';
            app.CF = uieditfield(app.Grid4, 'text');
            app.CF.Editable = 'off';
            app.CF.Enable = 'off';
            app.Unit = uilabel(app.Grid4);
            app.Unit.Enable = 'off';
            app.Unit.Text = 'MHz';
            app.regulatoryInfo = uidropdown(app.Grid4);
            app.regulatoryInfo.Items = {'Licenciada', 'Não licenciada', 'Não passível de licenciamento'};
            app.regulatoryInfo.ValueChangedFcn = createCallbackFcn(app, @regulatoryInfo_ValueChanged, true);
            app.regulatoryInfo.Enable = 'off';
            app.regulatoryInfo.Value = 'Licenciada';
            app.EmissoDropDownLabel = uilabel(app.Grid4);
            app.EmissoDropDownLabel.Enable = 'off';
            app.EmissoDropDownLabel.Text = 'Emissão:';
            app.Label2 = uilabel(app.Grid1);
            app.Label2.Text = 'Registro selecionado';
            app.addButton = uibutton(app.Grid1, 'push');
            app.addButton.ButtonPushedFcn = createCallbackFcn(app, @addButton_Clicked, true);
            app.addButton.Icon = 'img_318783a.png';
            app.addButton.Enable = 'off';
            app.addButton.Text = 'Incluir na lista';
            app.deleteButton = uiimage(app.Grid1);
            app.deleteButton.ImageClickedFcn = createCallbackFcn(app, @deleteButtonImageClicked, true);
            app.deleteButton.Enable = 'off';
            app.deleteButton.ImageSource = 'adicionar3d.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winTreeEdit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end